classdef RosePlotColorSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoseplotcoloreditorUIFigure  matlab.ui.Figure
        ClickoncategoryandadjustcolorLabel  matlab.ui.control.Label
        ClosecoloreditorButton       matlab.ui.control.Button
        ColorLabel                   matlab.ui.control.Label
        CategoryLabel                matlab.ui.control.Label
        ChangecolorColorPicker       matlab.ui.control.ColorPicker
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        Categories
        ColorAssignments % Description
        MainApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Categories, DefaultColors, MainApp)
            
                    % app.UpDropDown.Text = char(hex2dec('25B2'));
                    % app.UpDropDown.FontColor = [0.7,0,0];
                    % app.UpDropDown.FontSize = 24;
                    % 
                    % app.DownDropDown.Text =  char(hex2dec('25BC'));
                    % app.DownDropDown.FontColor = [0.7,0,0];  
                    % app.DownDropDown.FontSize = 24;                        
                    
                    if exist("Categories")
                        app.MainApp = MainApp;
                        app.Categories = Categories;
                        nCat = length(app.Categories);
                        app.ColorAssignments = DefaultColors; % flipud(sky(nCat));
                    else
                        app.MainApp = [];
                        app.Categories = {"DMSO sample category";"EZRIN sample category";"LOK sample category"};
                        nCat = length(app.Categories);
                        app.ColorAssignments = repmat( [0, 0.4471, 0.7412], nCat, 1);
                    end                    
                    
                    ColorText = repmat('  ',nCat,1);
                    T = table(app.Categories,ColorText,'VariableNames',["Category","Color"]);
                    app.UITable.Data = T;
                    app.UITable.RowName = '';
                    app.UITable.ColumnName = ''; 
                    app.UITable.ColumnWidth = {'6x','1x'};
                    app.UITable.ColumnEditable = false(1,2);

                    for c = 1:nCat
                        s = uistyle("BackgroundColor",app.ColorAssignments(c,:));
                        addStyle(app.UITable,s,"cell",[c,2])
                        if c == 1
                            app.ChangecolorColorPicker.Value = app.ColorAssignments(c,:);
                            app.UITable.Selection = [1,1];
                        end
                    end


        end

        % Callback function
        function AssignColorButtonPushed(app, event)
            
            c = uisetcolor;
            if c ~= 0
                app.ColorAssignments(app.CategoriesListBox.Value,:) = c;
            end
            drawnow

        end

        % Callback function: ChangecolorColorPicker
        function ChangecolorColorPickerValueChanged(app, event)
            
            
            value = app.ChangecolorColorPicker.Value;
            s = uistyle("BackgroundColor",value);
            row = app.UITable.Selection(1,1);
            addStyle(app.UITable,s,"cell",[row,2])
            
            if ~isempty(app.MainApp)
                app.MainApp.RosePlotColors(row,:) = value;
            end


        end

        % Button pushed function: ClosecoloreditorButton
        function ClosecoloreditorButtonPushed(app, event)
            
            close(app.RoseplotcoloreditorUIFigure) 

        end

        % Clicked callback: UITable
        function UITableClicked(app, event)
            
            row = event.InteractionInformation.DisplayRow;
            % column = event.InteractionInformation.DisplayColumn;

            if ~isempty(app.MainApp)
                app.ChangecolorColorPicker.Value = app.MainApp.RosePlotColors(row,:);
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoseplotcoloreditorUIFigure = uifigure('Visible', 'off');
            app.RoseplotcoloreditorUIFigure.Name = 'Roseplot color editor';
            app.UITable = uitable(app.RoseplotcoloreditorUIFigure);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.UITable.ClickedFcn = createCallbackFcn(app, @UITableClicked, true);
            app.UITable.Multiselect = 'off';
            app.ChangecolorColorPicker = uicolorpicker(app.RoseplotcoloreditorUIFigure);
            app.ChangecolorColorPicker.ValueChangedFcn = createCallbackFcn(app, @ChangecolorColorPickerValueChanged, true);
            app.CategoryLabel = uilabel(app.RoseplotcoloreditorUIFigure);
            app.CategoryLabel.Text = 'Category';
            app.ColorLabel = uilabel(app.RoseplotcoloreditorUIFigure);
            app.ColorLabel.Text = 'Color';
            app.ClosecoloreditorButton = uibutton(app.RoseplotcoloreditorUIFigure, 'push');
            app.ClosecoloreditorButton.ButtonPushedFcn = createCallbackFcn(app, @ClosecoloreditorButtonPushed, true);
            app.ClosecoloreditorButton.Text = 'Close color editor';
            app.ClickoncategoryandadjustcolorLabel = uilabel(app.RoseplotcoloreditorUIFigure);
            app.ClickoncategoryandadjustcolorLabel.Text = 'Click on category and adjust color.';
            % Show the figure after all components are created
            app.RoseplotcoloreditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RosePlotColorSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoseplotcoloreditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoseplotcoloreditorUIFigure)
        end
    end
end