classdef Sort_ndFormat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        uipanel1              matlab.ui.container.Panel
        ListOfStagePositions  matlab.ui.control.ListBox
        text2                 matlab.ui.control.Label
        CategoryName          matlab.ui.control.EditField
        text3                 matlab.ui.control.Label
        AddButton             matlab.ui.control.Button
        text9                 matlab.ui.control.Label
        text10                matlab.ui.control.Label
        CancelButton          matlab.ui.control.Button
        DoneButton            matlab.ui.control.Button
        GroupList             matlab.ui.control.ListBox
        DeleteButton          matlab.ui.control.Button
        text8                 matlab.ui.control.Label
        DirectoryLabel        matlab.ui.control.Label
        DirectoryDisplay      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        Groups =[];
        ndDirectory = [];
        FileInfo = [];
        CallingApp = [];
    end
    %--------------------------------------------------------------------------------------------------------------------------------      
    methods (Access = private)
        function [ImageFileNames, CommonText, CST, nC, C, nS, S, nT, T] = CountChannelsStagePositionsTimePoints(app, Dir)
            
                 [files,~] = FilesAndFolders(app,Dir);
                 TF = contains(files,'.nd');
                 N = sum(TF);
                 
                 if ~isempty(files)
                     if N <= 1 
                             files = files(~TF,1);
                             nF = size(files,1);
                             WH = waitbar(0,'Summarizing data...please wait');
                
                             CommonText = [];
                             ImageFileNames  = cell(nF,1);
                
                             c = NaN(nF,1);
                             s = NaN(nF,1);
                             t = NaN(nF,1);
                
                            for f = 1:nF
                                     waitbar(f/nF,WH,'Summarizing Data')
                    
                                     ImageFileNames{f,1} = files{f,1};
                    
                                     cIdx = strfind(files{f,1}, '_w');
                                        cIdx = cIdx(end);
                                     sIdx = strfind(files{f,1}, '_s');
                                        sIdx = sIdx(end);
                                     tIdx = strfind(files{f,1}, '_t');
                                        tIdx = tIdx(end);
                    
                                     c(f,1) = str2double(files{f,1}(cIdx+2)       );
                                     s(f,1) = str2double(files{f,1}(sIdx+2:tIdx-1));
                                     t(f,1) = str2double(files{f,1}(tIdx+2:end-4) );
                    
                                     if isequal(1,f)
                                        CommonText = files{f,1}(1:cIdx-1);
                                     end
                             end
                
                             try close(WH); end %#ok<TRYNC>
                
                             CST = [c,s,t];
                
                             C = unique(c);
                             S = unique(s);
                             T = unique(t);
                
                             nC = length(C);
                             nS = length(S);
                             nT = length(T);
                
                     else
                             MultipleDataSetsAlert(app)
                             ImageFileNames = [];
                             CommonText = [];
                             CST = [];
                             nC  = [];
                             C   = [];
                             nS  = [];
                             S   = [];
                             nT  = [];
                             T   = [];
                     end

                 else
                     WH = warndlg([{'No image files found!'};{'Check directory selection.'}],'Warning');
                     try 
                         WH.Children(3,1).Children.FontSize = 10; 
                         WH.Children(3,1).Children.Units = 'normalized';
                         WH.Children(3,1).Children.Position = [0.5,0.5,0];
                         WH.Children(3,1).Children.HorizontalAlignment = 'center';
                     end
                     ImageFileNames = [];
                     CommonText = [];
                     CST = [];
                     nC  = [];
                     C   = [];
                     nS  = [];
                     S   = [];
                     nT  = [];
                     T   = [];
                 end
        end
    
  %--------------------------------------------------------------------------------------------------------------------------------      
        function [files, folders] = FilesAndFolders(app,directory)
            
                    %  ______________________________________________
                    %  Grab and sort files and folders from directory
                    %
                    %  [files folders] = FilesAndFolders(directory)
                    %
                    %  Keith R Carney  10/30/2008
                    %  www.cellimagingcore.org/www.keithcarney.com
                    %  kcarney@genetics.utah.edu
                    %
            
                    DirInfo = dir(directory); % struct array with fields: name, date, bytes, isdir, datenum
                    DirInfoCell = struct2cell(DirInfo); % ([name date bytes isdir datenum],N)
                    names = DirInfoCell(1,:)'; % Grab Names only
                    designation = logical(cell2mat(DirInfoCell(4,:))'); % TRUE = Folder/Dir, FALSE = File
            
                    % List names of hidden files and folders that are operating system specific (PC and MAC)
                    flag1 =  strcmp('.',names);
                    flag2 =  strcmp('..',names);
                    flag3 =  strcmp('Thumbs.db',names);
                    flag4 =  strcmp('.DS_Store',names);
                    flag5 =  strcmp('._.DS_Dtore',names);
                    flags = all(~[flag1,flag2,flag3,flag4,flag5],2);
            
                    % Use logical Indexing to grab only names and designations that are non-operating system specific
                    FilteredNames = names(flags,1);
                    designation = designation(flags,1);
                    % Now sort into File and Folder cell arrays
                    files  = FilteredNames(designation);
                    folders = FilteredNames(~designation);
        end
  %--------------------------------------------------------------------------------------------------------------------------------            
        function MultipleDataSetsAlert(app)
            
                D = dialog('Name','Multiple .nd files detected','WindowStyle','normal','Units','normalized');
                D.Position = [0.375, 0.5, 0.3, 0.12];
            
                txt = uicontrol('Parent',D,...
                                'Style','text',...
                                'Units','normalized',...
                                'Position',[0.1,0.4,0.8,0.4],...
                                'FontSize',11,...
                                'FontWeight','bold',...
                                'String',[{'More than one data set (.nd file) detected in folder!'};...
                                          {'Remove irrelevant ".nd" and ".TIF" files and try again.'};...
                                          {'There should be at most one ".nd" file in the data folder'}]);
            
                btn = uicontrol('Parent',D,...
                                'Units','normalized',...
                                'Position',[0.375,0.1,0.25,0.2],...
                                'FontSize',10,...
                                'String','OK',...
                                'Callback','delete(gcf)');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Sort_ndFormat_OpeningFcn(app, MainApp, ReadDir)
                
                app.CallingApp = MainApp;
                G = groot;
                app.figure1.Position = [100, (G.ScreenSize(1,4)-200-713), 400, 713];
                % app.figure1.WindowStyle = 'modal';
                app.Groups = cell(0,1);
          
                if exist('ReadDir','var')
                    app.ndDirectory =  ReadDir;
                    app.DirectoryDisplay.Value = app.ndDirectory;
                    [ImageFileNames,CommonText,CST,nC,C,nS,S,nT,T] = CountChannelsStagePositionsTimePoints(app,app.ndDirectory);
                    
                    app.FileInfo.FileNames = ImageFileNames;
                    app.FileInfo.CST_array = CST;
                    app.FileInfo.CommonText = CommonText;
                    app.FileInfo.Channel = C;
                    app.FileInfo.StagePosition = S;
                    app.FileInfo.TimePoint = T;
                    app.FileInfo.nChannels = nC;
                    app.FileInfo.nStagePositions = nS;
                    app.FileInfo.nTimePoints = nT;
                        
                    app.ListOfStagePositions.Items = compose('%03d',S);
                    app.ListOfStagePositions.ItemsData = (1:length(S))';
                    app.ListOfStagePositions.Multiselect = 'on';
                end
                
%                 if exist('MainApp','var')
%                     app.MainFunctionHandle = MainApp;
%                 end


%                 app.output = [{app.Groups};{app.FileInfo}];
%                 % Update handles structure
%                 guidata(hObject, handles);
%             
%                 if isempty(ImageFileNames)
%                     delete(app.figure1)
%                 else
%                     % UIWAIT makes Sort_ndFormat wait for user response (see UIRESUME)
%                     uiwait(app.figure1);
%                 end

        end

        % Button pushed function: AddButton
        function AddButton_Callback(app, event)
            
                    Cat = app.CategoryName.Value;
                    
                    SP = app.ListOfStagePositions.Items;
                    idxSP = app.ListOfStagePositions.Value;
                    idx = length(app.Groups);
                    StagePositionsText = join(compose('%i',str2double(SP(1,idxSP))),' ');    %#ok<ST2NM>
            
                    if ~isempty(Cat) && ~isempty(SP)
                        % Update Groups variable
                        app.Groups{idx+1,1}.Category = Cat;
                        app.Groups{idx+1,1}.StagePositions = str2num(StagePositionsText{1});   %#ok<ST2NM>
                        % Update Groups List
                        app.GroupList.Items = [ app.GroupList.Items, { [Cat,'   [',StagePositionsText{1},']'] } ];
                        app.GroupList.ItemsData = (1:length(app.GroupList.Items))';
                    end
                    
        end

        % Button pushed function: CancelButton
        function CancelButton_Callback(app, event)
            
                delete(app.figure1)
                
        end

        % Button pushed function: DeleteButton
        function DeleteButton_Callback(app, event)
            
                if ~isempty(app.GroupList.Items)
                    idx = app.GroupList.Value;
                    if ~isequal(idx,1)
                        app.GroupList.Value = app.GroupList.Value - 1;
                    end
                    % Remove Deleted Group from Group List and Groups variable
                    GL = app.GroupList.Items';
                    GL(idx,:) = [];
                    % Update variables and Group List box
                    app.Groups(idx,:) = [];
                    app.GroupList.Items = GL;
                    app.GroupList.ItemsData = (1:length(app.GroupList.Items))';
                end
                
        end

        % Button pushed function: DoneButton
        function DoneButton_Callback(app, event)
            
                % Pass the Groups and GUIhandle variable to the main program,
                % close Sort_ndFormat from the main program using the app.figure1
                % handle.
                % app.output = [{app.Groups};{app.FileInfo}];
                app.CallingApp.MetamorphData.StageGroups = app.Groups;
                app.CallingApp.MetamorphData.FileInfo = app.FileInfo;
                
                delete(app.figure1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Sort_ndFormat';
            app.uipanel1 = uipanel(app.figure1);
            app.ListOfStagePositions = uilistbox(app.uipanel1);
            app.ListOfStagePositions.Items = {};
            app.ListOfStagePositions.Value = {};
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = {'Stage '; 'Positions'};
            app.CategoryName = uieditfield(app.uipanel1, 'text');
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Group Name';
            app.AddButton = uibutton(app.uipanel1, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButton_Callback, true);
            app.AddButton.Text = 'Add to Positions and Group List';
            app.text9 = uilabel(app.uipanel1);
            app.text9.Text = {'INSTRUCTIONS:'; '1. Highlight a group of stage positions.'; '2. Input a category name. '; '3. Press "Add to Positions and Group List".'};
            app.text10 = uilabel(app.uipanel1);
            app.text10.Text = {'note: CTRL and SHIFT keys'; 'also work when selecting'; 'stage positions.'};
            app.CancelButton = uibutton(app.figure1, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_Callback, true);
            app.CancelButton.Text = 'CANCEL';
            app.DoneButton = uibutton(app.figure1, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButton_Callback, true);
            app.DoneButton.Text = 'DONE';
            app.GroupList = uilistbox(app.figure1);
            app.GroupList.Items = {};
            app.GroupList.Value = {};
            app.DeleteButton = uibutton(app.figure1, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_Callback, true);
            app.DeleteButton.Text = 'Delete Category';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Stage Postions and Group List';
            app.DirectoryLabel = uilabel(app.figure1);
            app.DirectoryLabel.Text = 'Directory';
            app.DirectoryDisplay = uitextarea(app.figure1);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sort_ndFormat(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Sort_ndFormat_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end