classdef autocell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uipanel9                        matlab.ui.container.Panel
        FluorescentmarkednucleiLabel_2  matlab.ui.control.Label
        PhasecontrastwidefieldimageonlyLabel  matlab.ui.control.Label
        FluorescentmarkednucleiLabel    matlab.ui.control.Label
        Nikon_CP_nd2_format_button      matlab.ui.control.Button
        DataFormatDisplay               matlab.ui.control.Label
        Metamorph_nd_format_button      matlab.ui.control.Button
        Nikon_nd2_format_button         matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        CreateAnalysisPlots_Button      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        AssignColorsButton              matlab.ui.control.Button
        nTracksPerRosePlotInput         matlab.ui.control.NumericEditField
        nTracksEditFieldLabel           matlab.ui.control.Label
        RosePlotsCheckBox               matlab.ui.control.CheckBox
        SelectAnaylsisFolderButton      matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        CellCategoryDisplay             matlab.ui.control.ListBox
        uipanel4                        matlab.ui.container.Panel
        text25                          matlab.ui.control.Label
        uipanel5                        matlab.ui.container.Panel
        text17                          matlab.ui.control.Label
        WideFieldChannel                matlab.ui.control.DropDown
        text14                          matlab.ui.control.Label
        NuclearMarkerChannel            matlab.ui.control.DropDown
        uipanel7                        matlab.ui.container.Panel
        nPts_MSDFit                     matlab.ui.control.CheckBox
        AllPts_MSDFit                   matlab.ui.control.CheckBox
        nMSDPts                         matlab.ui.control.EditField
        Text20                          matlab.ui.control.Label
        uipanel6                        matlab.ui.container.Panel
        hourminimumlengthLabel          matlab.ui.control.Label
        uipanel10                       matlab.ui.container.Panel
        GaussianFilterSizeInput         matlab.ui.control.NumericEditField
        AutoAdjustGaussianSigma         matlab.ui.control.CheckBox
        text8                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        PercentThresholdInput           matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        text11                          matlab.ui.control.Label
        MinTrackLengthInput             matlab.ui.control.EditField
        text9                           matlab.ui.control.Label
        text15                          matlab.ui.control.Label
        uipanel2                        matlab.ui.container.Panel
        ImageStatsDisplay               matlab.ui.control.Label
        ReadDirectoryDisplay            matlab.ui.control.TextArea
        SelectReadFolderButton          matlab.ui.control.Button
        uipanel1                        matlab.ui.container.Panel
        ResampleImagesTo1024Check       matlab.ui.control.CheckBox
        CellposePanel                   matlab.ui.container.Panel
        CellposeModelPulldown           matlab.ui.control.DropDown
        ModelLabel                      matlab.ui.control.Label
        CellposeparametersLabel         matlab.ui.control.Label
        CellSizeForCellposeInput        matlab.ui.control.NumericEditField
        ApproxcellsizepixelscellposeparamLabel  matlab.ui.control.Label
        TimeStepInput                   matlab.ui.control.NumericEditField
        PixelSizeInput                  matlab.ui.control.NumericEditField
        uipanel8                        matlab.ui.container.Panel
        EndText                         matlab.ui.control.Label
        StText                          matlab.ui.control.Label
        EndTimePtInput                  matlab.ui.control.EditField
        StTimePtInput                   matlab.ui.control.EditField
        CropTimePtsCheck                matlab.ui.control.CheckBox
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        text6                           matlab.ui.control.Label
        text5                           matlab.ui.control.Label
    end

%-----------------------------------------------------------------------------------------------------------------------------------------------------
    methods (Access = private)

%----------------------------------------------------------------------------------------------------------------------------------------------------- 
            function    CS = EstimateCellSizeFromPixelSize(app,PixelSize)
                    
                    p = polyfit([0.325,0.65],[120,60],1);
                    CS = polyval(p,PixelSize);
            
            end
%----------------------------------------------------------------------------------------------------------------------------------------------------- 
            function ND2Stats = GetNDfileStats(app,Directory)

                ND2Stats = [];

                [~,Folders1] = FilesAndFolders(app,Directory);
                fIdx = ~contains(Folders1,'ANALYSIS');
                Folders1 = Folders1(fIdx,1);
                [Files,~] = FilesAndFolders(app, fullfile(Directory,Folders1{1}) );
                NDidx = contains(Files,'.nd2');
                NDfiles = Files(NDidx);

                if ~isequal(size(NDfiles,1),0)
                        reader = bfGetReader( fullfile(Directory,Folders1{1},NDfiles{1}) ); 
        
                        BitDepth = reader.getBitsPerPixel;
                        nChan    = reader.getSizeC;  % Number of channels
                        Height   = reader.getSizeX;  % Image height
                        Width    = reader.getSizeY;  % Image width
                        nZ       = reader.getSizeZ;  % number of z
                        nT       = reader.getSizeT;  % number of time points
                        
                        Meta = regexp( char(reader.getGlobalMetadata()),',', 'split')';
                        MetaField1 = 'dAvgPeriodDiff=';
                        idx1 = contains(Meta,MetaField1); 
                        idx2 = strfind(Meta{idx1},MetaField1);
                        app.TimeStep = round( (str2double( Meta{idx1}(idx2+length(MetaField1):end) )/1000 )/60, 2);
        
                        MetaField2 = 'dCalibration=';
                        idx3 = contains(Meta,MetaField2); 
                        idx4 = strfind(Meta{idx3},MetaField2);
                        app.PixelSize = round( str2double( Meta{idx3}(idx4+length(MetaField2):end) ), 3);
        
                        app.EndTimePtInput.Value = num2str(nT);
                        app.PixelSizeInput.Value = app.PixelSize;
                        app.TimeStepInput.Value  = app.TimeStep;
                     
                        %==========================================================================================================
                        if ~isempty(app.PixelSizeInput.Value) && ~isempty(app.TimeStepInput.Value) %&& ~isempty(app.ReadDirMain)
                            app.StartButton.Enable = 'on';
                            app.MinTrackLengthInput.Value = num2str(round(app.MinTrackHourLength*60/app.TimeStep));
                            app.nMSDPts.Value = num2str(round(app.MinTrackHourLength*60/app.TimeStep)-1);
                        end            
            
                        if app.AutoAdjustGaussianSigma.Value
                            SGFS = SuggestedGaussianFilterSize(app);
                            app.GaussianFilterSizeInput.Value = SGFS;
                            app.GaussianSigma = app.GaussianFilterSizeInput.Value;
                        end
                       
                        CS = EstimateCellSizeFromPixelSize(app,app.PixelSize);
                        app.CellSizeForCellposeInput.Value = CS;
                        %==========================================================================================================
                        
                        ND2Stats = ['Data stats:  ',num2str(Width),' x ',num2str(Height),' pixels,  ',num2str(nChan),' ch,  ',num2str(nT),' tps,  ',sprintf('%0.2f',app.TimeStep), ' dt (min),  ',sprintf('%0.3f',app.PixelSize),' dp (',char(181),'m)'];
                        reader.close()

                else    % If no.nd2 files are found then tell user
                        uialert(app.figure1,[{'No nd2 files were found in the folder:'};fullfile(Directory,Folders1{1})],'No nd2 files found')

                end
            end

%-----------------------------------------------------------------------------------------------------------------------------------------------------            
            function  CreateRosePlots(app,Categories,MSDVel,MSDtracks)
                    
                        nCat = size(Categories,1);
                        FigIdx = 0;
                        MaxRange = 0;
                        FigHandles = gobjects(0,1);
                        AxesHandles = gobjects(0,1);
                        TL = gobjects(0,1);
                        nTracks = app.nTracksPerRosePlotInput.Value; %20; % number of tracks closest to the median you want to plot
                        %Colors = abyss(nTracks);
                        FS1 = 14;
                        FS2 = 12;
    
                        for cat = 1:nCat   
                                Colors = CreateColorMapGradient(app,app.RosePlotColors(cat,:),nTracks);
                                if rem(cat,8) == 1 % Create new figure (10 plots per figure)
                                    AxIdx = 0;
                                    FigIdx = FigIdx + 1;
                                    FigHandles(FigIdx,1) = figure;
                                    set(FigHandles(FigIdx,1),'Color','w')
                                    TL(FigIdx,1) = tiledlayout(FigHandles(FigIdx,1),2,4,"TileSpacing","compact","Padding","compact");
                                end
                                AxIdx = AxIdx + 1;
                                                            
                                idx = find( ~isnan(MSDVel(:,cat)),1,'last');
    
                                IndexAndMSDvelocities = [ (1:idx )', MSDVel(1:idx,cat)];
                                MedMSDvel = median(IndexAndMSDvelocities(:,2));
                                IndexAndMSDvelocities(:,2) = abs(IndexAndMSDvelocities(:,2) - MedMSDvel); % Subtract MedianVel from MSDVelocities
                                
                                % Now sort Velocit differences
                                IndexAndMSDvelocities = sortrows(IndexAndMSDvelocities,2);
                                TrackIdx = IndexAndMSDvelocities(1:nTracks,1);
                                
                                % Create rose plot-----------------------------------------
                                AxesHandles(cat,1) = nexttile(TL(FigIdx,1),AxIdx);

                                for T = 1:nTracks
                                        Tidx = TrackIdx(T,1);
                                        Xtrack = MSDtracks{cat,1}{Tidx,1}(:,1);
                                        Ytrack = MSDtracks{cat,1}{Tidx,1}(:,2);
                                        X = Xtrack - Xtrack(1,1);
                                        Y = Ytrack - Ytrack(1,1);    
                                        plot(AxesHandles(cat,1),X,Y,'-','LineWidth',1.5,'Color',Colors(T,:));  
                                        hold(AxesHandles(cat,1),'on') 
                                end

                                hold(AxesHandles(cat,1),'off')
                                axis square
                                set(AxesHandles(cat,1),'LineWidth',2,'FontSize',FS2)
                                xlabel(AxesHandles(cat,1),'X (\mum)','FontSize',FS1)
                                ylabel(AxesHandles(cat,1),'Y (\mum)','FontSize',FS1)
                                xtickangle(AxesHandles(cat,1),45)
                                title(AxesHandles(cat,1),Categories{cat,1},'FontSize',FS1,'FontWeight','normal')
                                MaxRange = max([MaxRange; round(max(abs(axis))+10,-1)]);
                        end
    
                        % Adjust all axis ranges to the same value 
                        for cat = 1:nCat  
                            R = MaxRange;
                            axis(AxesHandles(cat,1),[-R R -R R])
                        end
            end
            
%-----------------------------------------------------------------------------------------------------------------------------------------------------
            function Image = GetND2Image(app,reader,iSeries,z,c,t)
    
                    % reader = bfGetReader(Filename); 
                   
                    nSeries = reader.getSeriesCount;    % Number of series (movies) in the file
                    % iSeries = 1;   % Specify series number to read from (between 1 and nSeries)
                
                    reader.setSeries(iSeries - 1); % Set image series to read from
                    BitDepth = reader.getBitsPerPixel; % Bit depth
                    nChan    = reader.getSizeC;  % Number of channels
                    Height   = reader.getSizeX;  % Image height
                    Width    = reader.getSizeY;  % Image width
                    nZ       = reader.getSizeZ;  % number of z
                    nT       = reader.getSizeT;  % number of time points
                 
                    iPlane = reader.getIndex(z-1,c-1,t-1) + 1;  % Convert to plane number
                    Image = bfGetPlane(reader, iPlane);  % Get image data and convert to double precision

            end
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
            function CM = DivergentColorMap05(app)
                        
                 CM =  [[0.058824           1           1];...
                        [0.058903     0.99327     0.99815];...
                        [0.058982     0.98654      0.9963];...
                        [0.059062     0.97981     0.99445];...
                        [0.059141     0.97309      0.9926];...
                        [0.059221     0.96636     0.99074];...
                        [0.0593       0.95963     0.98889];...
                        [0.059379     0.9529      0.98704];...
                        [0.059459     0.94617     0.98519];...
                        [0.059538     0.93944     0.98334];...
                        [0.059618     0.93271     0.98149];...
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                        [1           1           0.066667] ];
            
            end

            %--------------------------------------------------------------------------------------------------------------------------------------------------
            function ColorMap = CreateColorMapGradient(app,Color,nTracks)

                    C1 = Color; % User selects 'dark' version of color.
                    C2 = mean([ones(4,3); C1]); % Calculate lighter version of color.

                    % Create colormap with C1 and C2 at the ends ------------------------------
                    CMred   = linspace( C2(1,1), C1(1,1), nTracks )';
                    CMgreen = linspace( C2(1,2), C1(1,2), nTracks )';
                    CMblue  = linspace( C2(1,3), C1(1,3), nTracks )';

                    ColorMap = [CMred, CMgreen, CMblue];

            end

            %--------------------------------------------------------------------------------------------------------------------------------------------------
            function  [tracksFinal,TrackXArray,TrackYArray,TrackIDArray] = Apply_LAPlinker_tracking(app, nI, dt, linker, TimePointData, nLocations, StageName)                                  

                        GoodDataTF = ~isoutlier(nLocations,ThresholdFactor=5);
                        for t = 1:nI 
                            if GoodDataTF(t,1)  
                                try
                                    linker = assignToTrack(linker, t, TimePointData{t,1});
                                catch
                                    disp(['Track data assignment error: ', StageName,'     timepoint = ',num2str(t)])
                                end
                            end
                        end                         
                        

                        % Get the TrackArray for data analysis
                        trackData = linker.tracks;

                        tracksFinal = [];
                        MinLength = floor(4*60/dt);
                        TrackID = 0;
                        TrackIDArray = NaN(0,nI);
                        TrackXArray  = NaN(0,nI);
                        TrackYArray  = NaN(0,nI);
                        XYTT = [];
                        
                        for T = 1:trackData.NumTracks
                             Time = trackData.Tracks(T).Frames;
                             TrackLength = (Time(end)-Time(1)+1);
                             if TrackLength >= MinLength
                                    TrackID = TrackID + 1;
                                    X = NaN(nI,1);
                                    Y = NaN(nI,1);
                                    V  = NaN(nI,1);
                                    TimeVector = (Time(1):1:Time(end))';
                                    LV = false(1,nI);
                                    LV(TimeVector) = true;
                                    for q = 1:length(Time)
                                        X(Time(q),1) = trackData.Tracks(T).Data.Centroid{q}(1);
                                        Y(Time(q),1) = trackData.Tracks(T).Data.Centroid{q}(2);
                                    end
                                    X = fillmissing(X,'movmean',10,'EndValues','none');
                                    Y = fillmissing(Y,'movmean',10,'EndValues','none');        
                                    
                                    XYTT = [X(Time(1):Time(end),1), Y(Time(1):Time(end),1), TimeVector, TrackID*ones(TrackLength,1)];
                                    tracksFinal = [tracksFinal; XYTT]; % Columns are [ X, Y, Time, TrackID]
                                    IDVector = NaN(nI,1); 
                                    IDVector(TimeVector,1) = 1;
                                    TrackIDArray(TrackID,:) = IDVector;
                                    TrackXArray(TrackID,:) = X; 
                                    TrackYArray(TrackID,:) = Y;
                             end
                        end

            end


            %--------------------------------------------------------------------------------------------------------------------------------------------------
           function [ImageData, nCh, nTimePt] = Read_nd2_Image_File(app,FilePath)
    
                    %----------------------------------------------------------------------------------
                    %
                    % [ImageData, nCh, nTimePt] = Read_nd2_Image_File(FilePath)
                    %
                    % FilePath = example: 'X:\Mendoza Lab\Shiela\Imaging\02Mar18 H1299 EV AZD NKX\random walk\AZD\020318_AZD-2-3.nd2';
                    % ImageData = Cell Array of Images (time,channel)
                    % nCh = number of channels
                    % nTimePt = number of timepoints
                    %
                    % Keith Carney 3/6/18
                    %
                    % ---------------------------------------------------------------------------------
                    
                    ImDat = bfopen(FilePath);
                    ImDat = ImDat{1};
                    %===========================================
                    ImageInfo = ImDat{1,2};
                            k1 = strfind(ImageInfo,'; C=');
                            k2 = strfind(ImageInfo,'; T=');
                            ChText = ImageInfo(k1+4:k2-1);
                            k3 = strfind(ChText,'/');
                        nCh = str2double(ChText(k3+1:end));
                        if isnan(nCh)
                            nCh = 1;
                        end
                        %ChNum = str2double(ChText(1:k3-1));
                            TimeText = ImageInfo(k2+4:end);
                            k4 = strfind(TimeText,'/');
                        nTimePt = str2double(TimeText(k4+1:end));
                        %Tpt  = str2double(TimeText(1:k4-1));
                    %===========================================    
                    ImageData = zeros([size(ImDat{1,1}), nTimePt, nCh],class(ImDat{1,1}));
                    t = 0;
                    for L = 1:nCh:nCh*nTimePt
                        t = t + 1;
                        for c = 1:nCh
                            %ImageData{t,c} = ImDat{L+c-1,1};
                            ImageData(:,:,t,c) = ImDat{L+c-1,1};
                        end
                    end 
            
           end

 %-----------------------------------------------------------------------------------------------------------------------------------------------------    
             function CM2 = DivergentColorMap03(app)
            
                    CM =  [[ 127,59,8];...
                           [ 179,88,6];...
                           [ 224,130,20];...
                           [ 253,184,99];...
                           [ 254,224,182];...
                           [ 247,247,247];...
                           [ 216,218,235];...
                           [ 178,171,210];...
                           [ 128,115,172];...
                           [ 84,39,136];...
                           [ 45,0,75] ];
                            
                           CM2 = zeros(256,3);
                        
                           X1 = (9:24:255)';
                           X2 = (1:1:256)';
                           CM2(:,1) = interp1(X1,CM(:,1),X2,'linear','extrap');
                           CM2(:,2) = interp1(X1,CM(:,2),X2,'linear','extrap');
                           CM2(:,3) = interp1(X1,CM(:,3),X2,'linear','extrap');
                        
                           CM2 = CM2./255;
                           CM2(CM2>1) = 1;
                           CM2(CM2<0) = 0;
            
             end

 %-----------------------------------------------------------------------------------------------------------------------------------------------------  
        function [ArrayOut,nCells,Ns] = CellArray2PaddedNanArray(app,ArrayIn)
        
                nCells = size(ArrayIn,1);
                Ns = cellfun(@length ,ArrayIn);
                MaxDataLength = max(Ns); % Find the length of the longest vector
                ArrayOut = NaN(MaxDataLength,nCells);
                
                for n = 1:nCells
                    L = length(ArrayIn{n});
                    ArrayOut(1:L,n) = ArrayIn{n};
                end
        
        end


%-----------------------------------------------------------------------------------------------------------------------------------------------------  
         function H = WritingDataToExcelFileWaitBox(app)
        
            ScreenSize = get(groot,'ScreenSize');
            
            BoxWidth  = 280;
            BoxHeight = 100;
            
            H = dialog('Position',[round(-BoxWidth + ScreenSize(1,3)/2),...
                                   round( ScreenSize(1,4)/2 ),...
                                   BoxWidth, BoxHeight],'Name','Please wait');
        
            txt = uicontrol('Parent',H,'Style','text',...
                            'Position',[33 10 210 50],...
                            'HorizontalAlignment','left',...
                            'String','Writing statistics to excel file.',...
                            'FontSize',12);
         
         end

%-----------------------------------------------------------------------------------------------------------------------------------------------------  
        function  [Phi,RMSE] = TIE_FFT_solution(app,dIdz,I0,Pixelsize,k,RegPara,IntThr,True_phase)

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Main function for FFT-TIE solver.
                % Version 2.0 -
                % Related Reference:
                % D. Paganin and K. A. Nugent, Phys. Rev. Lett. 80, 2586 (1998).
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % function [Phi,RMSE,Time] = TIE_FFT_solution(dIdz,I0,Pixelsize,k,RegPara,IntThr,True_phase)
                % Purpose: Solution to TIE using FFT-based Possion solver
                % inputs :
                %	dIdz: Intensity derivative dI/dz;
                %   dz: propagation distance;
                %   I0: In-focus intensity;
                %	Pixelsize: Pixelsize (in the sample space);
                %   RegPara: Regularization parameter (to remove low-frequency noise);
                %   k: Wave-number;
                %   Iter: Maximum iteration times;
                %   Method: type of method used ('FFT' or 'Direct');
                %   IntThr, Intensity threshold to prevent divide-by-zero error
                %   True_phase£º Ture phase.
                % Outputs:
                %   Phi: Phase recovered;
                %   RMSE: Root mean squre error;
                %   Time: Computation time;
                %¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª¡ª
                % last modified on 02/27/2020
                % by Chao Zuo (zuochao@njust.edu.cn,surpasszuo@163.com)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
               
                J = -k*dIdz;
                [Ny,Nx] = size(J);
                Fx = (-(Nx/2):1:(Nx/2-1))/Nx/Pixelsize; % frequency coordinate X.
                Fy = (-(Ny/2):1:(Ny/2-1))/Ny/Pixelsize; % frequency coordinate Y.
                [U,V] = meshgrid(Fx,Fy);
                Lambda = 2*pi/k;
                
                % Coordinates in frequency domain
                fx = fftshift(U);
                fy = fftshift(V);
                
                % Terms for derivative calculation in frequency domain
                Cx = 2*1i*pi*fx;
                Cy = 2*1i*pi*fy;
                
                % Calculate Psi by TIE with non-uniform intensity.
                FJ = fft2(J);
                Fpsi = FJ.*(Cx.*Cx+Cy.*Cy)./(RegPara/Pixelsize.^4+(Cx.*Cx+Cy.*Cy).^2);
                
                % Calculate Phi by TIE with non-uniform intensity
                Fdpsidx = Fpsi.*Cx;
                Fdpsidy = Fpsi.*Cy;
                dpsidx = real(ifft2(Fdpsidx));
                dpsidy = real(ifft2(Fdpsidy));
                
                % Intensity thresholding to prevent divide-by-zero error
                I0(find(I0<IntThr*max(max(I0))))...
                    =IntThr*max(max(I0));
                
                % Phase derivative
                dPhidx = dpsidx./I0;
                dPhidy = dpsidy./I0;
                
                FdPsidx = fft2(dPhidx);
                FdPsidy = fft2(dPhidy);
                
                % Phase Laplacian
                Fd2Phidx2 = FdPsidx.*Cx;
                Fd2Phidy2 = FdPsidy.*Cy;
                
                d2Phidx2 = real(ifft2(Fd2Phidx2));
                d2Phidy2 = real(ifft2(Fd2Phidy2));
                d2Phi = d2Phidx2 + d2Phidy2;
                
                % Phase integration
                Fd2Phi = fft2(d2Phi);
                Fphi = Fd2Phi.*(Cx.*Cx+Cy.*Cy)./(RegPara/Pixelsize.^4+(Cx.*Cx+Cy.*Cy).^2);
                Phi = real(ifft2(Fphi));
                
              
                % Calculate RMSE
                if exist('True_phase')
                    err = Phi-True_phase;
                    err = err-mean(err(~isnan(err)));
                    RMSE=sqrt(nansum(nansum(err.^2))/(numel(err)-numel(find(isnan(err)))));
                end
        
        end


 %-----------------------------------------------------------------------------------------------------------------------------------------------------       
        function []=boxPlotCellArray(app,cellArrayData,nameList,convertFactor,notchOn,plotIndivPoint,forceShowP)
            % function []=boxPlotCellArray(cellArrayData,nameList,convertFactor,notchOn,plotIndivPoint,forceShowP) automatically converts cell array
            % format input to matrix input to use matlab function 'boxplot'
            % input: cellArrayData          cell array data
            %           nameList            cell array containing name of each
            %                               condition (ex: {'condition1' 'condition2' 'condition3'})
            %           convertFactor       conversion factor for physical unit (ex.
            %                               pixelSize, timeInterval etc...)
            %           forceShowP          0 if you want to show only significant p
            %                               1 if you want to show all p 
            %                               2 if you do not want to show any p
            % Sangyoon Han, March 2016
            [lengthLongest]=max(cellfun(@(x) length(x),cellArrayData));
            %If there is no data, exclude them in the plot
            idEmptyData = cellfun(@isempty,cellArrayData);
            cellArrayData(idEmptyData)=[];
            numConditions = numel(cellArrayData);
            matrixData = NaN(lengthLongest,numConditions);
            for k=1:numConditions
                matrixData(1:length(cellArrayData{k}),k) = cellArrayData{k};
            end
            if all(isnan(matrixData(:)))
                disp('All data are NaNs. Returning...')
                return
            end
            
            if nargin<6
                forceShowP=false;
            end
            if nargin<5
                forceShowP=false;
                plotIndivPoint = true;
            end
            if nargin<4
                forceShowP=false;
                notchOn=true;
                plotIndivPoint = true;
            end
            if nargin<3
                forceShowP=false;
                convertFactor = 1;
                notchOn=true;
            end
            if nargin<2
                nameList=arrayfun(@(x) num2str(x),(1:numConditions),'UniformOutput',false);
                convertFactor = 1;
                notchOn=true;
                forceShowP=false;
            end
            nameList(idEmptyData)=[];
            
            boxWidth=0.5;
            whiskerRatio=0;%1.5;
            matrixData=matrixData*convertFactor;
            nameListNew = cellfun(@(x,y) [x '(N=' num2str(length(y)) ')'],nameList,cellArrayData,'UniformOutput', false);
            
            if plotIndivPoint
                % individual data jitter plot
                numCategories = size(matrixData,2);
                % width=boxWidth/2;
                for ii=1:numCategories
                    xData = ii+0.1*boxWidth*(randn(size(matrixData,1),1));
                    scatter(xData,matrixData(:,ii),'filled','MarkerFaceColor',[0 0 0],'MarkerEdgeColor','none','SizeData',2)
                    hold on
                end
                alpha(.5)
            end
            if notchOn %min(sum(~isnan(matrixData),1))>20 || 
                boxplot(matrixData,'whisker',whiskerRatio,'notch','on',...
                    'labels',nameListNew,'symbol','','widths',boxWidth,'jitter',1,'colors','k');%, 'labelorientation','inline');
            else % if the data is too small, don't use notch
                boxplot(matrixData,'whisker',whiskerRatio*0.5,'notch','off',...
                    'labels',nameListNew,'symbol','','widths',boxWidth,'jitter',0,'colors','k');%, 'labelorientation','inline');
            end
                
            %set(findobj(gca,'LineStyle','--'),'LineStyle','-','Color',[0.4 0.4 0.4])
            wColor = [0.5 0.5 0.5];
            
            h = findobj(gca,'tag','boxplot');
            hc = h.Children;
            set(hc,'LineWidth',1)
            set(findobj(gca,'tag','Lower Adjacent Value'),'LineWidth',0.5,'LineStyle','-', 'Color', wColor)
            set(findobj(gca,'tag','Lower Whisker'),'LineWidth',0.5,'LineStyle','-', 'Color', wColor)
            set(findobj(gca,'tag','Upper Adjacent Value'),'LineWidth',0.5,'LineStyle','-', 'Color', wColor)
            set(findobj(gca,'tag','Upper Whisker'),'LineWidth',0.5,'LineStyle','-', 'Color', wColor)
            
            
            set(findobj(gca,'tag','Median'),'LineWidth',2)
            % set(gca,'XTick',1:numel(nameList))
            % set(gca,'XTickLabel',nameList)
            set(gca,'XTickLabelRotation',45)
            
            % hold on
            % perform ranksum test for every single combination
            maxPoint = quantile(matrixData,[0.25; 0.75]);
            maxPoint2 = maxPoint(2,:)+(maxPoint(2,:)-maxPoint(1,:))*whiskerRatio;
            maxPoint2 = max(maxPoint2);
            lineGap=maxPoint2*0.01;
            q=0;
            for k=1:(numConditions-1)
                for ii=k+1:numConditions
                    if numel(cellArrayData{k})>1 && numel(cellArrayData{ii})>1
                        if kstest(cellArrayData{k}) % this means the test rejects the null hypothesis
                            [p]=ranksum(cellArrayData{k},cellArrayData{ii});
                            if (p<0.05 && forceShowP~=2) || forceShowP==1 
                                q=q+lineGap;
                                line([k ii], ones(1,2)*(maxPoint2+q),'Color','k')    
                                q=q+lineGap;
                                text(floor((k+ii)/2)+0.3, maxPoint2+q,['p=' num2str(p) ' (r)'])
                            end
                        else
                            [~,p]=ttest2(cellArrayData{k},cellArrayData{ii});
                            if (p<0.05 && forceShowP~=2) || forceShowP==1
                                q=q+lineGap;
                                line([k ii], ones(1,2)*(maxPoint2+q),'Color','k')    
                                q=q+lineGap;
                                text(floor((k+ii)/2)+0.3, maxPoint2+q,['p=' num2str(p) ' (t)'])
                            end
                        end
                    end
                end
            end
            q=q+lineGap*3;
            minPoint = quantile(matrixData,[0.25; 0.75]);
            minPoint2 = minPoint(1,:)-(minPoint(2,:)-minPoint(1,:))*whiskerRatio;
            minPoint2 = min(minPoint2);
            if ~plotIndivPoint && forceShowP
                maxPoint2 = maxPoint2+q;
            end
            ylim([minPoint2-lineGap*2 maxPoint2+q])
            
            set(gca,'FontSize',9)
            set(findobj(gca,'Type','Text'),'FontSize',8)
        end

%-----------------------------------------------------------------------------------------------------------------------------------------------------
        function AutocellComplete(app)
            
                d = dialog('Name','Autocell finished','WindowStyle','normal','Units','normalized');
                d.Position = [0.425, 0.50, 0.15, 0.12];
            
                txt = uicontrol('Parent',d,...
                           'Style','text',...
                           'Units','normalized',...
                           'Position',[0.10,0.30,0.80,0.40],...
                           'FontSize',14,...
                           'String','Autocell is done :)');
            
                btn = uicontrol('Parent',d,...
                                'Units','normalized',...
                                'Position',[0.375,0.10,0.25,0.20],...
                                'FontSize',10,...
                                'String','OK',...
                                'Callback','delete(gcf)');
        end
    
%-----------------------------------------------------------------------------------------------------------------------------------------------------
        function [Fit, xFit, yFit, A, B, C, Threshold1, Threshold2, xHist, yHist] = CalculateBackgroundThreshold(app,Image)

            % Fit Gaussian to background levels -----------------------------------
             maxp = ceil(double(max(Image(:))));
             minp = floor(double(min(Image(:))));
             [Nh,Eh] = histcounts(double(Image), minp:maxp) ;    
             xHist = (Eh(1:end-1) + diff(Eh(1:2))/2)';  
                    indh = find(Nh >= 1);
                yHist = Nh(indh)'; 
                xHist = xHist(indh);
                
                Fit = fit(xHist,yHist,'gauss1');
                    A = Fit.a1;
                    B = Fit.b1;
                    C = Fit.c1;
                FWHM = 2*sqrt(2*log(2))*C;
                    xFit = linspace(B-floor(2*FWHM), B+2*ceil(2*FWHM), 100);
                    yFit = Fit(xFit);
            % Calculate Threshold -------------------------------------------------    
                indf = find(xHist >= B & xHist <= B+20*C);
                xVec = xHist(indf);
                yVec = Fit(xVec);
                PerDiff = abs((yHist(indf)-yVec)./yVec);
                p = 0.5;
                indT = find(PerDiff >= p, 1);
                while isempty(indT)
                    p = p - 0.1;
                    indT = find(PerDiff >= p, 1);
                end
                Threshold1 = ceil(xVec(indT));
        
                Y_thresh = 1;
                Threshold2 = ceil(B + sqrt(-1*(C^2)*log(Y_thresh/A)));      
        end
    
%-----------------------------------------------------------------------------------------------------------------------------------------------------
        function [s, D, S] = CellDisplacement(app, X, Y, dt, dp)
            
                % s = path length between each time point (microns)
                % D = distance between first and last time point (microns)
                % S = sum total of individual displacements (microns)
                % dt = time step (minutes)
                % dp = pixel size (microns)
            
                s = dp*sqrt(diff(X).^2 + diff(Y).^2);
                D = dp*sqrt((X(end)-X(1)).^2 + (Y(end)-Y(1)).^2);
                S = sum(s);
        end
 
    
%----------------------------------------------------------------------------------------------------------------------------------------------------- 
        function MaxPixVal = CalculateRelativeMaxPixel(app,IM)

            [N3,E3] = histcounts( IM(IM>0), 25);
                    N3 = N3';
                    C3 = (E3(1,1:end-1) + diff(E3(1,1:2))/2)';
                    %----- Remove Zero Values (otherwise logarithm will fail) -----
                        N3 = N3(N3 ~= 0);
                        C3 = C3(N3 ~= 0);
                    %--------------------------------------------------------------
                Fit2  = fit(C3,log(N3),'poly1');  
                    LogA = Fit2.p2; 
                    b = Fit2.p1;
                    a = exp(LogA);
                N3fit = a*exp(b*C3); 
                
                MaxPixVal = round((1/b)*log(0.001));
                
                % figure(78); semilogy(C3,N3,'.',C3,N3fit,'-',MaxPixVal, 0.001*a,'*k')
        end

%-----------------------------------------------------------------------------------------------------------------------------------------------------
function [DATA, DR, TRACKS] = CellTrack_MSD_Analysis(app, tracksFinal, nT, dt, dp, N, nMSD, MSD_ImageFileName)
            
                %
                %   dt = time step (minutes)
                %   dp = pixel size (microns)
                %   N = tracks with less than N points will be ignored
                %
                %   DATA columns:
                %       1 Index Number
                %       2 Persistance
                %       3 Alpha
                %       4 Mean Velocity (derived from X,Y and time step)
                %       5 Median Velocity (derived from X,Y and time step)
                %       6 MSD derived Velocity (S)    ( using fit of MSD(t) = 2*P*(S^2)*(t - P + P*exp(-t/P)) );
                %       7 MSD derived Persistance (P) ( using fit of MSD(t) = 2*P*(S^2)*(t - P + P*exp(-t/P)) );
                %
                %   KRC 07/11/2017
            
                H = figure;
                H.Units = 'normalized';
                H.Position = [0.065    0.13    0.87    0.60];
                H.Color = [1,1,1];
                TL = tiledlayout(2,4,"TileSpacing","loose","Padding","compact");
                SA1 = nexttile(TL,1,[2,2]);
                SA2 = nexttile(TL,3,[2,1]);
                SA3 = nexttile(TL,4,[2,1]);
                drawnow
                
                k = 0;
                IND = [];
                DATA = zeros(0,7);
                TRACKS = [];
                DR = [];
                index = 0;

                % if strcmp(app.DataFormat,'Nikon') || strcmp(app.DataFormat,'Metamorph')
                %     nTracks = numel(tracksFinal);
                % else
                    TrackIDs = unique(tracksFinal(:,4));
                    nTracks = length(TrackIDs);
                % end

            
                for ii = 1:nTracks
                        clear data

                        % if strcmp(app.DataFormat,'Nikon') || strcmp(app.DataFormat,'Metamorph')
                        %     Track = tracksFinal(ii).tracksCoordAmpCG;
                        %     Track = mean(Track,1,'omitnan'); % Assume compound tracks (gap closing) are correct and take mean of
                        %                                      % X and Y coordinates. Mean will have no effect on non-compound
                        %                                      % tracks. (Compound tracks happen with noisy data)                            
                        %     X = Track(1,1:8:end)'; % X coordinate of cell
                        %     Y = Track(1,2:8:end)'; % Y coordinate of cell
                        %     T = (min(tracksFinal(ii).seqOfEvents(:,1)):max(tracksFinal(ii).seqOfEvents(:,1)))';
                        % 
                        % else % For Cellpose
                            Tidx = find(tracksFinal(:,4) == TrackIDs(ii));
                            X = smoothdata( tracksFinal(Tidx,1),'gaussian',7);
                            Y = smoothdata( tracksFinal(Tidx,2),'gaussian',7);
                            T = tracksFinal(Tidx,3);
                        % end
            
                        n = numel(X);
                        if n >= N % Track must have at least n points
                            if any(isnan(X)) || any(isnan(Y))
                                [X,Y] = ConnectTrack(app,X,Y);
                            end
                            %----------------------------------------------------------
                            Xraw = X;
                            Yraw = Y;
                            X = smooth(X,5); % Remove pixel jittering
                            Y = smooth(Y,5);
                            %----------------------------------------------------------
                            k = k + 1;
                            TRACKS(k,1).X = X;
                            TRACKS(k,1).Y = Y;
                            % if strcmp(app.DataFormat,'Nikon') || strcmp(app.DataFormat,'Metamorph')
                            %     TRACKS(k,1).T = (min(tracksFinal(ii).seqOfEvents(:,1)):max(tracksFinal(ii).seqOfEvents(:,1)))';
                            % else
                                TRACKS(k,1).T = T; 
                            %end
                            TRACKS(k,1).Xraw = Xraw;
                            TRACKS(k,1).Yraw = Yraw;
                            TRACKS(k,1).dp = dp;
                            TRACKS(k,1).dt = dt;
                            %----------------------------------------------------------
                            [s,D,Sd] = CellDisplacement(app, X, Y, dt, dp);
                            [V,t] = CellVelocity(app, X, Y, dt, dp);
                            Persistence = D/Sd;
                            [MSD,TAU]  = MeanSquaredDisplacement(app, dp*X, dp*Y, dt);
                            [dr, ti] = DirectionalityRatio(app, dp*X, dp*Y, dt);
                            DR{k,1}.dr = dr;
                            DR{k,1}.ti = ti;
                            alpha = 0;
                            TRACKS(k,1).Alpha = alpha; % set default value for alpha (incase MSD has a NaN in it)
                            
                            m = nMSD; %NumberOfPointsToIncludeInMSDfit(app,MSD);
            
                            if ~any(isnan(MSD)) % Check if there are any NaNs in MSD
                                [Ti,Mi,alpha,~] = MSDexponentialfit(app, TAU(1:m,1), MSD(1:m,1) );
                                TRACKS(k,1).Alpha = alpha;
                                index = index + 1;
                                IND(k,1) = ii;
                                warning off
        
                                if ~isgraphics(H)
                                    H = figure;
                                    H.Units = 'normalized';
                                    H.Position = [0.065    0.13    0.87    0.60];
                                    H.Color = [1,1,1];
                                    TL = tiledlayout(2,3,"TileSpacing","loose","Padding","compact");
                                    SA1 = nexttile(TL,1,[2,2]);
                                    SA2 = nexttile(TL,3);
                                    SA3 = nexttile(TL,6);
                                    drawnow
                                end
        
                                plot(SA1, dp*(X-X(1)), dp*(Y-Y(1)), '.-b', dp*(Xraw-Xraw(1)), dp*(Yraw-Yraw(1)),'.:r');
                                set(SA1,'XAxisLocation','bottom','YAxisLocation','left')
                                xlabel(SA1,'X(\mum)')
                                ylabel(SA1,'Y(\mum)')
                                grid(SA1,'on');
                                axis(SA1,'square')
                                axis(SA1,'equal')
                                title(SA1,['Persistance = ' num2str(Persistence,'%0.2f') ',  ii = ' num2str(ii)])
        
                                MeanVel = mean(V);
                                MedVel  = median(V);
                                plot(SA2, t, V, '.-',[0;max(t)],[MeanVel;MeanVel],'-r','LineWidth',1.5);
                                xlim(SA2,[0,max(t)])
                                ylim(SA2,[0,3])
                                xlabel(SA2,'Time(min)','FontSize',14)
                                ylabel(SA2,'Velocity (\mum/min)','FontSize',14)
                                grid(SA2,'on')
                                legend(SA2,'Velocity: dx/dt','Mean Velocity', 'location','northwest','FontSize',14);
                                title(SA2,['\rmMean Velocity: \bf' num2str(MeanVel,'%0.3f') ' \mum/min'],'FontSize',14)
        
                                %[So,Po,To,fit1o] = MSDfit(TAU, MSD, MeanVel);
                                try
                                    [S,P,T,FIT] = MSDfit(app,Ti, Mi, MeanVel);
                                catch
                                    S = []; P = [];  T = [];  FIT = [];   
                                    disp('Failed MSDfit. If this keeps happening, try removing all the Han Library (External Libraries) paths from the MATLAB paths and restart MATLAB ')
                                end

                                F = 2*P*(S^2)*(TAU - P + P*exp(-TAU/P));
                                plot(SA3, T, FIT, 'b-', Ti, Mi, '--k','LineWidth',1.5);
                                set(SA3,'YScale','log','XScale','log')
                                hold(SA3,'on')
                                scatter(SA3,TAU,MSD,80,'.r')
                                hold(SA3,'off')
                                grid(SA3,'on')
                                xlabel(SA3,'\tau (min)','FontSize',16);
                                ylabel(SA3,'MSD (um^2)','FontSize',14);
                                xlim([1,1E3])
                                ylim([1E-2,1E6])
                                TitleText =  ['\rmMSD Velocity = \bf'  , num2str(S,'%0.3f') ' \mum/min'] ;
                                if alpha > 1 
                                    TitleColor = 'k';
                                else
                                    TitleColor = 'r';
                                end
                                title(SA3,TitleText,'Color',TitleColor,'FontSize',14);
                                text(SA3,0.5,0.95,['\alpha = ', num2str(alpha,'%0.3f')],'Units','normalized',...
                                                    'Color',TitleColor,'FontSize',14,'FontWeight','bold','HorizontalAlignment','center')
                                legend(SA3,'Furth equation fit','\itk\tau^{\alpha} \rmequation fit','Calculated MSD', 'location','southeast','FontSize',14);
                                pause(0.25)
        
                                %--------------------------------------------
                                MSDimage = getframe(H);
                                if index == 1
                                   imwrite(MSDimage.cdata, MSD_ImageFileName, 'tif','WriteMode','overwrite')
                                else
                                   imwrite(MSDimage.cdata, MSD_ImageFileName, 'tif','WriteMode', 'append')
                                end
                                %-----------------------------------------------------------------------------------------------------
                                if alpha > 1 
                                    %    1        2         3      4        5     6  7  8
                                    d = [ii, Persistence, alpha, MeanVel, MedVel, S, P, n];
                                    DATA = [DATA;d];
                                else
                                    MeanVel = mean(V);
                                    MedVel  = median(V);
                                    d = [ii, Persistence, alpha, MeanVel, MedVel, NaN, NaN, n];
                                    DATA = [DATA;d];
                                end
                            else
                                MeanVel = mean(V);
                                MedVel  = median(V);
                                d = [ii, Persistence, alpha, MeanVel, MedVel, NaN, NaN, n];
                                DATA = [DATA;d];
                            end
                        end
            
                end
                try close(H); end
        end

%-----------------------------------------------------------------------------------------------------------------------------------------------------
        function [V, t] = CellVelocity(app, X, Y, dt, dp)
            
                N = length(X);
            
                S = sqrt(diff(dp*X).^2+ diff(dp*Y).^2); % distance between points vector
                V = S./dt; % speed between points vector
                t = linspace(0,(N-1)*dt,(N-1));
        end
    
%-----------------------------------------------------------------------------------------------------------------------------------------------------
        function [X2, Y2] = ConnectTrack(app, X, Y)
            
                X2 = fillmissing(X,'linear');
                Y2 = fillmissing(Y,'linear');
        end
    
%-----------------------------------------------------------------------------------------------------------------------------------------------------
%         function [VALUES, Categories, CellTracks] = CreateAnalysisPlots(app, AnalysisDir, CreatePlot, dp, dt)
%             
%                     VALUES = [];
%                     Categories = [];
%                     CellTracks = [];
%                     %----------------------------------------------------------------------------------
%                     [~, AnalysisFolders] = FilesAndFolders(app, AnalysisDir);
%                     N = length(AnalysisFolders);
%             
%                     for k = 1:N
%                         ReadDir{k,1} = fullfile(AnalysisDir,AnalysisFolders{k,1});
%                     end
%                    %-----------------------------------------------------------------------------------
%                     for F = 1:length(ReadDir)
%                         clear DATA
%                         [Files,~] = FilesAndFolders(app, ReadDir{F,1});
%                         ii = contains(Files,'MSD-ANALYSIS_');
%                         Files = Files(ii);
%                         nFiles = length(Files);
%                         % Reset storage
%                         % variables--------------------------------------------------------------------
%                         meanV = [];  medV = []; P = []; msdV = []; msdP = []; dr = [];
%                         ti = []; DR_concat = []; TI_concat = []; nT = []; MSDTracks = []; AllTracks = []; %#ok<NASGU>
%                         %||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
%                         for k = 1:nFiles
%                             load(fullfile(ReadDir{F,1}, Files{k,1}))
%                             if ~isempty(DATA)
%                                 % Col#    1       2          3       4       5     6  7  8
%                                 % DATA = [ii, Persistence, alpha, MeanVel, MedVel, S, P, nT];
%                                 % where nT is the number of time points in the track
%                                 %===============================================================================
%                                 meanV = [meanV; DATA(:, 4)];          % Concatentate all Mean Velocity measurements
%                                 medV  = [medV;  DATA(:, 5)];          % Concatentate all Median Velocity measurements
%                                 P     = [P; DATA(:,2)];               % Concatentate all persistence measurements
%                                 idx  = find( ~isnan(DATA(:,6)) );    % Find MSD cells data rows
%                                 MSDV  = DATA(idx, 6);
%                                 msdV  = [msdV; MSDV];                 % Concatentate all MSD Velocity measurements
%                                 MSDP  = DATA(~isnan(DATA(:,7)), 7);
%                                 msdP  = [msdP; MSDP];                 % Concatentate all MSD persistence measurements
%             
%                                 % BACKWARDS COMPATIBILITY (START) --------------------------------------------------
%                                 % Add pixel size and time step information for each track (this is important for
%                                 % when combining analysis files of possibly different pixel size and time step)
%                                 if ~isfield(TRACKS(1),'dp')
%                                     for n = 1:size(TRACKS,1)
%                                         TRACKS(n).dp = dp;
%                                         TRACKS(n).dt = dt;
%                                     end
%                                 end
%                                 % BACKWARDS COMPATIBILITY (END) --------------------------------------------------
%             
%                                 AllTracks = [AllTracks; TRACKS];
%                                 MSDTracks = [MSDTracks; TRACKS(idx)];
%             
%                                 for n = idx' % only use the cells used in the MSD velocity calculation %1:M
%                                     dr = [dr; {DR{n,1}.dr}];
%                                     ti = [ti; {DR{n,1}.ti}];
%                                 end
%             
%                             end
%                         end
%                         %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
%             
%                         nT  = cellfun(@length,dr);
%                         nDR = length(dr);
%                         dr2 = NaN(max(nT),nDR);
%                         ti2 = dr2;
%             
%                         for n = 1:nDR
%                             dr2(1:nT(n,1),n) = dr{n,1};
%                             ti2(1:nT(n,1),n) = ti{n,1};
%                         end
%             
%                         DR2 = mean(dr2,2,'omitnan');
%                         TI2 = mean(ti2,2,'omitnan');
%                         DR_sem = DR2./sqrt(sum(~isnan(dr2),2));
%             
%                         VALUES{F,1}.MeanVelocities = meanV;
%                         VALUES{F,1}.MedianVelocities = medV;
%                         VALUES{F,1}.Persistence = P;
%                         VALUES{F,1}.MSDVelocities = msdV;
%                         VALUES{F,1}.MSDPersistence = msdP;
%                         VALUES{F,1}.DR.dr = DR2;
%                         VALUES{F,1}.DR.ti = TI2;
%                         VALUES{F,1}.DR.sem = DR_sem;
%             
%                         CellTracks{F,1}.AllTracks = AllTracks;
%                         CellTracks{F,1}.MSDTracks = MSDTracks;
%                         CellTracks{F,1}.PixelSize = dp;
%                         CellTracks{F,1}.TimeStep = dt;
%                     end
%             
%                     Categories  = [];
%                     MSDVel      = [];
%                     MSDPer      = [];
%                     MeanVel     = [];
%                     MedVel      = [];
%                     Persis      = [];
%                     DirRatio    = [];
%             
%                     for k = 1:N
%                         Categories = [ Categories; AnalysisFolders(k)          ];
%                         MSDVel     = [ MSDVel;    {VALUES{k}.MSDVelocities}    ];
%                         MSDPer     = [ MSDPer;    {VALUES{k}.MSDPersistence}   ];
%                         MeanVel    = [ MeanVel;   {VALUES{k}.MeanVelocities}   ];
%                         MedVel     = [ MedVel;    {VALUES{k}.MedianVelocities} ];
%                         Persis     = [ Persis;    {VALUES{k}.Persistence}      ];
%                         DirRatio   = [ DirRatio;  {VALUES{k}.DR}               ];
%                     end
%             
%                     % reorder categories --------------------------------------------------------
%             %             ReOrderVector = app.AnalysisFolderOriginalOrderAssignment.Order;
%             %
%             %             Categories = Categories(ReOrderVector);
%             %             MSDVel     = MSDVel(ReOrderVector);
%             %             MSDPer     = MSDPer(ReOrderVector);
%             %             MeanVel    = MeanVel(ReOrderVector);
%             %             MedVel     = MedVel(ReOrderVector);
%             %             Persis     = Persis(ReOrderVector);
%             %             DirRatio   = DirRatio(ReOrderVector);
%                     % ---------------------------------------------------------------------------
%                 if CreatePlot
%                     hfig = figure;
%                     notch = true;
%             
%                     subplot(2,2,1)
%                         boxPlotCellArray(MSDVel, Categories,1,notch,true,2)
%                                     ylabel('MSD Velocity (\mum/min)','FontSize',12)
%                                     ylim([0 1.0]);  grid on
%                                     th = title(AnalysisDir,'Interpreter','none');
%                                     set(th,'Position',[1, th.Position(2:end)],...
%                                     'HorizontalAlignment','left')
%             
%                     subplot(2,2,2)
%                         boxPlotCellArray(MeanVel, Categories,1,notch,true,2)
%                                     ylabel('Mean Velocity (\mum/min)','FontSize',12)
%                                     ylim([0 1.0]);  grid on
%             
%                     subplot(2,2,3) % Change to Directionality
%                         c = lines(length(DirRatio));
%                         h(1,1) = plot(DirRatio{1,1}.ti, DirRatio{1,1}.dr,'LineWidth',2,'Color',c(1,:));
%             
%                         for m = 2:length(DirRatio)
%                             hold on;
%                             h(m,1) = plot(DirRatio{m,1}.ti, DirRatio{m,1}.dr,'LineWidth',2,'Color',c(m,:));
%                             hold off
%                         end
%             
%                         ylabel('Directionality Ratio','FontSize',12)
%                         xlabel('Time Interval (min)')
%                         axis([0 700 0 1]); grid on
%             
%                         for m = 1:length(DirRatio)
%                             hold on;
%                             plot(DirRatio{m,1}.ti, DirRatio{m,1}.dr + DirRatio{m,1}.sem, ':',...
%                                                         'LineWidth', 0.5, 'Color', c(m,:));
%                             plot(DirRatio{m,1}.ti, DirRatio{m,1}.dr - DirRatio{m,1}.sem, ':',...
%                                                         'LineWidth', 0.5, 'Color', c(m,:));
%                             hold off
%                         end
%             
%                         LH = legend(h,Categories);
%                         LH.Interpreter = 'none';
%             
%                     subplot(2,2,4)
%                         boxPlotCellArray(Persis, Categories,1,notch,true,2)
%                         ylabel('Persistence','FontSize',12)
%                         ylim([0 1]);  grid on
%                 end
%         end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------
        function CreateExcelFileWithStats(app, M, Cmsd, Cmean, Cpersis)
            
                status = [];
                H = WritingDataToExcelFileWaitBox(app);
                drawnow
            
                [NC,~] = size(Cmsd);
                [NM,~] = size(M);
            
                [file,path] = uiputfile('*.xlsx','Input Stats Excel File Name','CellStats.xlsx');
            
                if ~isequal(file,0) & ~isempty(file)
                        filename = fullfile(path,file);
                        A = cell(NC+3*NM+12,4);
                        % Create Cell Array to Export to Excel file -----------------------------------
                        A{2,1} = 'Median of Calculated Cell Speeds (microns/min)';
                        A(3,1:4) = [{'Category'},{'MSD Speed'},{'Mean Speed'},{'Persistence'}];
                        A(4:NM+3,1:4) = M;
    
                        A{NM+5,1} = 'Two-Sample Kolmogorov-Smirnov test (for MSD speed values)';
                        A(NM+6,1:4) = [{'Category 1'},{'Category 2'},{'Pass?'},{'P-value'}];
                        A(NM+7:NM+NC+6,1:4) = Cmsd;
    
                        A{NM+NC+8,1} = 'Two-Sample Kolmogorov-Smirnov test (for Mean speed values)';
                        A(NM+NC+9,1:4) = [{'Category 1'},{'Category 2'},{'Pass?'},{'P-value'}];
                        A(NM+NC+10:NM+2*NC+9,1:4) = Cmean;
    
                        A{NM+NC+NC+11,1} = 'Two-Sample Kolmogorov-Smirnov test (for persistence values)';
                        A(NM+NC+NC+12,1:4) = [{'Category 1'},{'Category 2'},{'Pass?'},{'P-value'}];
                        A(NM+NC++NC+13:NM+3*NC+12,1:4) = Cpersis;
                        %------------------------------------------------------------------------------
                        xlRange = ['A1:D',num2str(NM+3*NC+12)];
                        status = xlswrite(filename,A,xlRange);
                end
                drawnow
                try close(H); end
        end
       
%-----------------------------------------------------------------------------------------------------------------------------------------------------
        % function pairs = CreatePairCombinationsNoRepeatsNoSame(app, n)
        % 
        %         [p,q] = meshgrid(1:n,1:n);
        %         [pairs,~] = unique(sort([p(:),q(:)],2),'rows');
        %         D = diff(pairs,1,2);
        %         pairs(D==0,:) = [];
        % end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------
        function [DR, TI] = DirectionalityRatio(app, X, Y, dt)
            
                N = length(X);
                DR = zeros(N-1,1);
                TI = dt*(1:(N-1));
            
                for ii = 2:N
                    s = sqrt(diff(X(1:ii)).^2 + diff(Y(1:ii)).^2);
                    d = sqrt((X(ii)-X(1)).^2 + (Y(ii)-Y(1)).^2);
                    S = sum(s);
                    DR(ii-1,1) = d./S;
                end
            
                TI = TI';
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------       
        % function ExtractND2Images1(app, ReadDir, WriteDir, dP)
        % 
        %         [~, Folders] = FilesAndFolders(app,ReadDir);
        % 
        %         Findex = 0;
        %         h1 = waitbar(0,'Exporting ND2 files to tiff');
        %         ind1 = ~contains(Folders,["ANALYSIS","IMAGE_DATA","SUMMARY_IMAGES"]);
        %         Folders = Folders(ind1);
        %         nF = length(Folders);
        % 
        %         % Calculate total nd files for waitbar ----------------------------------------
        %         TotalNDfiles = 0;
        %         for f = 1:nF
        %             READDIR = fullfile(ReadDir,Folders{f});
        %             [Files, ~] = FilesAndFolders(app,READDIR);
        %             nFiles = size(Files,1);
        %             TotalNDfiles = TotalNDfiles + nFiles;
        %         end
        %         %------------------------------------------------------------------------------
        % 
        %         for f = 1:nF
        %             READDIR = fullfile(ReadDir,Folders{f});
        %             [Files, ~] = FilesAndFolders(app,READDIR);
        %             nf = size(Files,1);
        %             for k = 1:nf
        %                     Findex = Findex + 1;
        %                     mkdir(fullfile(WriteDir, Folders{f,1}, strip(Files{k,1}(1:end-4))))
        %                     CurrentND2File  = fullfile(READDIR, Files{k,1});
        %                     %==============================================================================================================
        %                     %[ImageData, nCh, nTimePt] = Read_nd2_Image_File(app,CurrentND2File); % http://www.openmicroscopy.org/bio-formats/
        %                     reader = bfGetReader(CurrentND2File); 
        %                     nSeries = reader.getSeriesCount; % Number of series (movies) in the file
        %                     iSeries = 1; % Specify series number to read from (between 1 and nSeries)
        %                     reader.setSeries(iSeries - 1); % Set image series to read from
        %                     BitDepth = reader.getBitsPerPixel; 	% Bit depth
        %                     % Height = reader.getSizeX; 		% Image height
        %                     % Width = reader.getSizeY; 		% Image width
        %                     nZ = reader.getSizeZ; 		% number of z
        %                     nT = reader.getSizeT; 		% number of time points
        %                     nCh = reader.getSizeC;		% Number of channels
        % 
        %                     % app.DataFormat = 'Nikon';
        %                     % app.DataFormat = 'Nikon-CP';
        %                     %==============================================================================================================
        %                     waitbar(Findex/TotalNDfiles,h1,['Exporting ND2 files to tiff: ' num2str(Findex) ' of ' num2str(TotalNDfiles)])
        %                     for c = 1:nCh
        %                         chText = ['c', sprintf('%02g',c)];
        %                         CurrentWriteDir1 = fullfile( WriteDir, Folders{f}, strip(Files{k,1}(1:end-4)), ['Ch', num2str(c)] );
        %                         mkdir(CurrentWriteDir1)
        %                         for t = 1:nT
        %                                     z = 1;
        %                                     tText = ['_t', sprintf('%05g',t)];
        %                                     DPtext = sprintf('%1.3f',dP);
        %                                     idx = strfind(DPtext,'.'); % Find decimal point and replace with the letter 'd'
        %                                     DPtext(idx) = 'd';
        %                                     Ztext = sprintf('%02d',nZ);
        %                                     FileName = fullfile( CurrentWriteDir1, [strip(Files{k,1}(1:end-4)),'_ps',DPtext,'_','nZ',Ztext,tText,chText,'.tif'] );
        %                                     % IM1 = squeeze( ImageData(:,:,t,c) );
        %                                     iPlane = reader.getIndex(z-1,c-1,t-1) + 1;    % Convert to plane number
        %                                     IM1 = bfGetPlane(reader, iPlane);             % Get image data and convert to double precision
        %                                     imwrite(IM1, FileName,'tif','WriteMode','overwrite','Compression','none','RowsPerStrip',size(IM1,1));
        % 
        %                         end
        %                     end
        %             end
        % 
        %         end           
        % 
        %         try close(h1);  end
        % 
        % 
        % end
    
%-----------------------------------------------------------------------------------------------------------------------------------------------------        
        function [files, folders] = FilesAndFolders(app, directory)
            
            %  ______________________________________________
            %  Grab and sort files and folders from directory
            %
            %  [files folders] = FilesAndFolders(app,directory)
            %
            %  Keith R Carney  10/30/2008
            %  www.cellimagingcore.org/www.keithcarney.com
            %  kcarney@genetics.utah.edu
            %
            
                        DirInfo = dir(directory); % struct array with fields: name, date, bytes, isdir, datenum
                        DirInfoCell = struct2cell(DirInfo); % ([name date bytes isdir datenum],N)
                        names = DirInfoCell(1,:)'; % Grab Names only
                        designation = DirInfoCell(5,:)'; % TRUE = Folder/Dir, FALSE = File
            
                        % List names of hidden files and folders that are operating system specific (PC and MAC)
                        flag1 =  strcmp('.',names);
                        flag2 =  strcmp('..',names);
                        flag3 =  strcmp('Thumbs.db',names);
                        flag4 =  strcmp('.DS_Store',names);
                        flag5 =  strcmp('._.DS_Dtore',names);
                        flags = [flag1, flag2, flag3, flag4, flag5];
            
                        % Use logical Indexing to grab only names and designations that are non-operating system specific
                        FilteredNames = names(all(~flags,2),1);
                        FilteredDesignation = cell2mat(designation(all(~flags,2),1)); % Convert this cell array to matrix array for logical indexing below
            
                        % Now sort into File and Folder cell arrays
                        files  = FilteredNames(~FilteredDesignation);
                        folders = FilteredNames(FilteredDesignation);
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function FileName = FindMetamorphFileName(app, CST, c, s, t, FileList)
            
                    idxC = find(CST(:,1) == c);
                    idxS = find(CST(:,2) == s);
                    idxT = find(CST(:,3) == t);
            
                    idx2 = intersect(idxC,idxS);
                    idx  = intersect(idx2,idxT);
            
                    if isempty(idx)
                        FileName = [];
                    else
                        FileName = FileList{idx,1};
                    end
                    
        end
    
%-----------------------------------------------------------------------------------------------------------------------------------------------------        
        function nSP = FindTotalNumberOfStagePositions(app, ReadDir1, Folders1)
            
                ind = ~contains( Folders1, ["ANALYSIS", "IMAGE_DATA", "SUMMARY_IMAGES"] );
                Folders1 = Folders1(ind);
                nC = size(Folders1,1);
                nSP = 0;
            
                for c = 1:nC
                    [files, ~] = FilesAndFolders(app,fullfile(ReadDir1,Folders1{c,1}) );
                    nS = size(files,1);
                    nSP = nSP+nS;
                end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function MSDHigherAlpha_CreateFcn(app, hObject, eventdata, handles)
            
                if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                    set(hObject,'BackgroundColor','white');
                end
        end
   
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function [TAUi, MSDi, alpha, y_int] = MSDexponentialfit(app, TAU, MSD)
            
                             % fit the equation Y = c*x^n
                % where alpha = n and coeff = c
            
                    % Data is ideally exponential, and exponentials are
                    % lines in log space. So take log of data and fit a line to it
                    %nPts = length(TAU);
                    LT = log(TAU);
                    LM = log(MSD);
                    p = polyfit(LT,LM,1); % Linear fit of data in logspace
                    % Weights = ones(nPts,1);
                    % if N < nPts
                    %     Weights(N:end,1) = ( (1./LT(N:end))./(1/LT(N)) ).^0.5;
                    % end
                    % f = fit(LT,LM,'poly1','Weights',Weights);
                    % Fit Coefficients
                    alpha = p(1);
                    y_int = p(2);
                    %Create data vectors based on fit
                    %TAUi = logspace(log10(min(TAU)), log10(max(TAU)),100);
                    TAUi = TAU;
                    MSDi = exp(y_int)*TAU.^alpha;
                    %=========================================================
                    % LT = log(TAU);
                    % Xoffset = LT(1);
                    % LT = LT - Xoffset;
                    % LM = log(MSD);
                    % LTinterp = linspace(LT(1),LT(end),100);
                    % LMinterp = interp1(LT,LM,LTinterp,'linear');                                     
                    % alpha = (LM(1) - LM(end))/(LT(1) - LT(end));
                    % y_int = LM(1);
                    % LMfit = alpha*(LTinterp) + y_int;
                    % Xfit = LTinterp + Xoffset;
                    % Yfit = LMfit;
                    % % figure(7); clf   
                    % % plot(LT+Xoffset,LM,'.r',Xfit,Yfit,'.-b')
                    % TAUi = exp(Xfit);
                    % MSDi = exp(Yfit);
                    % %loglog(exp(Xfit),exp(Yfit),'.-b',TAU,MSD,'-r')
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function [S, P, T, FIT] = MSDfit(app, T, M, SV)
            
            fo = fitoptions('Method','NonlinearLeastSquares',...
               'Lower',[0.01, 0.001],...
               'Upper',[6, 1E5],...
               'StartPoint',[SV, 10]);
            ft = fittype('2*P*(S^2)*(x-P+P*exp(-x/P))',independent="x",coefficients=["S","P"],options=fo);
            F  = fit(T,M,ft);
            S = F.S;
            P = F.P;
            FIT = 2*P*(S^2)*(T - P + P*exp(-T/P));

                % % have to give the parameters some 'rough' start values
                % % a = [s p];
                % a = [SV, 10];  % SV = the starting value for fitting
                % % Define FitEquation
                % FitEquation = @(a,t) 2*a(2)*(a(1)^2)*(t - a(2) + a(2)*exp(-t/a(2)));
                % % Apply Curve fitting function
                % % options = optimset('Display','off','TolFun',1e-4,'LargeScale','on'); 
                % options = optimoptions('lsqcurvefit','FunctionTolerance',1e-6,'Display','off','StepTolerance',1e-6);
                % [v,~,~,~,~,~,~] = lsqcurvefit(FitEquation,a,T,M,[0.01 0.001],[6 1E5],options);
                % % Create new Y-data vector based on equation and fit parameters
                % FIT = 2*v(2)*(v(1)^2)*(T - v(2) + v(2)*exp(-T/v(2)));
                % % Plot data created using Fit Equation on top of original y data in red
                % S = v(1); 
                % P = v(2);
        end
        
    
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function [MSD, TAU] = MeanSquaredDisplacement(app, X, Y, dt)
            
                % [MSD,TAU,err] = MeanSquaredDisplacement(X,Y,dt)
                %
                % X = x coordinate of cell track
                % Y = y-coordinate of cell track
                % dt = time step
                %
                % Keith Carney 4/17/2019
            
                N = numel(X);
                nTau = N-1;
            
                %Calculate Mean Square Displacement ------------------------------------------------
            
                tau = (1:nTau)';
                MSD = zeros(nTau,1);
                ind = (1:N)';
            
                for n = 1:nTau
                    idx1 = ind(1:end-n);
                    idx2 = ind(n+1:end);
                    x = X(idx2)-X(idx1);
                    y = Y(idx2)-Y(idx1);
                    m = x.^2 + y.^2;
                    MSD(n,1) = mean(m);
                end
            
                TAU = dt*tau;
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function m = NumberOfPointsToIncludeInMSDfit(app,MSD)
            
                val = logical(app.nPts_MSDFit.Value);
            
                switch val
                    case false
                        m = length(MSD);
                    case true
                        m = min([app.nMSDPtsForFit; length(MSD)]); % compare values just in case nMSDPtsForFit is more than the length of MSD
                end
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function SGFS = SuggestedGaussianFilterSize(app)
            
                dp = app.PixelSizeInput.Value;
                if ~isempty(dp)
                    SGFS = round( (app.DefaultPixelSize/dp)*app.DefaultSigma, 1);
                end

        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
%         function [gapCloseParam, costMatrices, kalmanFunctions, verbose, probDim] = TrackParametersKRC(app)
% 
%             %% general gap closing parameters
%             gapCloseParam.timeWindow  = 5; % 5; % 1; maximum allowed time gap (in frames) between a track segment end and a track segment start that allows linking them.
%             gapCloseParam.mergeSplit  = 3; % 2; % if merging and splitting are to be considered, 2 if only merging is to be considered, 3 if only splitting is to be considered, 0 if no merging or splitting are to be considered.
%             gapCloseParam.minTrackLen = 3; % 3; % minimum length of track segments from linking to be used in gap closing.
% 
%             %optional input:
%             gapCloseParam.diagnostics = 0; %0 to plot a histogram of gap lengths in the end; 0 or empty otherwise.
% 
%             %% cost matrix for frame-to-frame linking
% 
%             %function name
%             costMatrices(1).funcName = 'costMatRandomDirectedSwitchingMotionLink';
% 
%             %parameters
%             parameters.linearMotion    = 1;  % 1  % use linear motion Kalman filter.
%             parameters.minSearchRadius = 1;  % 1  % minimum allowed search radius. The search radius is calculated on the spot in the code given a feature's motion parameters. If it happens to be smaller than this minimum, it will be increased to the minimum.
%             parameters.maxSearchRadius = 100;% 20 % maximum allowed search radius. Again, if a feature's calculated search radius is larger than this maximum, it will be reduced to this maximum.
%             parameters.brownStdMult    = 3;  % 1  % multiplication factor to calculate search radius from standard deviation.
% 
%             parameters.useLocalDensity = 1;  % 1 if you want to expand the search radius of isolated features in the linking (initial tracking) step.
%             parameters.nnWindow = gapCloseParam.timeWindow; % number of frames before the current one where you want to look to see a feature's nearest neighbor in order to decide how isolated it is (in the initial linking step).
% 
%             parameters.kalmanInitParam = []; % Kalman filter initialization parameters.
%             % parameters.kalmanInitParam.searchRadiusFirstIteration = 10; %Kalman filter initialization parameters.
% 
%             %optional input
%             parameters.diagnostics = []; %if you want to plot the histogram of linking distances up to certain frames, indicate their numbers; 0 or empty otherwise. Does not work for the first or last frame of a movie.
% 
%             costMatrices(1).parameters = parameters;
%             clear parameters
% 
%             %% cost matrix for gap closing
% 
%             %function name
%             costMatrices(2).funcName = 'costMatRandomDirectedSwitchingMotionCloseGaps';
% 
%             %parameters
% 
%             %needed all the time
%             parameters.linearMotion = 1; %0; %use linear motion Kalman filter.
% 
%             parameters.minSearchRadius = 1;   % 1  %minimum allowed search radius.
%             parameters.maxSearchRadius = 100; % 20 %maximum allowed search radius.
%             parameters.brownStdMult    = 5*ones(gapCloseParam.timeWindow,1); % 3 multiplication factor to calculate Brownian search radius from standard deviation.
% 
%             parameters.brownScaling = [0.25 0.01]; %[0.25 0.01] power for scaling the Brownian search radius with time, before and after timeReachConfB (next parameter).
%             % parameters.timeReachConfB = 3; %before timeReachConfB, the search radius grows with time with the power in brownScaling(1); after timeReachConfB it grows with the power in brownScaling(2).
%             parameters.timeReachConfB = gapCloseParam.timeWindow; %before timeReachConfB, the search radius grows with time with the power in brownScaling(1); after timeReachConfB it grows with the power in brownScaling(2).
% 
%             parameters.ampRatioLimit  = []; % for merging and splitting. Minimum and maximum ratios between the intensity of a feature after merging/before splitting and the sum of the intensities of the 2 features that merge/split.
% 
%             parameters.lenForClassify = 5;  %5; %minimum track segment length to classify it as linear or random.
% 
%             parameters.useLocalDensity = 1; %1; %1 if you want to expand the search radius of isolated features in the gap closing and merging/splitting step.
%             parameters.nnWindow = gapCloseParam.timeWindow; %number of frames before/after the current one where you want to look for a track's nearest neighbor at its end/start (in the gap closing step).
% 
%             parameters.linStdMult = 3*ones(gapCloseParam.timeWindow,1); %multiplication factor to calculate linear search radius from standard deviation.
% 
%             parameters.linScaling = [1 0.01]; %[1 0.01] power for scaling the linear search radius with time (similar to brownScaling).
%             % parameters.timeReachConfL = 4; % similar to timeReachConfB, but for the linear part of the motion.
%             parameters.timeReachConfL = gapCloseParam.timeWindow; % similar to timeReachConfB, but for the linear part of the motion.
% 
%             parameters.maxAngleVV = 360; %30; %maximum angle between the directions of motion of two tracks that allows linking them (and thus closing a gap). Think of it as the equivalent of a searchRadius but for angles.
% 
%             %optional; if not input, 1 will be used (i.e. no penalty)
%             parameters.gapPenalty = 1.5; %penalty for increasing temporary disappearance time (disappearing for n frames gets a penalty of gapPenalty^(n-1)).
% 
%             %optional; to calculate MS search radius
% 
%             %if not input, MS search radius will be the same as gap closing search radius
%             parameters.resLimit = [];%[]; %resolution limit, which is generally equal to 3 * point spread function sigma.
% 
%             %NEW PARAMETER
%             parameters.gapExcludeMS = 1; %flag to allow gaps to exclude merges and splits
% 
%             %NEW PARAMETER
%             parameters.strategyBD = -1; %strategy to calculate birth and death cost
% 
%             costMatrices(2).parameters = parameters;
%             clear parameters
% 
%             %% Kalman filter function names
% 
%             kalmanFunctions.reserveMem  = 'kalmanResMemLM';
%             kalmanFunctions.initialize  = 'kalmanInitLinearMotion';
%             kalmanFunctions.calcGain    = 'kalmanGainLinearMotion';
%             kalmanFunctions.timeReverse = 'kalmanReverseLinearMotion';
% 
%             %% additional input
% 
%             %saveResults
%             % saveResults.dir = '/project/biophysics/jaqaman_lab/vegf_tsp1/slee/VEGFR2/2015/20150312_HMVECp6_AAL/AAL-NoVEGF/Stream_10min/TrackingPackage/tracks/'; %directory where to save input and output
%             % saveResults.filename = 'tracksTest.mat'; %name of file where input and output are saved
%             % saveResults = 0; %don't save results
% 
%             %verbose state
%             verbose = 1;
% 
%             %problem dimension
%             probDim = 2;
%         end
% 
% 
% %-----------------------------------------------------------------------------------------------------------------------------------------------------    
%         function tracksFinal = convertMat2Struct(app, tracksCoordAmpLink, tracksFeatIndxLink)
% 
%             %get number of tracks
%             numTracks = size(tracksCoordAmpLink,1);
% 
%             %reserve memory for structure storing tracks
%             tracksFinal = repmat(struct('tracksFeatIndxCG',[],...
%                 'tracksCoordAmpCG',[],'seqOfEvents',[]),numTracks,1);
% 
%             %get the start and end time of tracks
%             trackSEL = getTrackSEL(tracksCoordAmpLink);
% 
%             %go over all tracks and store information
%             for iTrack = 1 : numTracks
% 
%                 %track start time and end time
%                 startTime = trackSEL(iTrack,1);
%                 endTime = trackSEL(iTrack,2);
% 
%                 %feature indices
%                 tracksFinal(iTrack).tracksFeatIndxCG = tracksFeatIndxLink(iTrack,startTime:endTime);
% 
%                 %feature coordinates and amplitudes
%                 tracksFinal(iTrack).tracksCoordAmpCG = full(tracksCoordAmpLink(iTrack,...
%                     (startTime-1)*8+1:endTime*8));
% 
%                 %sequence of events
%                 tracksFinal(iTrack).seqOfEvents = [startTime 1 1 NaN; endTime 2 1 NaN];
% 
%             end
%         end
        

%-----------------------------------------------------------------------------------------------------------------------------------------------------    
        function IMS = imsaturate(app, Im, p)
                %
                %     Image Saturation (AutoContrast)
                %           IMS = imsaturate(app,Im,p)
                %
                %           Im = 8bit grayscale image
                %           p = percent saturation on each end of histogram (0-49)
                %
                % KRC 06/26/2009
                %
                IMS = imadjust(Im,stretchlim(Im,[p/100 (100-p)./100]));
        end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------    
%         function popupmenu1_CreateFcn(app, hObject, eventdata, handles)
%             
%                 if ispc && isequal(get(hObject,'BackgroundColor'),...
%                         get(0,'defaultUicontrolBackgroundColor'))
%                     set(hObject,'BackgroundColor','white');
%                 end
%         end
        
%-----------------------------------------------------------------------------------------------------------------------------------------------------
        % function [tracksFinal, kalmanInfoLink, errFlag] = trackCloseGapsKalmanSparse(app, movieInfo, costMatrices, gapCloseParam, kalmanFunctions, probDim, saveResults, verbose, varargin)
        % 
        %         % This function is borrowed from https://github.com/DanuserLab/u-track
        %         %
        %         %TRACKCLOSEGAPSKALMANSPARSE (1) links features between frames, possibly using the Kalman Filter for motion propagation and (2) closes gaps, with merging and splitting
        %         %
        %         %SYNOPSIS [tracksFinal,kalmanInfoLink,errFlag] = trackCloseGapsKalmanSparse(...
        %         %    movieInfo,costMatrices,gapCloseParam,kalmanFunctions,probDim,...
        %         %    saveResults,verbose)
        %         %
        %         %INPUT  movieInfo    : Array of size equal to the number of frames in a
        %         %                      movie, containing at least the fields:
        %         %             .xCoord      : x-coordinates of detected features.
        %         %                            1st column: value, 2nd column: standard
        %         %                            deviation (zeros if not available).
        %         %             .yCoord      : y-coordinates of detected features.
        %         %                            1st column: value, 2nd column: standard
        %         %                            deviation (zeros if not available).
        %         %             .zCoord      : z-coordinates of detected features.
        %         %                            1st column: value, 2nd column: standard
        %         %                            deviation (zeros if not available).
        %         %                            Optional. Skipped if problem is 2D. Default: zeros.
        %         %             .amp         : "Intensities" of detected features.
        %         %                            1st column: values (ones if not available),
        %         %                            2nd column: standard deviation (zeros if not
        %         %                            available).
        %         %           ADDITIONAL FIELDS:
        %         %             .kinType     : Kinetochore type: 0 - inlier, 1 -
        %         %                            unaligned, 2 - lagging. Needed only when
        %         %                            using costMatHeLaKinsLink and costMatHeLaKinsCloseGaps.
        %         %       costMatrices : 2-by-1 array indicating cost matrices and their
        %         %                      parameters.
        %         %                      -1st entry supplies the cost matrix for linking
        %         %                       between consecutive frames.
        %         %                      -2nd entry supplies the cost matrix for closing gaps
        %         %                       and merging and splitting.
        %         %                      Each entry is a structure with fields:
        %         %             .funcName    : Name of function used to calculate cost matrix.
        %         %             .parameters  : Structure containing parameters needed for cost matrix.
        %         %       gapCloseParam: Structure containing variables needed for gap closing.
        %         %                      Contains the fields:
        %         %             .timeWindow   : Largest time gap between the end of a track and the
        %         %                             beginning of another that could be connected to it.
        %         %             .mergeSplit   : Logical variable with value 1 if the merging
        %         %                             and splitting of trajectories are to be consided;
        %         %                             and 0 if merging and splitting are not allowed.
        %         %             .minTrackLen  : Minimum length of tracks obtained from
        %         %                             linking to be used in gap closing.
        %         %             .diagnostics  : Logical variable with value 1 to plot a
        %         %                             histogram of gap lengths; 0 otherwise.
        %         %                             Optional. Default: 0.
        %         %       kalmanFunctions: Names of Kalman filter functions for self-adaptive
        %         %                        tracking. Structure with fields:
        %         %             .reserveMem   : Reserves memory for kalmanFilterInfo.
        %         %             .initialize   : Initializes the Kalman filter for an appearing
        %         %                             feature.
        %         %             .calcGain     : Calculates the Kalman gain after linking.
        %         %             .timeReverse  : Reverses time (and associated variables) in
        %         %                             kalmanInfoLink between the different
        %         %                             frame-to-frame linking steps.
        %         %                        For non-self-adaptive tracking, enter [].
        %         %                        Optional. Default: [].
        %         %       probDim      : Problem dimensionality. 2 (for 2D) or 3 (for 3D).
        %         %                      Optional. If not input, dimensionality will be
        %         %                      derived from movieInfo.
        %         %       saveResults  : 0 if no saving is requested.
        %         %                      If saving is requested, structure with fields:
        %         %           .dir          : Directory where results should be saved.
        %         %                           Optional. Default: current directory.
        %         %           .filename     : Name of file where results should be saved.
        %         %                      Or []. Default: trackedFeatures in directory
        %         %                      where run is initiated.
        %         %                      Whole structure optional.
        %         %       verbose      : 1 to show calculation progress, 0 otherwise.
        %         %                      Optional. Default: 1.
        %         %
        %         %       All optional variables can be entered as [] to use default values.
        %         %
        %         %OUTPUT tracksFinal   : Structure array where each element corresponds to a
        %         %                       compound track. Each element contains the following
        %         %                       fields:
        %         %           .tracksFeatIndxCG: Connectivity matrix of features between
        %         %                              frames, after gap closing. Number of rows
        %         %                              = number of track segments in compound
        %         %                              track. Number of columns = number of frames
        %         %                              the compound track spans. Zeros indicate
        %         %                              frames where track segments do not exist
        %         %                              (either because those frames are before the
        %         %                              segment starts or after it ends, or because
        %         %                              of losing parts of a segment.
        %         %           .tracksCoordAmpCG: The positions and amplitudes of the tracked
        %         %                              features, after gap closing. Number of rows
        %         %                              = number of track segments in compound
        %         %                              track. Number of columns = 8 * number of
        %         %                              frames the compound track spans. Each row
        %         %                              consists of
        %         %                              [x1 y1 z1 a1 dx1 dy1 dz1 da1 x2 y2 z2 a2 dx2 dy2 dz2 da2 ...]
        %         %                              NaN indicates frames where track segments do
        %         %                              not exist, like the zeros above.
        %         %           .seqOfEvents     : Matrix with number of rows equal to number
        %         %                              of events happening in a compound track and 4
        %         %                              columns:
        %         %                              1st: Frame where event happens;
        %         %                              2nd: 1 = start of track segment, 2 = end of track segment;
        %         %                              3rd: Index of track segment that ends or starts;
        %         %                              4th: NaN = start is a birth and end is a death,
        %         %                                   number = start is due to a split, end
        %         %                                   is due to a merge, number is the index
        %         %                                   of track segment for the merge/split.
        %         %       kalmanInfoLink: Structure array with number of entries equal to
        %         %                       number of frames in movie. Contains the fields
        %         %                       defined in kalmanFunctions.reserveMem (at
        %         %                       least stateVec, stateCov and noiseVar).
        %         %       errFlag       : 0 if function executes normally, 1 otherwise.
        %         %
        %         % Khuloud Jaqaman, April 2007
        % 
        %         %% Output
        % 
        %         tracksFinal    = [];
        %         kalmanInfoLink = [];
        %         % numPotLinksPerFeature = [];
        %         % numPotLinksPerTrack = [];
        %         errFlag        =  0;
        % 
        %         %% Input
        % 
        %         %check whether correct number of input arguments was used
        %         if nargin < 3
        %             disp('--trackCloseGapsKalmanSparse: Incorrect number of input arguments!');
        %             errFlag = 1;
        %             return
        %         end
        % 
        %         %check whether tracking is self-adaptive
        %         if nargin < 4 || isempty(kalmanFunctions)
        %             kalmanFunctions = [];
        %             selfAdaptive = 0;
        %         else
        %             selfAdaptive = 1;
        %         end
        % 
        %         %get number of frames in movie
        %         numFrames = length(movieInfo);
        % 
        %         %check whether z-coordinates were input, making problem potentially 3D
        %         if isfield(movieInfo,'zCoord')
        %             probDimT = 3;
        %         else
        %             probDimT = 2;
        %         end
        % 
        %         %assign problem dimensionality if not input
        %         if nargin < 5 || isempty(probDim)
        %             probDim = probDimT;
        %         else
        %             if probDim == 3 && probDimT == 2
        %                 disp('--trackCloseGapsKalmanSparse: Inconsistency in input. Problem 3D but no z-coordinates.');
        %                 errFlag = 1;
        %             end
        %         end
        % 
        %         %determine where to save results
        %         if nargin < 6 || isempty(saveResults) %if nothing was input
        %             saveResDir = pwd;
        %             saveResFile = 'trackedFeatures';
        %             saveResults.dir = pwd;
        %         else
        %             if isstruct(saveResults)
        %                 if ~isfield(saveResults,'dir') || isempty(saveResults.dir)
        %                     saveResDir = pwd;
        %                 else
        %                     saveResDir = saveResults.dir;
        %                 end
        %                 if ~isfield(saveResults,'filename') || isempty(saveResults.filename)
        %                     saveResFile = 'trackedFeatures';
        %                 else
        %                     saveResFile = saveResults.filename;
        %                 end
        %             else
        %                 saveResults = 0;
        %             end
        %         end
        % 
        %         %check whether verbose
        %         if nargin < 7 || isempty(verbose)
        %             verbose = 1;
        %         end
        % 
        %         %exit if there are problems with input
        %         if errFlag
        %             disp('--trackCloseGapsKalmanSparse: Please fix input parameters.');
        %             return
        %         end
        % 
        %         %% preamble
        % 
        %         %get gap closing parameters from input
        %         mergeSplit = gapCloseParam.mergeSplit;
        %         minTrackLen = gapCloseParam.minTrackLen;
        % 
        %         %make sure that gapCloseParam.timeWindow is not equal to 0
        %         %set to 1 in this case, in order to not have any gap closing
        %         if gapCloseParam.timeWindow == 0
        %             gapCloseParam.timeWindow = 1;
        %         end
        % 
        %         %get number of features in each frame
        %         if ~isfield(movieInfo,'num')
        %             for iFrame = 1 : numFrames
        %                 movieInfo(iFrame).num = size(movieInfo(iFrame).xCoord,1);
        %             end
        %         end
        % 
        %         %collect coordinates and their std in one matrix in each frame
        %         if ~isfield(movieInfo,'allCoord')
        %             switch probDim
        %                 case 2
        %                     for iFrame = 1 : numFrames
        %                         movieInfo(iFrame).allCoord = [movieInfo(iFrame).xCoord ...
        %                             movieInfo(iFrame).yCoord];
        %                     end
        %                 case 3
        %                     for iFrame = 1 : numFrames
        %                         movieInfo(iFrame).allCoord = [movieInfo(iFrame).xCoord ...
        %                             movieInfo(iFrame).yCoord movieInfo(iFrame).zCoord];
        %                     end
        %             end
        %         end
        % 
        %         %calculate nearest neighbor distance for each feature in each frame
        %         if ~isfield(movieInfo,'nnDist')
        % 
        %             for iFrame = 1 : numFrames
        % 
        %                 switch movieInfo(iFrame).num
        % 
        %                     case 0 %if there are no features
        % 
        %                         %there are no nearest neighbor distances
        %                         nnDist = zeros(0,1);
        % 
        %                     case 1 %if there is only 1 feature
        % 
        %                         %assign nearest neighbor distance as 1000 pixels (a very big
        %                         %number)
        %                         nnDist = 1000;
        % 
        %                     otherwise %if there is more than 1 feature
        % 
        %                         %compute distance matrix
        %                         nnDist = createDistanceMatrix(movieInfo(iFrame).allCoord(:,1:2:end),...
        %                             movieInfo(iFrame).allCoord(:,1:2:end));
        % 
        %                         %sort distance matrix and find nearest neighbor distance
        %                         nnDist = sort(nnDist,2);
        %                         nnDist = nnDist(:,2);
        % 
        %                 end
        % 
        %                 %store nearest neighbor distance
        %                 movieInfo(iFrame).nnDist = nnDist;
        % 
        %             end
        % 
        %         end
        % 
        %         %remove empty frames in the beginning and the end and keep the information
        %         %for later
        %         emptyStart = 0;
        %         numFeatures = vertcat(movieInfo.num);
        %         emptyFrames = find(numFeatures == 0);
        %         if ~isempty(emptyFrames)
        %             findEmpty = emptyFrames(1) == 1;
        %         else%
        %             findEmpty = 0;
        %         end
        %         while findEmpty
        %             emptyStart = emptyStart + 1;
        %             numFeatures = numFeatures(2:end);
        %             emptyFrames = find(numFeatures == 0);
        %             if ~isempty(emptyFrames)
        %                 findEmpty = emptyFrames(1) == 1;
        %             else
        %                 findEmpty = 0;
        %             end
        %         end
        %         emptyEnd = 0;
        %         numFeatures = vertcat(movieInfo.num);
        %         emptyFrames = find(numFeatures == 0);
        %         if ~isempty(emptyFrames)
        %             findEmpty = emptyFrames(end) == length(numFeatures);
        %         else
        %             findEmpty = 0;
        %         end
        %         while findEmpty
        %             emptyEnd = emptyEnd + 1;
        %             numFeatures = numFeatures(1:end-1);
        %             emptyFrames = find(numFeatures == 0);
        %             if ~isempty(emptyFrames)
        %                 findEmpty = emptyFrames(end) == length(numFeatures);
        %             else
        %                 findEmpty = 0;
        %             end
        %         end
        %         movieInfo = movieInfo(emptyStart+1:numFrames-emptyEnd);
        %         numFramesEff = length(movieInfo);
        % 
        %         if numFramesEff == 0
        %             disp('Empty movie. Nothing to track.');
        %             return
        %         end
        % 
        %         %% Link between frames
        %         % For skipping frames:
        %         repFrame=1;
        %         if repFrame>1
        %             movieInfo=movieInfo(1:repFrame:end);
        %             numFramesEff = length(movieInfo);
        %         end
        %         % if self-adaptive, link in multiple rounds
        %         if selfAdaptive
        % 
        %             %get initial track segments by linking features between consecutive frames
        %             if verbose
        %                 disp('Linking features forwards ...');
        %             end
        %             [tmp,dummy,kalmanInfoLink,dummy,linkingCosts] = linkFeaturesKalmanSparse(...
        %                 movieInfo,costMatrices(1).funcName,costMatrices(1).parameters,...
        %                 kalmanFunctions,probDim,[],[],verbose);
        %             clear dummy
        % 
        %             %time-reverse Kalman filter information
        %             % -- USER DEFINED FUNCTION -- %
        %             eval(['kalmanInfoLink = ' kalmanFunctions.timeReverse ...
        %                 '(kalmanInfoLink,probDim);']);
        % 
        %             %redo the linking by going backwards in the movie and using the
        %             %Kalman filter information from the first linking attempt
        %             %this will improve the linking and the state estimation
        %             if verbose
        %                 disp('Linking features backwards ...');
        %             end
        %             [dummy,dummy,kalmanInfoLink,dummy,linkingCosts] = linkFeaturesKalmanSparse(...
        %                 movieInfo(end:-1:1),costMatrices(1).funcName,costMatrices(1).parameters,...
        %                 kalmanFunctions,probDim,kalmanInfoLink,linkingCosts,verbose);
        %             clear dummy
        % 
        %             %time-reverse Kalman filter information
        %             % -- USER DEFINED FUNCTION -- %
        %             eval(['kalmanInfoLink = ' kalmanFunctions.timeReverse ...
        %                 '(kalmanInfoLink,probDim);']);
        % 
        %             %go forward one more time to get the final estimate of the initial track
        %             %segments
        %             if verbose
        %                 disp('Linking features forwards ...');
        %             end
        %             [tracksFeatIndxLink,tracksCoordAmpLink,kalmanInfoLink,nnDistLinkedFeat,...
        %                 dummy,errFlag] = linkFeaturesKalmanSparse(movieInfo,costMatrices(1).funcName,...
        %                 costMatrices(1).parameters,kalmanFunctions,probDim,...
        %                 kalmanInfoLink,linkingCosts,verbose);
        %             clear dummy
        % 
        %         else %if not self-adaptive, link in one round only
        % 
        %             %get initial track segments by linking features between consecutive frames
        %             if verbose
        %                 disp('Linking features ...');
        %             end
        %             [tracksFeatIndxLink,tracksCoordAmpLink,dummy,nnDistLinkedFeat,...
        %                 dummy,errFlag] = linkFeaturesKalmanSparse(movieInfo,costMatrices(1).funcName,...
        %                 costMatrices(1).parameters,kalmanFunctions,probDim,[],[],verbose);
        %             kalmanInfoLink = [];
        %             clear dummy
        % 
        %         end
        % 
        %         %% post-processing of linking results
        % 
        % 
        %         %this function now breaks up frame-to-frame linked tracks if they do not
        %         %follow a linear trajectory.  it only runs with the EB3 cost matrix
        %         if isequal(costMatrices(2).funcName,'plusTipCostMatCloseGaps')
        %             tracksCoordAmpLink = full(tracksCoordAmpLink);
        %             tracksCoordAmpLink(tracksCoordAmpLink==0) = NaN;
        %             tracksCoordAmpLink(:,3:8:end) = 0;
        %             tracksCoordAmpLink(:,7:8:end) = 0;
        %             [tracksCoordAmpLink,tracksFeatIndxLink,nnDistLinkedFeat]=...
        %                plusTipBreakNonlinearTracks(tracksCoordAmpLink,tracksFeatIndxLink,nnDistLinkedFeat);
        %         end
        % 
        % 
        % 
        %         %get track start times, end times amd lifetimes
        %         trackSEL = getTrackSEL(tracksCoordAmpLink);
        % 
        %         %remove tracks whose length is less than minTrackLen
        %         indxKeep = find(trackSEL(:,3) >= minTrackLen);
        %         trackSEL = trackSEL(indxKeep,:);
        %         tracksFeatIndxLink = tracksFeatIndxLink(indxKeep,:);
        %         tracksCoordAmpLink = tracksCoordAmpLink(indxKeep,:);
        %         nnDistLinkedFeat = nnDistLinkedFeat(indxKeep,:);
        % 
        %         %calculate the new nearest-neighbor distance of each feature in each frame
        %         for iFrame = 1 : numFramesEff
        % 
        %             %get the coordinates of features in this frame
        %             coordFrame = tracksCoordAmpLink(:,(iFrame-1)*8+1:(iFrame-1)*8+3);
        %             if issparse(coordFrame)
        %                 coordFrame = full(coordFrame(:,1:probDim));
        %                 coordFrame(coordFrame==0) = NaN;
        %             end
        %             coordFrame = coordFrame(~isnan(coordFrame(:,1)),:);
        % 
        %             if ~isempty(coordFrame)
        % 
        %                 %compute distance matrix
        %                 nnDist = createDistanceMatrix(coordFrame,coordFrame);
        % 
        %                 %if there happens to be only one feature in this frame, give it a very
        %                 %large nearest neighbor distance
        %                 if length(nnDist(:)) == 1
        % 
        %                     nnDist = 1000;
        % 
        %                 else %if there is more than 1 feature
        % 
        %                     %sort distance matrix and find nearest neighbor distance
        %                     nnDist = sort(nnDist,2);
        %                     nnDist = nnDist(:,2);
        % 
        %                 end
        % 
        %                 %store nearest neighbor distances in matrix
        %                 nnDistLinkedFeat(~isnan(nnDistLinkedFeat(:,iFrame)),iFrame) = nnDist;
        % 
        %             end
        % 
        %         end
        % 
        %         %save track start and end times
        %         trackStartTime = trackSEL(:,1);
        %         trackEndTime   = trackSEL(:,2);
        %         clear trackSEL
        % 
        %         %get number of tracks
        %         numTracksLink = size(tracksFeatIndxLink,1);
        % 
        %         %% Close gaps with merging/splitting
        % 
        %         %if there are gaps to close (i.e. if there are tracks that start after the
        %         %first frame and tracks that end before the last frame) ...
        %         if any(trackStartTime > 1) || any(trackEndTime < numFramesEff)
        % 
        %             if verbose
        %                 disp(sprintf('Closing gaps (%d starts and %d ends) ...',...
        %                     length(find(trackStartTime>1)),length(find(trackEndTime<numFramesEff))));
        %             end
        % 
        %             %initialize progress display
        %             if verbose
        %                 progressText(0,'Gap closing');
        %             end
        % 
        %             %calculate the cost matrix, which already includes the
        %             %costs of birth and death
        %             % -- USER DEFINED FUNCTION -- %
        %             eval(['[costMat,nonlinkMarker,indxMerge,numMerge,indxSplit,numSplit,'...
        %                 'errFlag] = ' costMatrices(2).funcName '(tracksCoordAmpLink,'...
        %                 'tracksFeatIndxLink,trackStartTime,trackEndTime,costMatrices(2).parameters,'...
        %                 'gapCloseParam,kalmanInfoLink,nnDistLinkedFeat,probDim,movieInfo);'])
        % 
        %             %if there are possible links ...
        %             if any(isfinite(nonzeros(costMat)))
        % 
        %                 % % %         %for paper - get number of potential links per track
        %                 % % %         numPotLinksPerTrack = full([sum(costMat(1:numTracksLink,1:numTracksLink+numMerge)...
        %                 % % %             ~=0,2); sum(costMat(1:numTracksLink+numSplit,1:numTracksLink)...
        %                 % % %             ~=0,1)']);
        % 
        %                 %link tracks based on this cost matrix, allowing for birth and death
        %                 [link12,link21] = lap(costMat,nonlinkMarker);
        %                 link12 = double(link12);
        %                 link21 = double(link21);
        % 
        %                 %put the indices of all tracks from linking in one vector
        %                 tracks2Link = (1:numTracksLink)';
        %                 tracksRemaining = tracks2Link;
        % 
        %                 %reserve memory space for matrix showing track connectivity
        %                 compoundTrack = zeros(numTracksLink,600);
        % 
        %                 %initialize compTrackIndx
        %                 compTrackIndx = 0;
        % 
        %                 while ~isempty(tracksRemaining)
        % 
        %                     %update compound track index by 1
        %                     compTrackIndx = compTrackIndx + 1;
        % 
        %                     % take first track as a seed to build a compound track with
        %                     % closed gaps and merges/splitsDISP(SPRINTF(...)) can usually be replaced by FPRINTF(...\n) .Details % Fix
        % 
        %                     trackSeed = tracksRemaining(1);
        %                     seedLength = 1;
        %                     seedLengthOld = 0; %dummy just to get into the while loop
        % 
        %                     %while current seed contains more tracks than previous seed, i.e.
        %                     %whie new track segments are still being added to the compound
        %                     %track
        %                     while seedLength > seedLengthOld
        % 
        %                         %store current seed for later comparison
        %                         seedLengthOld = seedLength;
        % 
        %                         %find tracks connected to ends of seed tracks
        %                         tmpTracks = link12(trackSeed);
        %                         trackLink2End = tmpTracks(tmpTracks <= numTracksLink); %starts linked to ends
        %                         trackMerge = [];
        %                         if mergeSplit
        %                             trackMerge = indxMerge(tmpTracks(tmpTracks > numTracksLink & ...
        %                             tmpTracks <= numTracksLink+numMerge) - numTracksLink); % tracks that ends merge with
        %                         end
        % 
        %                         %find tracks connected to starts of seed tracks
        %                         tmpTracks = link21(trackSeed);
        %                         trackLink2Start = tmpTracks(tmpTracks <= numTracksLink); %ends linked to starts
        %                         trackSplit = [];
        %                         if mergeSplit
        %                             trackSplit = indxSplit(tmpTracks(tmpTracks > numTracksLink & ...
        %                             tmpTracks <= numTracksLink+numSplit) - numTracksLink); %tracks that starts split from
        %                         end
        % 
        %                         %put all tracks together as the new seed
        %                         trackSeed = [trackSeed; trackLink2End; trackLink2Start; trackMerge; trackSplit];
        % 
        %                         %remove repetitions and arrange tracks in ascending order
        %                         trackSeed = unique(trackSeed);
        % 
        %                         %get number of tracks in new seed
        %                         seedLength = length(trackSeed);
        % 
        %                         %expand new seed if merging/splitting are allowed
        %                         if mergeSplit
        % 
        %                             %variables storing merge/split seed tracks
        %                             mergeSeed = [];
        %                             splitSeed = [];
        % 
        %                             %go over all seed tracks
        %                             for iSeed = 1 : seedLength
        % 
        %                                 %get the location(s) of this track in indxMerge
        %                                 mergeSeed = [mergeSeed; find(indxMerge == trackSeed(iSeed))];
        % 
        %                                 %get the location(s) of this track in indxSplit
        %                                 splitSeed = [splitSeed; find(indxSplit == trackSeed(iSeed))];
        % 
        %                             end
        % 
        %                             %add numTracksLink to mergeSeed and splitSeed to determine
        %                             %their location in the cost matrix
        %                             mergeSeed = mergeSeed + numTracksLink;
        %                             splitSeed = splitSeed + numTracksLink;
        % 
        %                             %find tracks merging with seed tracks
        %                             trackMerge = [];
        %                             for iSeed = 1 : length(mergeSeed)
        %                                 trackMerge = [trackMerge; find(link12(1:numTracksLink)==mergeSeed(iSeed))];
        %                             end
        % 
        %                             %find tracks splitting from seed tracks
        %                             trackSplit = [];
        %                             for iSeed = 1 : length(splitSeed)
        %                                 trackSplit = [trackSplit; find(link21(1:numTracksLink)==splitSeed(iSeed))];
        %                             end
        % 
        %                             %add these track to the seed
        %                             trackSeed = [trackSeed; trackMerge; trackSplit];
        % 
        %                             %remove repetitions and arrange tracks in ascending order
        %                             trackSeed = unique(trackSeed);
        % 
        %                             %get number of tracks in new seed
        %                             seedLength = length(trackSeed);
        % 
        %                         end %(if mergeSplit)
        % 
        %                     end %(while length(trackSeed) > length(trackSeedOld))
        % 
        %                     %expand trackSeed to reserve memory for connetivity information
        %                     trackSeedConnect = [trackSeed zeros(seedLength,2)];
        % 
        %                     %store the tracks that the ends of the seed tracks are linked to,
        %                     %and indicate whether it's an end-to-start link (+ve) or a merge (-ve)
        %                     tmpTracks = link12(trackSeed);
        %                     if mergeSplit
        %                         tmpTracks(tmpTracks > numTracksLink & tmpTracks <= ...
        %                             numTracksLink+numMerge) = -indxMerge(tmpTracks(tmpTracks > ...
        %                             numTracksLink & tmpTracks <= numTracksLink+numMerge) - numTracksLink);
        %                     end
        %                     tmpTracks(tmpTracks > numTracksLink) = NaN;
        %                     trackSeedConnect(:,2) = tmpTracks;
        % 
        %                     %store the tracks that the starts of the seed tracks are linked to,
        %                     %and indicate whether it's a start-to-end link (+ve) or a split (-ve)
        %                     tmpTracks = link21(trackSeed);
        %                     if mergeSplit
        %                         tmpTracks(tmpTracks > numTracksLink & tmpTracks <= ...
        %                             numTracksLink+numSplit) = -indxSplit(tmpTracks(tmpTracks > ...
        %                             numTracksLink & tmpTracks <= numTracksLink+numSplit) - numTracksLink);
        %                     end
        %                     tmpTracks(tmpTracks > numTracksLink) = NaN;
        %                     trackSeedConnect(:,3) = tmpTracks;
        % 
        %                     %store tracks making up this compound track and their connectivity
        %                     compoundTrack(compTrackIndx,1:3*seedLength) = reshape(...
        %                         trackSeedConnect,3*seedLength,1)';
        % 
        %                     %in the list of all tracks, indicate that these tracks have
        %                     %been taken care of by placing NaN instead of their number
        %                     tracks2Link(trackSeed) = NaN;
        % 
        %                     %retain only tracks that have not been linked to anything yet
        %                     tracksRemaining = tracks2Link(~isnan(tracks2Link));
        % 
        %                 end %(while ~isempty(tracksRemaining))
        % 
        %                 %remove empty rows
        %                 maxValue = max(compoundTrack,[],2);
        %                 compoundTrack = compoundTrack(maxValue > 0,:);
        % 
        %                 %determine number of tracks after gap closing (including merge/split if
        %                 %specified)
        %                 numTracksCG = size(compoundTrack,1);
        % 
        %                 %reserve memory for structure storing tracks after gap closing
        %                 tracksFinal = repmat(struct('tracksFeatIndxCG',[],...
        %                     'tracksCoordAmpCG',[],'seqOfEvents',[]),numTracksCG,1);
        % 
        %                 %go over all compound tracks
        %                 for iTrack = 1 : numTracksCG
        % 
        %                     %get indices of tracks from linking making up current compound track
        %                     %determine their number and connectivity
        %                     trackSeedConnect = compoundTrack(iTrack,:)';
        %                     trackSeedConnect = trackSeedConnect(trackSeedConnect ~= 0);
        %                     seedLength = length(trackSeedConnect)/3; %number of segments making current track
        %                     trackSeedConnect = reshape(trackSeedConnect,seedLength,3);
        % 
        %                     %get their start times
        %                     segmentStartTime = trackStartTime(trackSeedConnect(:,1));
        % 
        %                     %arrange segments in ascending order of their start times
        %                     [segmentStartTime,indxOrder] = sort(segmentStartTime);
        %                     trackSeedConnect = trackSeedConnect(indxOrder,:);
        % 
        %                     %get the segments' end times
        %                     segmentEndTime = trackEndTime(trackSeedConnect(:,1));
        % 
        %                     %calculate the segments' positions in the matrix of coordinates and
        %                     %amplitudes
        %                     segmentStartTime8 = 8 * (segmentStartTime - 1) + 1;
        %                     segmentEndTime8   = 8 * segmentEndTime;
        % 
        %                     %instead of having the connectivity in terms of the original track
        %                     %indices, have it in terms of the indices of this subset of tracks
        %                     %(which are arranged in ascending order of their start times)
        %                     for iSeed = 1 : seedLength
        %                         value = trackSeedConnect(iSeed,2);
        %                         if value > 0
        %                             trackSeedConnect(iSeed,2) = find(trackSeedConnect(:,1) == ...
        %                                 value);
        %                         elseif value < 0
        %                             trackSeedConnect(iSeed,2) = -find(trackSeedConnect(:,1) == ...
        %                                 -value);
        %                         end
        %                         value = trackSeedConnect(iSeed,3);
        %                         if value > 0
        %                             trackSeedConnect(iSeed,3) = find(trackSeedConnect(:,1) == ...
        %                                 value);
        %                         elseif value < 0
        %                             trackSeedConnect(iSeed,3) = -find(trackSeedConnect(:,1) == ...
        %                                 -value);
        %                         end
        %                     end
        % 
        %                     %get track information from the matrices storing linking information
        %                     tracksFeatIndxCG = tracksFeatIndxLink(trackSeedConnect(:,1),:);
        %                     tracksCoordAmpCG = tracksCoordAmpLink(trackSeedConnect(:,1),:);
        % 
        %                     %convert zeros to NaNs where approriate for the case of sparse
        %                     %matrices
        %                     if issparse(tracksCoordAmpCG)
        % 
        %                         %convert sparse to full
        %                         tracksCoordAmpCG = full(tracksCoordAmpCG);
        % 
        %                         %go over all the rows in this compound track
        %                         for iRow = 1 : size(tracksCoordAmpCG,1)
        % 
        %                             %find all the zero entries
        %                             colZero = find(tracksCoordAmpCG(iRow,:)==0);
        %                             colZero = colZero(:)';
        % 
        %                             %find the columns of the x-coordinates corresponding to
        %                             %the zero columns
        %                             xCoordCol = colZero - mod(colZero-1,8*ones(size(colZero)));
        % 
        %                             %keep only the columns whose x-coordinate is zero as
        %                             %well
        %                             colZero = colZero(tracksCoordAmpCG(iRow,xCoordCol)==0);
        % 
        %                             %replace zero with NaN in the surviving columns
        %                             tracksCoordAmpCG(iRow,colZero) = NaN;
        % 
        %                         end
        % 
        %                     end
        % 
        %                     %perform all gap closing links and modify connectivity accordingly
        %                     %go over all starts in reverse order
        %                     for iSeed = seedLength : -1 : 2
        % 
        %                         %find the track this track might be connected to
        %                         track2Append = trackSeedConnect(iSeed,3);
        % 
        %                         %if there is a track (which is not a split)
        %                         if track2Append > 0
        % 
        %                             %put track information in the relevant row
        %                             tracksFeatIndxCG(track2Append,segmentStartTime(iSeed):...
        %                                 segmentEndTime(iSeed)) = tracksFeatIndxCG(iSeed,...
        %                                 segmentStartTime(iSeed):segmentEndTime(iSeed));
        %                             tracksFeatIndxCG(iSeed,:) = 0;
        %                             tracksCoordAmpCG(track2Append,segmentStartTime8(iSeed):...
        %                                 segmentEndTime8(iSeed)) = tracksCoordAmpCG(iSeed,...
        %                                 segmentStartTime8(iSeed):segmentEndTime8(iSeed));
        %                             tracksCoordAmpCG(iSeed,:) = NaN;
        % 
        %                             %update segment information
        %                             segmentEndTime(track2Append) = segmentEndTime(iSeed);
        %                             segmentEndTime8(track2Append) = segmentEndTime8(iSeed);
        %                             segmentEndTime(iSeed) = NaN;
        %                             segmentEndTime8(iSeed) = NaN;
        %                             segmentStartTime(iSeed) = NaN;
        %                             segmentStartTime8(iSeed) = NaN;
        % 
        %                             %update connectivity
        %                             trackSeedConnect(track2Append,2) = trackSeedConnect(iSeed,2);
        %                             trackSeedConnect(trackSeedConnect(:,2) == iSeed,2) = track2Append;
        %                             trackSeedConnect(trackSeedConnect(:,3) == iSeed,3) = track2Append;
        %                             trackSeedConnect(trackSeedConnect(:,2) == -iSeed,2) = -track2Append;
        %                             trackSeedConnect(trackSeedConnect(:,3) == -iSeed,3) = -track2Append;
        % 
        %                         end %(if track2Append > 0)
        % 
        %                     end %(for iSeed = seedLength : -1 : 2)
        % 
        %                     %find rows that are not empty
        %                     maxValue = max(tracksFeatIndxCG,[],2);
        %                     rowsNotEmpty = find(maxValue > 0);
        % 
        %                     %remove empty rows
        %                     tracksFeatIndxCG = tracksFeatIndxCG(rowsNotEmpty,:);
        %                     tracksCoordAmpCG = tracksCoordAmpCG(rowsNotEmpty,:);
        %                     segmentEndTime   = segmentEndTime(rowsNotEmpty);
        %                     segmentStartTime = segmentStartTime(rowsNotEmpty);
        %                     trackSeedConnect = trackSeedConnect(rowsNotEmpty,:);
        % 
        %                     %update connectivity accordingly
        %                     %by now, only merges and splits are left - thus no need for minus
        %                     %sign to distinguish them from closed gaps
        %                     for iSeed = 1 : length(rowsNotEmpty)
        %                         trackSeedConnect(trackSeedConnect(:,2) == -rowsNotEmpty(...
        %                             iSeed),2) = iSeed;
        %                         trackSeedConnect(trackSeedConnect(:,3) == -rowsNotEmpty(...
        %                             iSeed),3) = iSeed;
        %                     end
        % 
        %                     %determine new "seedLength"
        %                     seedLength = length(rowsNotEmpty);
        % 
        %                     %store the sequence of events of this track
        %                     seqOfEvents = [segmentStartTime ones(seedLength,1) ...
        %                         (1:seedLength)' trackSeedConnect(:,3); ...
        %                         segmentEndTime 2*ones(seedLength,1) ...
        %                         (1:seedLength)' trackSeedConnect(:,2)];
        % 
        %                     %sort sequence of events in ascending order of time
        %                     [tmp,indxOrder] = sort(seqOfEvents(:,1));
        %                     seqOfEvents = seqOfEvents(indxOrder,:);
        % 
        %                     %add 1 to the times of merges
        %                     indx = find(~isnan(seqOfEvents(:,4)) & seqOfEvents(:,2) == 2);
        %                     seqOfEvents(indx,1) = seqOfEvents(indx,1) + 1;
        % 
        %                     %find the frame where the compound track starts and the frames
        %                     %where it ends
        %                     frameStart = seqOfEvents(1,1);
        %                     frameEnd   = seqOfEvents(end,1);
        % 
        %                     %store final tracks, removing frames before anything happens and
        %                     %after everything happens
        %                     tracksFinal(iTrack).tracksFeatIndxCG = tracksFeatIndxCG(:,...
        %                         frameStart:frameEnd);
        %                     tracksFinal(iTrack).tracksCoordAmpCG = tracksCoordAmpCG(:,...
        %                         8*(frameStart-1)+1:8*frameEnd);
        %                     tracksFinal(iTrack).seqOfEvents = seqOfEvents;
        % 
        %                 end %(for iTrack = 1 : numTracksCG)
        % 
        %             else %if there are no possible links
        % 
        %                 if verbose
        %                     disp('No gaps to close!');
        %                 end
        % 
        %                 %convert matrix of tracks into structure
        %                 tracksFinal = convertMat2Struct( tracksCoordAmpLink, tracksFeatIndxLink);
        % 
        %             end %(if any(~isfinite(nonzeros(costMat))))
        % 
        %             %display elapsed time
        %             if verbose
        %                 progressText(1,'Gap closing');
        %             end
        % 
        %         else %if there are no gaps to close
        % 
        %             if verbose
        %                 disp('No gaps to close!');
        %             end
        % 
        %             %convert matrix of tracks into structure
        %             tracksFinal = convertMat2Struct(app,tracksCoordAmpLink,tracksFeatIndxLink);
        % 
        %         end %(if any(trackStartTime > 1) && any(trackEndTime < numFramesEff)
        % 
        %         %shift time if any of the initial frames are empty
        %         for iTrack = 1 : length(tracksFinal)
        %             tracksFinal(iTrack).seqOfEvents(:,1) = tracksFinal(iTrack).seqOfEvents(:,1) + emptyStart;
        %         end
        % 
        %         %replicate if only subset of movieInfo is used (e.g. by,
        %         %movieInfo=movieInfo(1:repFrame:end);) - added by Sangyoon Han 2/25/2016
        %         if repFrame>1
        %             for iTrack = 1 : length(tracksFinal)
        %                 containsFinal=false;
        %                 tracksFinal(iTrack).seqOfEvents(1,1) = (tracksFinal(iTrack).seqOfEvents(1,1)-1)*repFrame+1;
        %                 if  tracksFinal(iTrack).seqOfEvents(2,1)==numFramesEff
        %                     containsFinal=true;
        %                     leftOverFrames = (tracksFinal(iTrack).seqOfEvents(2,1))*repFrame-numFrames;
        %                     tracksFinal(iTrack).seqOfEvents(2,1) = min((tracksFinal(iTrack).seqOfEvents(2,1))*repFrame,numFrames);
        %                 else
        %                     leftOverFrames=0;
        %                     tracksFinal(iTrack).seqOfEvents(2,1) = (tracksFinal(iTrack).seqOfEvents(2,1))*repFrame;
        %                 end
        %                 curTracksFeatIndxCG = kron(tracksFinal(iTrack).tracksFeatIndxCG,ones(1,repFrame));
        %                 if leftOverFrames>1
        %                     curTracksFeatIndxCG(end-leftOverFrames+1:end)=[];
        %                 end
        %                 tracksFinal(iTrack).tracksFeatIndxCG = curTracksFeatIndxCG;
        % 
        %                 tempCoordAmpCG = zeros(1,(tracksFinal(iTrack).seqOfEvents(2,1)-tracksFinal(iTrack).seqOfEvents(1,1)+1)*8);
        %                 for pp=1:length( tracksFinal(iTrack).tracksCoordAmpCG)/8
        %                     curSourceSegment = ((pp-1)*8+1):pp*8;
        %                     curOutputSegment = ((pp-1)*repFrame*8+1):pp*repFrame*8;
        %                     %finalFrame=tracksFinal(iTrack).seqOfEvents(2,1)*8;
        %                     curTracksCoordAmpCG = repmat( tracksFinal(iTrack).tracksCoordAmpCG(curSourceSegment),1,repFrame);
        %                     if containsFinal && pp==length( tracksFinal(iTrack).tracksCoordAmpCG)/8
        %                         curOutputSegment = ((pp-1)*repFrame*8+1):(pp*repFrame*8-leftOverFrames*8);
        %                         curTracksCoordAmpCG = repmat( tracksFinal(iTrack).tracksCoordAmpCG(curSourceSegment),1,repFrame-leftOverFrames);
        %                     end
        %                     tempCoordAmpCG(curOutputSegment)=curTracksCoordAmpCG;
        %                 end
        %                 tracksFinal(iTrack).tracksCoordAmpCG=tempCoordAmpCG;
        %             end
        %         end
        %         %% Save results
        % 
        %         if isstruct(saveResults)
        %             save([saveResDir filesep saveResFile],'costMatrices','gapCloseParam',...
        %                 'kalmanFunctions','tracksFinal','kalmanInfoLink');
        %         end
        % 
        %         %% Gap closing diagnostics
        % 
        %         %check whether to perform diagnostics
        %         if isfield(gapCloseParam,'diagnostics')
        %             diagnostics = gapCloseParam.diagnostics;
        %         else
        %             diagnostics = 0;
        %         end
        % 
        %         %if diagnostics are requested
        %         if ~isempty(diagnostics) && diagnostics == 1
        % 
        %             %extract gap information from tracks
        %             gapInfo = findTrackGaps(tracksFinal);
        % 
        %             %plot the histogram of gap lengths if there are gaps
        %             if ~isempty(gapInfo)
        %                 figure('Name','Gap length histogram','NumberTitle','off');
        %                 hist(gapInfo(:,4),(1:max(gapInfo(:,4))));
        %                 xlabel('Gap length');
        %                 ylabel('Counts');
        %             else
        %                 disp('No gaps to plot');
        %             end
        % 
        %         end
        % end
    end


%-----------------------------------------------------------------------------------------------------------------------------------------------------
        properties (Access = private)
            
            DataFormat = 'Nikon'; 
            ReadDirMain = '';
            PixelSize = [];
            TimeStep  = [];
            SkipND2toTifConversion = false;
            GaussianSigma = 5;
            NuclearChannel = 1;
            WFChannel = 2;
            MinTrackHourLength = 3;
            AlphaThreshold = 1.0;
            %nMSDPtsForFit = 24;
            PercentThreshold = 2;
            AnalysisFilesDirectory = [];
            AnalysisFolderOriginalOrderAssignment = [];
            %MetamorphData = [];
            Sort_ndFormat_handle = [];            
       end
    
    properties (Access = public)
        
            MetamorphData = [];
            DefaultSigma = 6;
            DefaultPixelSize = 0.65;
            RosePlotColors = [];
            
    end
%-----------------------------------------------------------------------------------------------------------------------------------------------------


    methods (Access = public)
        
%         function results = func27(app)
%             % Use this function for applying output of Sort_ndFormat
%         end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function autocell_OpeningFcn(app)
%             
                G = groot;
                %app.figure1.Position = [100, (G.ScreenSize(1,4)-200-810), 535, 810];
                app.Metamorph_nd_format_button.Enable = "off";

                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'off';
                app.PixelSizeInput.Value = [];
                app.TimeStepInput.Value = [];
                app.CellCategoryDisplay.Enable = 'off';
                app.UpButton.Enable = 'off';
                app.DownButton.Enable = 'off';
                app.CreateAnalysisPlots_Button.Enable = 'off';
                app.ReadDirectoryDisplay.Value = '';
                app.CellposePanel.Enable = "on";
                app.AssignColorsButton.Enable = "off";

                app.UpButton.Text = char(hex2dec('25B2'));
                app.UpButton.FontColor = [0,0,0.7];
                app.UpButton.FontSize = 18;

                app.DownButton.Text =  char(hex2dec('25BC'));
                app.DownButton.FontColor = [0,0,0.7];  
                app.DownButton.FontSize = 18;

        
                % Setup initial data format ----------------------------------------------------------
                set(app.Nikon_nd2_format_button,      'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal'  );
                set(app.Nikon_CP_nd2_format_button,   'FontColor',[0.29,1.00,0.29],'BackgroundColor',[0.00,0.37,0.59],'FontWeight','bold'  );
                set(app.Metamorph_nd_format_button,   'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal');
                app.DataFormatDisplay.Text = 'Nikon format: phase-contrast imaging for Cellpose (.nd2)';
                app.uipanel2.Title = 'STEP 1: Sort nd2 files into sub-folders, then select the main folder';
                app.SelectReadFolderButton.Text = {'Select';'Main';'Folder'};
                app.DataFormat = 'Nikon-CP';
                %-------------------------------------------------------------------------------------
      
                app.AutoAdjustGaussianSigma.Value = true;
                app.GaussianFilterSizeInput.Value = app.GaussianSigma;
                app.GaussianFilterSizeInput.Enable = 'off';
                app.text7.Enable = 'off';
                app.text8.Enable = 'off';
                app.PercentThresholdInput.Enable   = "off";

                app.ImageStatsDisplay.Text = '';
            
                app.CellCategoryDisplay.Items = cell(0,1);
                app.CellCategoryDisplay.ItemsData = [];
            
                app.NuclearMarkerChannel.Items     = {'1';'2';'3';'4'};
                app.NuclearMarkerChannel.ItemsData = [1,2,3,4];                
                app.NuclearMarkerChannel.Value     = 2;
                app.NuclearMarkerChannel.Enable    = "off";
                app.WideFieldChannel.Items         = {'1';'2';'3';'4'};
                app.WideFieldChannel.ItemsData     = [1,2,3,4];
                app.WideFieldChannel.Value         = 1;
                           
                app.CellposeModelPulldown.Items  = {'cyto';'cyto2';'cyto3';'CP';'CPx';...
                                                    'nuclei';'livecell';'LC1';'LC2';'LC3';'LC4';...
                                                    'tissuenet';'TN1';'TN2';'TN3'};
                app.CellposeModelPulldown.ItemsData  = 1:length(app.CellposeModelPulldown.Items); 
                app.CellposeModelPulldown.Value = 3;

                app.nPts_MSDFit.Value = true;
                app.nMSDPts.Value = '10';

                app.CropTimePtsCheck.Value = 0;
                app.StText.Enable = 'off';
                app.StTimePtInput.Enable = 'off';
                app.StTimePtInput.Value = '1';
                app.EndText.Enable = 'off';
                app.EndTimePtInput.Enable = 'off';
                app.EndTimePtInput.Value = '50';

        end

        % Value changed function: AutoAdjustGaussianSigma
        function AutoAdjustGaussianSigma_Callback(app, event)
            
              
                dp = app.PixelSizeInput.Value;
                if ~isempty(dp)
                    switch app.AutoAdjustGaussianSigma.Value
                        case 1
                            SGFS = SuggestedGaussianFilterSize(app);
                            app.GaussianFilterSizeInput.Value = SGFS;
                            app.GaussianSigma = app.GaussianFilterSizeInput.Value;
                            app.text7.Enable = 'off';
                            app.text8.Enable = 'off';
                            app.GaussianFilterSizeInput.Enable = 'off';                        
                        case 0
                            app.GaussianSigma = app.DefaultSigma;
                            app.GaussianFilterSizeInput.Value = app.GaussianSigma;
                            app.text7.Enable = 'on';
                            app.text8.Enable = 'on';
                            app.GaussianFilterSizeInput.Enable = 'on';
                    end
                end


        end

        % Button pushed function: CreateAnalysisPlots_Button
        function CreateAnalysisPlots_Button_Callback(app, event)
            
                    AnalysisDir = app.AnalysisFilesDirectory;
                    [~, AnalysisFolders] = FilesAndFolders(app, AnalysisDir);
                    N = length(AnalysisFolders);
                    VALUES = [];
            
                    for k = 1:N
                        ReadDir{k,1} = fullfile(AnalysisDir,AnalysisFolders{k,1});
                    end
                   %-----------------------------------------------------------------------------------
                    for F = 1:length(ReadDir)
                            clear DATA
                            [Files,~] = FilesAndFolders(app, ReadDir{F,1});
                            ii = contains(Files,'MSD-ANALYSIS_');
                            Files = Files(ii);
                            nFiles = length(Files);
                            % Reset storage
                            % variables-----------------------------------------------------------------------------------------------------
                            meanV = [];  medV = []; P = []; msdV = []; msdP = []; dr = [];
                            ti = []; DR_concat = []; TI_concat = []; nT = []; MSDtracks = [];
                            %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                            for k = 1:nFiles
                                    load(fullfile(ReadDir{F,1}, Files{k,1}))
                                    if ~isempty(DATA)
                                        % Col#    1        2         3       4       5     6  7  8
                                        % DATA = [ii, Persistence, alpha, MeanVel, MedVel, S, P, nT]; where nT is the number of time points in the track
                                        %===============================================================================
                                        MinTimePts = str2double(app.MinTrackLengthInput.Value); %app.MinTrackLength;
                                        DATA(DATA(:,8) < MinTimePts, :) = []; % Eliminate rows with less than MinTimePts
                                        %===============================================================================
                                        meanV = [meanV; DATA(:, 4)];       % Concatentate all Mean Velocity measurements
                                        medV  = [medV;  DATA(:, 5)];       % Concatentate all Median Velocity measurements                                    
                                        
                                        idx4  = find( ~isnan(DATA(:,6)) ); % Find MSD cells data rows
                                        MSDV  = DATA(idx4, 6);
                                        P     = [P; DATA(idx4,2)];         % Concatentate all persistence measurements with an MSD velocity
                                        msdV  = [msdV; MSDV];              % Concatentate all MSD Velocity measurements
                                        MSDP  = DATA(~isnan(DATA(:,7)), 7);
                                        msdP  = [msdP; MSDP];              % Concatentate all MSD persistence measurements (This is the "persistence time" from Furth's Equation)
                
                                       for n = idx4' % only use the cells used in the MSD velocity calculation % 1:M
                                            dr = [ dr; {DR{n,1}.dr} ];
                                            ti = [ ti; {DR{n,1}.ti} ];
                                            if exist("tracksFinal",'var')
                                                X = smooth(tracksFinal(n,1).Xraw,3); % autocell does a 3 point smoothing on the raw data
                                                Y = smooth(tracksFinal(n,1).Yraw,3);   
                                            else
                                                X = smooth(TRACKS(n,1).Xraw,3); % autocell does a 3 point smoothing on the raw data
                                                Y = smooth(TRACKS(n,1).Yraw,3);  
                                            end
                                            MSDtracks = [MSDtracks; {[X,Y]}];
                                        end
                                    end
                            end
                            %|||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                            % Calculate MEAN Directionality Ratio -----------------
                                nT = cellfun(@length,dr);
                                nDR = length(dr);
                                dr2 = NaN(max(nT),nDR);
                                ti2 = dr2;
                
                                for n = 1:nDR
                                    dr2(1:nT(n,1),n) = dr{n,1};
                                    ti2(1:nT(n,1),n) = ti{n,1};
                                end
                
                                DR2 = mean(dr2,2,'omitnan');
                                TI2 = mean(ti2,2,'omitnan');
                                DR_sem = DR2./sqrt(sum(~isnan(dr2),2));
                            %------------------------------------------------------
                
                            VALUES{1,F}.MeanVelocities = meanV;
                            VALUES{1,F}.MedianVelocities = medV;
                            VALUES{1,F}.Persistence = P;
                            VALUES{1,F}.MSDVelocities = msdV;
                            VALUES{1,F}.MSDPersistence = msdP;
                            VALUES{1,F}.DR.dr = DR2;
                            VALUES{1,F}.DR.ti = TI2;
                            VALUES{1,F}.DR.sem = DR_sem;    
                            VALUES{1,F}.MSDtracks = MSDtracks;
                    end
            
                    Categories  = [];
                    MSDVel      = [];
                    MSDPer      = [];
                    MeanVel     = [];
                    MedVel      = [];
                    Persis      = [];
                    DirRatio    = [];
                    MSDtracks   = [];
            
                    for k = 1:N
                        Categories = [Categories;  AnalysisFolders(k,1)          ];
                        MSDVel     = [MSDVel;     {VALUES{1,k}.MSDVelocities}    ];
                        MSDPer     = [MSDPer;     {VALUES{1,k}.MSDPersistence}   ];
                        MeanVel    = [MeanVel;    {VALUES{1,k}.MeanVelocities}   ];
                        MedVel     = [MedVel;     {VALUES{1,k}.MedianVelocities} ];
                        Persis     = [Persis;     {VALUES{1,k}.Persistence}      ];
                        DirRatio   = [DirRatio;   {VALUES{1,k}.DR}               ];
                        MSDtracks  = [MSDtracks;  {VALUES{1,k}.MSDtracks}       ];
                    end
            
                    % reorder categories ----------------------
                        ReOrderVector = app.AnalysisFolderOriginalOrderAssignment.Order;
            
                        Categories = Categories(ReOrderVector);
                        MSDVel     = MSDVel(ReOrderVector);
                        MSDPer     = MSDPer(ReOrderVector);
                        MeanVel    = MeanVel(ReOrderVector);
                        MedVel     = MedVel(ReOrderVector);
                        Persis     = Persis(ReOrderVector);
                        DirRatio   = DirRatio(ReOrderVector);
                        MSDtracks  = MSDtracks(ReOrderVector);
                    % ------------------------------------------
            
                % START PLOTTING |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||    
                hfig = figure;
                set(hfig,'Color','w','Units','normalized')
                notch = true;                
                TT = tiledlayout(hfig,2,2,"TileSpacing","tight","Padding","compact");
                warning off
                %-------------------------------------------------------------------------------------------------------------
                nexttile(TT,1)
                        [MSDVel2,nCat,Ns] = CellArray2PaddedNanArray(app,MSDVel);
                        Categories2 = cellfun(@(x,y) [x,' [',num2str(y),']'] ,Categories, num2cell(Ns) ,'UniformOutput',false); % Append N to Category label
                        X1 = repmat(1:nCat,max(Ns),1); 
                        
                        swarmchart( X1, MSDVel2,'.k','SizeData',2,'XJitterWidth',0.65,'CData',0.4*ones(1,3) );
                        hold on
                        BP = boxplot(MSDVel2, 'Widths',0.65,'whisker',0,'Notch','on','Symbol','');
                        set(BP,'LineWidth',2);
                        set(gca,'XTickLabels',Categories2,'LineWidth',1)
                        xtickangle(gca,30)
                        ylabel('MSD Velocity (\mum/min)','FontSize',12)
                        xlim([0,nCat+1])
                        ylim([0 1.0]);  grid on
                        th = title(AnalysisDir,'Interpreter','none','FontSize',7,'Units','normalized','HorizontalAlignment','left');
                        set(th,'Position',[-0.1, th.Position(2)+0.02, 0])
                
                %-------------------------------------------------------------------------------------------------------------
                nexttile(TT,2)
                        [MeanVel2,nCat,Ns] = CellArray2PaddedNanArray(app,MeanVel);
                        Categories3 = cellfun(@(x,y) [x,' [',num2str(y),']'] ,Categories, num2cell(Ns) ,'UniformOutput',false); % Append N to Category label
                        X2 = repmat(1:nCat,max(Ns),1); 
                        swarmchart( X2, MeanVel2,'.k','SizeData',2,'XJitterWidth',0.65,'CData',0.4*ones(1,3) );
                        hold on
                        BP = boxplot(MeanVel2, 'Widths',0.65,'whisker',0,'Notch','on','Symbol','');
                        set(BP,'LineWidth',2);
                        set(gca,'XTickLabels',Categories3,'LineWidth',1)
                        xtickangle(gca,30)
                        ylabel('Mean Velocity (\mum/min)','FontSize',12)
                        xlim([0,nCat+1])
                        ylim([0 1.0]);  grid on
                
                %-------------------------------------------------------------------------------------------------------------
                nexttile(TT,3) % Change to Directionality
                        c = lines(length(DirRatio));
                        h(1,1) = plot(DirRatio{1,1}.ti, DirRatio{1,1}.dr,'LineWidth',2,'Color',c(1,:));
                    
                        for m = 2:length(DirRatio)
                            hold on;
                            h(m,1) = plot(DirRatio{m,1}.ti, DirRatio{m,1}.dr,'LineWidth',2,'Color',c(m,:));
                            hold off
                        end
                        set(gca,'LineWidth',1)
                        ylabel('Directionality Ratio','FontSize',12)
                        xlabel('Time Interval (min)','FontSize',12)
                        axis([0,700,0,1]); grid on
                    
                        for m = 1:length(DirRatio)
                            hold on;
                            plot(DirRatio{m,1}.ti, DirRatio{m,1}.dr + DirRatio{m,1}.sem, ':', 'LineWidth', 0.5, 'Color', c(m,:));
                            plot(DirRatio{m,1}.ti, DirRatio{m,1}.dr - DirRatio{m,1}.sem, ':', 'LineWidth', 0.5, 'Color', c(m,:));
                            hold off
                        end
                        LH = legend(h,Categories2,'FontSize',6,'NumColumns',1,'Location','northeast');
                        LH.Interpreter = 'none';
                
                %-------------------------------------------------------------------------------------------------------------
                nexttile(TT,4)
                        [Persis2,nCat,Ns] = CellArray2PaddedNanArray(app,Persis);
                        X4 = repmat(1:nCat,max(Ns),1); 
                        swarmchart( X4, Persis2,'.k','SizeData',2,'XJitterWidth',0.65,'CData',0.4*ones(1,3) );
                        hold on
                        BP = boxplot(Persis2, 'Widths',0.65,'whisker',0,'Notch','on','Symbol','');
                        set(BP,'LineWidth',2);
                        set(gca,'XTickLabels',Categories2,'LineWidth',1)
                        xtickangle(gca,30)
                        ylabel('Persistence','FontSize',12)
                        xlim([0,nCat+1])
                        ylim([0 1.0]);  grid on

                % END PLOTTING |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                    
                % Create rose plots if selected ------------------------------
                if app.RosePlotsCheckBox.Value
                    CreateRosePlots(app,Categories,MSDVel2,MSDtracks)
                end
            
                % Ask user if they want to compute and save stats ----------------------------------------------------------------------
                opts.Interpreter = 'tex';
                opts.Default = 'No';
                answer = questdlg('\fontsize{14} Calculate and save medians and p-values?','Save Stats?','Yes','No',opts);
                if strcmp(answer,'Yes')
                        %---------------------------------------------------------------------------------------
                        %    [VALUES, Categories, ~] = CreateAnalysisPlots(app.AnalysisFilesDirectory, false, [], []);
                        N = size(VALUES,2);
                        pairs =  nchoosek(1:N,2); %CreatePairCombinationsNoRepeatsNoSame(app,N);
        
                        m = pairs(:,1);
                        n = pairs(:,2);
                        K = length(m);
                        Cmsd = cell(K,4);
                        Cmean = cell(K,4);
                        Cpersis = cell(K,4);
                        M = cell(N,4);
        
                        % Calculate medians for all ditributions
                        for k = 1:N
                            M{k,1} = Categories{k,1};
                            M{k,2} = median(MSDVel{k,1});
                            M{k,3} = median(MeanVel{k,1});
                            M{k,4} = median(Persis{k,1});
                        end
        
                        % Calculate p-values for all combinations
                        % Start with MSD p-values
                        for k = 1:K
                            x1 = MSDVel{m(k),1};
                            x2 = MSDVel{n(k),1};
                            [h,p] = kstest2(x1,x2,'Alpha',0.05);
                            Cmsd{k,1} = Categories{m(k),1};
                            Cmsd{k,2} = Categories{n(k),1};
                            Cmsd{k,3} = h;
                            Cmsd{k,4} = p;
                        end
        
                        % Start with Mean p-values
                        for k = 1:K
                            x1 = MeanVel{m(k),1};
                            x2 = MeanVel{n(k),1};
                            [h,p] = kstest2(x1,x2,'Alpha',0.05);
                            Cmean{k,1} = Categories{m(k),1};
                            Cmean{k,2} = Categories{n(k),1};
                            Cmean{k,3} = h;
                            Cmean{k,4} = p;
                        end
        
                                        % Start with Mean p-values
                        for k = 1:K
                            x1 = Persis{m(k),1};
                            x2 = Persis{n(k),1};
                            [h,p] = kstest2(x1,x2,'Alpha',0.05);
                            Cpersis{k,1} = Categories{m(k),1};
                            Cpersis{k,2} = Categories{n(k),1};
                            Cpersis{k,3} = h;
                            Cpersis{k,4} = p;
                        end

                        CreateExcelFileWithStats(app,M,Cmsd,Cmean,Cpersis)            
                end

        end

        % Value changed function: CropTimePtsCheck
        function CropTimePtsCheck_Callback(app, event)
            
                switch app.CropTimePtsCheck.Value
                    case 0
                        app.StText.Enable = 'off';
                        app.StTimePtInput.Enable = 'off';
                        app.EndText.Enable = 'off';
                        app.EndTimePtInput.Enable = 'off';
                    case 1
                        app.StText.Enable = 'on';
                        app.StTimePtInput.Enable = 'on';
                        app.EndText.Enable = 'on';
                        app.EndTimePtInput.Enable = 'on';
                end
            
              
        end

        % Button pushed function: Metamorph_nd_format_button
        function Metamorph_nd_format_button_Callback(app, event)
            
            
                set(app.Metamorph_nd_format_button, 'FontColor',[0.29,1.00,0.29],'BackgroundColor',[0.00,0.37,0.59],'FontWeight','bold');
                set(app.Nikon_nd2_format_button,    'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal');
                set(app.Nikon_CP_nd2_format_button, 'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal');

                app.uipanel2.Title = 'STEP 1: Select data folder, and sort stage positions';
                app.uipanel1.Title = 'STEP 2: Cell Tracking';
                app.DataFormatDisplay.Text = 'Metamorph format: phase contrast with fluorescent nuclear marker (.nd)';
        
                app.SelectReadFolderButton.Text = {'Select';'and';'Sort'};
                app.DataFormat = 'Metamorph';
                app.CellposePanel.Enable = "off";
                app.WideFieldChannel.Value = 2;
                app.NuclearMarkerChannel.Value = 1;
                app.NuclearMarkerChannel.Enable    = "on";
                app.PercentThresholdInput.Enable   = "on";


        end

        % Value changed function: MinTrackLengthInput
        function MinTrackLengthInput_Callback(app, event)
            
                val = str2double(app.MinTrackLengthInput.Value);
            
                if ~isnan(val)
                    app.MinTrackLength = val;
                    app.MinTrackLengthInput.Value = num2str(val);
                    if val > app.nMSDPtsForFit
                        app.nMSDPts.Text  = num2str(val);
                        app.nMSDPtsForFit = val;
                    end
                else
                    app.MinTrackLengthInput.Text = num2str(app.MinTrackLength);
                end
             
        end

        % Button pushed function: DownButton
        function DownButton_Callback(app, event)
            
                Names = app.AnalysisFolderOriginalOrderAssignment.Names; % Names
                Order = app.AnalysisFolderOriginalOrderAssignment.Order; % Original Number Order
                n = app.CellCategoryDisplay.Value;
                m = length(Names);
        
                if ~isequal(m,n)
                    %--------------------------------------
                        Names(n:n+1,1) = Names([n+1;n],1);
                        Order(n:n+1,1) = Order([n+1;n],1);
                    %--------------------------------------
                    app.AnalysisFolderOriginalOrderAssignment.Names = Names; % Update Name Order
                    app.AnalysisFolderOriginalOrderAssignment.Order = Order; % Update Number Order
                    app.CellCategoryDisplay.Items = Names;
                    app.CellCategoryDisplay.Value = n+1;
                end
                  
        end

        % Button pushed function: UpButton
        function UpButton_Callback(app, event)
            
                Names = app.AnalysisFolderOriginalOrderAssignment.Names; % Names
                Order = app.AnalysisFolderOriginalOrderAssignment.Order; % Original Number Order
                n = app.CellCategoryDisplay.Value;
        
                if ~isequal(1,n)
                    %--------------------------------------
                        Names(n-1:n,1) = Names([n,n-1],1);
                        Order(n-1:n,1) = Order([n,n-1],1);
                    %--------------------------------------
                    app.AnalysisFolderOriginalOrderAssignment.Names = Names; % Update Name Order
                    app.AnalysisFolderOriginalOrderAssignment.Order = Order; % Update Number Order
                    app.CellCategoryDisplay.Items = Names;
                    app.CellCategoryDisplay.Value = n-1;
                end
                  
        end

        % Button pushed function: Nikon_nd2_format_button
        function Nikon_nd2_format_button_Callback(app, event)
            
                set(app.Nikon_nd2_format_button,   'FontColor',[0.29,1.00,0.29],'BackgroundColor',[0.00,0.37,0.59],'FontWeight','bold');
                set(app.Metamorph_nd_format_button,'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal');
                set(app.Nikon_CP_nd2_format_button,'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal');

                app.uipanel2.Title = 'STEP 1: Sort nd2 files into sub-folders, then select the main folder';
                set(app.uipanel1,'Title','STEP 2: Cell Tracking')
                app.DataFormatDisplay.Text = 'Nikon format: phase contrast with fluorescent nuclear marker (.nd2)';
                app.NuclearMarkerChannel.Enable    = "on";
                app.PercentThresholdInput.Enable   = "on";

                app.SelectReadFolderButton.Text = {'Select';'Main';'Folder'};
                app.DataFormat = 'Nikon';
                app.CellposePanel.Enable = "off";
                app.WideFieldChannel.Value = 2;
                app.NuclearMarkerChannel.Value = 1;
          
        end

        % Value changed function: NuclearMarkerChannel
        function NuclearMarkerChannel_Callback(app, event)
            
                app.NuclearChannel = app.NuclearMarkerChannel.Value;
            
                if isequal(app.NuclearChannel, app.WFChannel)
                    switch app.NuclearChannel
                        case 1
                            app.WFChannel = 2;
                            app.WideFieldChannel.Value = 2;
                        case 2
                            app.WFChannel = 1;
                            app.WideFieldChannel.Value = 1;
                        case 3
                            app.WFChannel = 2;
                            app.WideFieldChannel.Value = 2;
                    end
                end
             
        end

        % Value changed function: PercentThresholdInput
        function PercentThresholdInput_Callback(app, event)
            
                V = str2double(app.PercentThresholdInput.Value);
            
                if ~isnan(V)
                    if V < 50 && V > 0
                        app.PercentThreshold = V;
                        app.PercentThresholdInput.Value = num2str(V,'%0.1f');
                    else
                        app.PercentThresholdInput.Value = num2str(app.PercentThreshold,'%0.1f');
                    end
                else
                    app.PercentThresholdInput.Value = num2str(app.PercentThreshold,'%0.1f');
                end
               
        end

        % Button pushed function: SelectAnaylsisFolderButton
        function SelectAnaylsisFolderButton_Callback(app, event)
            
            
                [PathName] = uigetdir('','Select ANALYSIS folder');
    
                if ~isequal(0,PathName)
                    % Check to see if there is an ANALYSIS FOLDER ----------------------------------------
                    app.AnalysisFilesDirectory = fullfile(PathName);
                    [~, Folders] = FilesAndFolders(app, app.AnalysisFilesDirectory);
                    nF = length(Folders);
                    Groups = cell(0,1);
                    ind = 0;
                    for n = 1:nF
                        [Files, ~] = FilesAndFolders(app, fullfile(app.AnalysisFilesDirectory, Folders{n,1}) );
                        ii = contains(Files,'MSD-ANALYSIS_');
                        if any(ii)
                            ind = ind+1;
                            Groups{ind,1} = Folders{n,1};
                        end
                    end

                    nG = size(Groups,1);
                    app.RosePlotColors = repmat( [0, 0.4471, 0.7412],nG,1);

                    app.CellCategoryDisplay.Items = Groups;
                    app.CellCategoryDisplay.ItemsData = (1:nG)';
                    app.AnalysisFolderOriginalOrderAssignment.Names = Groups;
                    app.AnalysisFolderOriginalOrderAssignment.Order = (1:nG)';
                    app.CellCategoryDisplay.Enable = 'on';
                    app.UpButton.Enable = 'on';
                    app.DownButton.Enable = 'on';
                    app.CreateAnalysisPlots_Button.Enable = 'on';
                    app.AssignColorsButton.Enable = "on";
                else
                    app.CellCategoryDisplay.Items = cell(0,1);
                    app.CellCategoryDisplay.ItemsData = [];
                    app.CellCategoryDisplay.Enable = 'off';
                    app.UpButton.Enable = 'off';
                    app.DownButton.Enable = 'off';
                    app.CreateAnalysisPlots_Button.Enable = 'off';
                    app.AssignColorsButton.Enable = "off";
                end
    
                       
        end

        % Button pushed function: SelectReadFolderButton
        function SelectReadFolderButton_Callback(app, event)
            
                switch app.DataFormat
                    case 'Nikon' %=============================================================================================
                            [PathName] = uigetdir('','Select main folder');
                            if ~isequal(0,PathName)
                                    app.ReadDirMain = fullfile(PathName);
                                    % Check to see if there is an ANALYSIS FOLDER ----------------------------------------
                                    [~, Folders1] = FilesAndFolders(app, app.ReadDirMain);
                                    ind = contains(Folders1,"ANALYSIS");
                                    if any(ind)
                                        app.AnalysisFilesDirectory = fullfile(app.ReadDirMain,Folders1{ind});
                                        [~, Folders2] = FilesAndFolders(app, app.AnalysisFilesDirectory);
                                        app.CellCategoryDisplay.Items = Folders2;
                                        app.CellCategoryDisplay.ItemsData = (1:length(Folders2))';
                                        app.AnalysisFolderOriginalOrderAssignment.Names = Folders2;
                                        app.AnalysisFolderOriginalOrderAssignment.Order = (1:length(Folders2))';
                                        app.CellCategoryDisplay.Enable = 'on';
                                        app.UpButton.Enable = 'on';
                                        app.DownButton.Enable = 'on';
                                        app.CreateAnalysisPlots_Button.Enable = 'on';
                                        app.AssignColorsButton.Enable = "on";
                                    else
                                        app.CellCategoryDisplay.Items = cell(0,1);
                                        app.CellCategoryDisplay.ItemsData = [];
                                        app.CellCategoryDisplay.Enable = 'off';
                                        app.UpButton.Enable = 'off';
                                        app.DownButton.Enable = 'off';
                                        app.CreateAnalysisPlots_Button.Enable = 'off';
                                        app.AssignColorsButton.Enable = "off";
                                    end
                                    % ----------------------------------------------------------------------------------------
                                    
                                    ImageStats = GetNDfileStats(app,app.ReadDirMain);
                                    app.ImageStatsDisplay.Text = ImageStats;
                                    if ~isempty(ImageStats)
                                        app.ReadDirectoryDisplay.Value = app.ReadDirMain;
                                        app.StartButton.Enable = 'on';
                                        app.StopButton.Enable = 'off';
                                    else
                                        app.ReadDirectoryDisplay.Value = '';
                                        app.StartButton.Enable = 'off';
                                        app.StopButton.Enable = 'off';
                                    end
                            end
                        
                    case 'Nikon-CP' %=============================================================================================
                            [PathName] = uigetdir('','Select main folder');
                            if ~isequal(0,PathName)
                                    app.ReadDirMain = fullfile(PathName);
                                    % Check to see if there is an ANALYSIS FOLDER ----------------------------------------
                                    [~, Folders1] = FilesAndFolders(app, app.ReadDirMain);
                                    ind = contains(Folders1,"ANALYSIS");
                                    if any(ind)
                                        app.AnalysisFilesDirectory = fullfile(app.ReadDirMain,Folders1{ind});
                                        [~, Folders2] = FilesAndFolders(app, app.AnalysisFilesDirectory);
                                        app.CellCategoryDisplay.Items = Folders2;
                                        app.CellCategoryDisplay.ItemsData = (1:length(Folders2))';
                                        app.AnalysisFolderOriginalOrderAssignment.Names = Folders2;
                                        app.AnalysisFolderOriginalOrderAssignment.Order = (1:length(Folders2))';
                                        app.CellCategoryDisplay.Enable = 'on';
                                        app.UpButton.Enable = 'on';
                                        app.DownButton.Enable = 'on';
                                        app.CreateAnalysisPlots_Button.Enable = 'on';
                                    else
                                        app.CellCategoryDisplay.Items = cell(0,1);
                                        app.CellCategoryDisplay.ItemsData = [];
                                        app.CellCategoryDisplay.Enable = 'off';
                                        app.UpButton.Enable = 'off';
                                        app.DownButton.Enable = 'off';
                                        app.CreateAnalysisPlots_Button.Enable = 'off';
                                    end
                                    % ----------------------------------------------------------------------------------------                                    
                                    app.ReadDirectoryDisplay.Value = app.ReadDirMain;
                                    ImageStats = GetNDfileStats(app,app.ReadDirMain);
                                    app.ImageStatsDisplay.Text = ImageStats; 
                                    if ~isempty(ImageStats)
                                        app.ReadDirectoryDisplay.Value = app.ReadDirMain;
                                        app.StartButton.Enable = 'on';
                                        app.StopButton.Enable = 'off';
                                    else
                                        app.ReadDirectoryDisplay.Value = '';
                                        app.StartButton.Enable = 'off';
                                        app.StopButton.Enable = 'off';
                                    end
                            end
                                    
                    case 'Metamorph'  %=============================================================================================
                            [PathName] = uigetdir('','Select data folder');
                            if ~isequal(0,PathName)
                                    app.ReadDirMain = fullfile(PathName);
                                    %-----------------------------------------------------------------------------
                                    %[StageGroups, FileInfo] = Sort_ndFormat('ReadDir',app.ReadDirMain);
                                    app.Sort_ndFormat_handle = Sort_ndFormat(app, app.ReadDirMain);
                                    
                                    waitfor(app.Sort_ndFormat_handle)                                    
                                    
    %                                 app.MetamorphData.StageGroups = StageGroups;
    %                                 app.MetamorphData.FileInfo = FileInfo;
                                    %-----------------------------------------------------------------------------
                                    if ~isempty(app.MetamorphData)
                                            [~, Folders1] = FilesAndFolders(app, app.ReadDirMain);
                                            ind = contains(Folders1,"ANALYSIS");
                                            if any(ind)
                                                app.AnalysisFilesDirectory = fullfile(app.ReadDirMain,Folders1{ind(1)});
                                                [~, Folders2] = FilesAndFolders(app, app.AnalysisFilesDirectory);
                                                app.CellCategoryDisplay.Items = Folders2;
                                                app.CellCategoryDisplay.ItemsData = (1:length(Folders2))';
                                                app.AnalysisFolderOriginalOrderAssignment.Names = Folders2;
                                                app.AnalysisFolderOriginalOrderAssignment.Order = (1:length(Folders2))';
                                                app.CellCategoryDisplay.Enable = 'on';
                                                app.UpButton.Enable = 'on';
                                                app.DownButton.Enable = 'on';
                                                app.CreateAnalysisPlots_Button.Enable = 'on';
                                            else
                                                app.CellCategoryDisplay.Items = cell(0,1);
                                                app.CellCategoryDisplay.ItemsData = [];
                                                app.CellCategoryDisplay.Enable = 'off';
                                                app.UpButton.Enable = 'off';
                                                app.DownButton.Enable = 'off';
                                                app.CreateAnalysisPlots_Button.Enable = 'off';
                                            end
                                            % ----------------------------------------------------------------------------------------
                                            app.ReadDirMain = fullfile(PathName);
                                            app.ReadDirectoryDisplay.Value = app.ReadDirMain;
                                            app.StartButton.Enable = 'off';
                                            app.StopButton.Enable = 'off';
                                            app.PixelSizeInput.Value = [];
                                            app.TimeStepInput.Value = [];
                                    end
                            end
                end
                
                
        end

        % Button pushed function: StartButton
        function StartButton_Callback(app, event)
            
                    app.StopButton.Enable  = 'on';
                    app.StartButton.Enable = 'off';
                    %---------------------------------------------------------------------------------
                    ReadDir1 = app.ReadDirMain;
                        idx = strfind(ReadDir1,filesep);
                        CurrentFolderName = ReadDir1(idx(end)+1:end);
                    %AnalysisDir = fullfile(ReadDir1, [CurrentFolderName,' IMAGE_DATA']);
                        [~, Folders1] = FilesAndFolders(app, ReadDir1);
            
                    dt = app.TimeStep;  % (minutes)
                    dp = app.PixelSize; % (microns)
                    warning off
                    %======================================================================================================
                    switch app.DataFormat            
                        case 'Nikon'  % Create IMAGE_DATA folder for Nikon .nd file extraction
                            
                            ImageReadDir = ReadDir1; %WriteDir;
                            [~,Folders1] = FilesAndFolders(app,ImageReadDir);
                            fIdx = ~contains(Folders1,'ANALYSIS');
                            Folders1 = Folders1(fIdx,1);
                            nG = length(Folders1);
                            nSP = FindTotalNumberOfStagePositions(app,ReadDir1,Folders1);    

                        case 'Nikon-CP' 

                            ImageReadDir = ReadDir1; %WriteDir;
                            [~,Folders1] = FilesAndFolders(app,ImageReadDir);
                            fIdx = ~contains(Folders1,'ANALYSIS');
                            Folders1 = Folders1(fIdx,1);
                            nG = length(Folders1);
                            nSP = FindTotalNumberOfStagePositions(app,ReadDir1,Folders1);    
                        
                            try
                                SelectedModelName = app.CellposeModelPulldown.Items{app.CellposeModelPulldown.Value};
                                CPmodel = fullfile( 'X:\Mendoza Lab\MATLAB\Cellpose\cellposeModels', SelectedModelName );
                                CPT = cellpose(Model = CPmodel);
                            catch
                                disp('Cellpose functions not found!!')
                            end

                        case 'Metamorph'
                            ImageReadDir = ReadDir1;
                            nG = length(app.MetamorphData.StageGroups);
                            nSP = app.MetamorphData.FileInfo.nStagePositions;   
                    end
            
                    % Initialize with default values
                    app.RosePlotColors = repmat( [0, 0.4471, 0.7412],nG,1);

                    % Create ANALYSIS and SUMMARY_IMAGES folders ========================================
                    AnalysisDir = fullfile(ReadDir1, [CurrentFolderName, ' ANALYSIS']);
                        mkdir(AnalysisDir);
            
                    % SummaryDir = fullfile(ReadDir1, [CurrentFolderName, ' SUMMARY_IMAGES']);
                    %     mkdir(SummaryDir)
            
                    %====================================================================================
                    SE1 = strel('disk',2);
                    SE2 = strel('disk',3);
                    ImSat = @(Im,pL,pH) imadjust(Im,stretchlim(Im, [pL/100,(100-pH)/100]));
                    index = 0;
                    StopTrigger = false;
                    
                    if strcmp(app.DataFormat,'Nikon') || strcmp(app.DataFormat,'Metamorph')
                        wbh = waitbar(0);
                        wbh.Units = 'normalized';
                        wbh.Position = [0.0083, 0.0519, 0.1875, 0.0694];
                    else
                        wbh = -1;
                    end
                    %====================================================================================
            
                    for k = 1:nG
                                %-------------------------------------------------------------------------------
                                switch app.DataFormat
                                    case 'Nikon'                                        
                                        [StageFiles,~] = FilesAndFolders(app,fullfile(ImageReadDir,Folders1{k}));
                                        nS = length(StageFiles);
                                        StageNumbers = 1:nS;
                                    
                                    case 'Nikon-CP' 
                                        [StageFiles,~] = FilesAndFolders(app,fullfile(ImageReadDir,Folders1{k}));
                                        nS = length(StageFiles);
                                        StageNumbers = 1:nS;
        
                                    case 'Metamorph'
                                        nS =    length(app.MetamorphData.StageGroups{k}.StagePositions);
                                        StageNumbers = app.MetamorphData.StageGroups{k}.StagePositions;
                                end
                                %-------------------------------------------------------------------------------
                                for s = StageNumbers
                                        index = index + 1;
                                        clear movieInfo DATA
                                        %------------------------------------------------------------------------------------
                                        switch app.DataFormat
                                            case 'Nikon'  % for Nikon format Read in all image names for this stage position                                            
                                                RD1 = fullfile(ImageReadDir, Folders1{k}, StageFiles{s}); % ND file directory/name
                                                reader = bfGetReader(RD1); 
                                                nI = reader.getSizeT;

                                                if app.ResampleImagesTo1024Check.Value
                                                    Irows = 1024;
                                                    Icols = 1024;
                                                else
                                                    Irows = reader.getSizeX;
                                                    Icols = reader.getSizeY;
                                                end

                                                ImageData = zeros(Irows,Icols,nI,'uint16');
                                                TimePointData = cell(nI,1);
                                                nLocations = NaN(nI,1);     

                                                try
                                                    linker = LAPLinker;                                                    
                                                    %Adjust properties as necessary
                                                    linker.Solver = 'munkres'; % 'munkres'; 'lapjv'
                                                    linker.LinkedBy = 'Centroid';  %Link objects by position
                                                    linker.LinkCostMetric = 'euclidean';  %Compute straight-line distance 
                                                    linker.LinkScoreRange = [0, 100]; %Link objects within 0 - 50 pxs
                                                    linker.TrackDivision = true;
                                                catch
                                                    d = dbstack;
                                                    disp(['Problem with tracking setup...Autocell line# ',num2str(d(1).line)])
                                                end


                                            case 'Nikon-CP'  
                                                RD1 = fullfile(ImageReadDir, Folders1{k}, StageFiles{s}); % ND file directory/name
                                                reader = bfGetReader(RD1); 
                                                nI = reader.getSizeT;

                                                if app.ResampleImagesTo1024Check.Value
                                                    Irows = 1024;
                                                    Icols = 1024;
                                                else
                                                    Irows = reader.getSizeX;
                                                    Icols = reader.getSizeY;
                                                end
    
                                                ImageData = zeros(Irows,Icols,nI,'uint16');
                                                TimePointData = cell(nI,1);
                                                nLocations = NaN(nI,1);                                                
                                                
                                                try
                                                    linker = LAPLinker;
                                                    %Adjust properties as necessary
                                                    linker.Solver = 'munkres'; % 'munkres'; 'lapjv'
                                                    linker.LinkedBy = 'PixelIdxList'; % 'Centroid';  %Link objects by position
                                                    linker.LinkCostMetric = 'pxintersect'; % 'euclidean';  Compute straight-line distance 
                                                    linker.LinkScoreRange = [0, 5]; % Link objects within 0 - 60 pxs
                                                    linker.DivisionParameter = 'PixelIdxList';
                                                    linker.DivisionScoreMetric = 'pxintersect';
                                                    linker.MinFramesBetweenDiv = 6*60/dt;
                                                    linker.DivisionScoreRange = [0,2];
                                                    linker.MaxTrackAge = 2;
                                                    linker.TrackDivision = true;
                                                catch
                                                    d = dbstack;
                                                    disp(['Problem with tracking setup...Autocell line# ',num2str(d(1).line)])
                                                end

                                            case 'Metamorph'
                                                idxC = find(app.MetamorphData.FileInfo.CST_array(:,1) == 1);
                                                idxS = find(app.MetamorphData.FileInfo.CST_array(:,2) == s);
                                                idx = intersect(idxC,idxS);
                                                TimePoints = app.MetamorphData.FileInfo.CST_array(idx,3);
                                                nI = max(TimePoints);
                                                
                                        end
                                        %-----------------------------------------------------------------------------------
                    
                                        % Crop Time Points -------------------------------------------------
                                            if app.CropTimePtsCheck.Value
                                                tStart = str2double(app.StTimePtInput.Value);
                                                tEnd   = str2double(app.EndTimePtInput.Value);
                                            else
                                                tStart = 1;
                                                tEnd   = nI;
                                            end
                                        % ------------------------------------------------------------------
                    
                                        % Mean cell radius
                                        r = 1;
                                        % Create Figure with Axes -------------------------
                                        MH = figure(7); 
                                        %clf(FH);
                                        MH.Units = 'normalized';
                                        MH.Position = [0.15,0.2,0.7,0.65];

                                        if strcmp(app.DataFormat,'Nikon-CP')
                                            TL = tiledlayout(MH,1,1,"TileSpacing","tight","Padding","compact");
                                            A1 = nexttile(TL,1);
                                        else
                                            A1 = axes(MH,'Position',[0.05,0.22,0.90,0.75]);
                                            A2 = axes(MH,'Position',[0.05,0.05,0.90,0.16]);
                                        end
                                        %--------------------------------------------------
                    
                                        % START LOOPING THROUGH ALL THE TIME POINTS FOR THIS STAGE POSITION -----------------------------------------
                                        for t = tStart:tEnd
                    
                                                        % STOP BUTTON |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                        if StopTrigger; break
                                                        elseif strcmp(app.StopButton.Enable,'off')
                                                            button = questdlg('Are you sure you want to stop processing?','Processing Stop','Yes','No','Yes');
                                                            if strcmp(button,'Yes')
                                                                StopTrigger = true;
                                                                app.StartButton.Enable = 'on'; break;
                                                            else
                                                                app.StopButton.Enable = 'on';
                                                            end
                                                        end
                                                        % |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                       
                                                        dp = app.PixelSizeInput.Value;
                            
                                                        if isempty(dp) || isnan(dp)
                                                            wH = warndlg([{'Pixel size is not being read properly.'};  ...
                                                                          {'Is this old image data?. Try re-converting ND2 to tiffs.'}]);  %#ok<NASGU>
                                                            StopTrigger = true;
                                                            break
                                                        end
                            
                                                        % Read in Channel 1 image ===========================================================================
                                                        switch app.DataFormat
                                                            case 'Nikon'  % for Nikon format Read in all image names for this stage position
                                                                    %ImageFileName = fullfile(RD1, ImFilesCh1{t});
                                                                    try 
                                                                        NucCh = app.NuclearMarkerChannel.Value;
                                                                        IM1A  = GetND2Image(app,reader,1,1,NucCh,t);   % Image = GetND2Image(app,reader,Series,z,c,t);
                                                                        if app.ResampleImagesTo1024Check.Value
                                                                            IM1A = imresize(IM1A,[1024,1024]);
                                                                        end
                                                                        %IM1A = imread(ImageFileName); % Read in nuclear stained image
                                                                    catch
                                                                        d = dbstack;
                                                                        display(['Autocell line# ',num2str(d(1).line)])
                                                                        display(['Image not read from:  ',RD1])
                                                                    end
                                                            
                                                            case 'Nikon-CP'  
                                                                    try 
                                                                        WFCh = app.WideFieldChannel.Value;
                                                                        IM1A  = GetND2Image(app,reader,1,1,WFCh,t);   % Image = GetND2Image(app,reader,Series,z,c,t);
                                                                        if app.ResampleImagesTo1024Check.Value
                                                                            IM1A = imresize(IM1A,[1024,1024]);
                                                                        end
                                                                        I0_bright = adapthisteq(imsaturate(app,IM1A,0.1));
                                                                        ImageData(:,:,t) = IM1A;
                                                                    catch
                                                                        d = dbstack;
                                                                        display(['Autocell line# ',num2str(d(1).line)])
                                                                        display(['Image not read from:  ',RD1])
                                                                    end
                
                                                            case 'Metamorph'
                                                                    FileName = FindMetamorphFileName(app, app.MetamorphData.FileInfo.CST_array, 1,s,t,... % channel,stageposition,timepoint
                                                                                                          app.MetamorphData.FileInfo.FileNames);
                                                                    ImageFileName = fullfile(app.ReadDirMain,FileName);
                                                                    try 
                                                                        IM1A = imread(ImageFileName); % Read in nuclear stained image
                                                                        if app.ResampleImagesTo1024Check.Value
                                                                            IM1A = imresize(IM1A,[1024,1024]);
                                                                        end
                                                                    catch
                                                                        display(ImageFileName)
                                                                    end
                                                        end
                                                        % ===================================================================================================                                                        
                                                        
                                                        if isa(IM1A,'uint8'); IM1A = im2uint16(IM1A); end
                            
                                                        Iraw = IM1A; 
                                                        if t == tStart % Pre-allocate variable space in memory
                                                            [Irows,Icols] = size(IM1A,[1,2]);
                                                            IM2D  = zeros(Irows, Icols, nI, class(IM1A)); % For recording saturated widefield images
                                                            IMnuc = zeros(Irows, Icols, nI, class(IM1A)); % For recording saturated nuclear images
                                                            IRAW  = zeros(Irows, Icols, nI, class(IM1A)); % Iraw = IM1A before smoothing and subtracting threshold
                                                            L2    = false(Irows, Icols, nI);              % Recording regional max masks
                                                        end
                                                    
                                                        %------------------------------------------------------------------                            
                                                                                                               
                                                        if strcmp(app.DataFormat,'Nikon-CP')  
                                                                labelCustom = segmentCells2D(CPT, IM1A , ImageCellDiameter = app.CellSizeForCellposeInput.Value); 
                                                                stats = regionprops(labelCustom,"Centroid","Area","PixelIdxList");  
                                                                TimePointData{t,1} = stats;
                                                                Stats2Cell = struct2cell(stats)';
                                                                XY = cell2mat(Stats2Cell(:,2));
                                                                [DX,DY] = gradient(labelCustom);
                                                                Perimeter = double( ~ imdilate( logical( sqrt(DX.^2 + DY.^2) ),strel('disk',3) ) );
                                                                SegmentationImage = Perimeter.*labelCustom;
                                                                nLocations(t,1) =  size(stats,1);  

                                                        else
                                                                IM1A = imgaussfilt(IM1A, app.GaussianSigma);
                                                                % Calculate Dynamic Threshold Value ====================================================================
                                                                try
                                                                    [Fit1,xFit,yFit,A,B,C,Threshold_01,Threshold_02,xHist,yHist] = CalculateBackgroundThreshold(app,IM1A);
                                                                    FWHM = 2*sqrt(2*log(2))*C;
                                                                    IMgood = IM1A; % Capture image and use it if next time point is blank
                                                                catch
                                                                    IM1A = IMgood;
                                                                    warning(['Error with Time Point: ', num2str(t)])
                                                                end
                                    
                                                                %===================================================================================
                                                                IM1B = IM1A - Threshold_01;  % Subtract Threshold value from image
                                                                try    
                                                                    MaxPixVal = CalculateRelativeMaxPixel(app,IM1B);
                                                                    %MaxPixVal = prctile(IM1B(:),99);
                                                                catch
                                                                    MaxPixVal = max(IM1B(:));
                                                                end
                                    
                                                                BW1 = imregionalmax(IM1B); % Find nuclear markers
                                                                MinBrightnessPr = app.PercentThreshold/100; % default = 0.02;
                                                                ObjectThreshold = ceil(MinBrightnessPr*MaxPixVal); % threshold value above background threshold
                                                                BW1( IM1B < ObjectThreshold ) = false; % Only keep binary objects that have at least 2% of MaxPixVal
                                                             
                                                                % [Yrow,Xcol] = find( BW1 );
                                                                % imagesc(IM1B); axis equal tight off; colormap(gray); hold on; 
                                                                % scatter(Xcol,Yrow,'MarkerFaceColor','c','MarkerEdgeColor','b','SizeData',25,'LineWidth',1); hold off

                                                                stats = regionprops(BW1,"Centroid","Area","PixelIdxList");  
                                                                TimePointData{t,1} = stats;

                                                                L1 = bwmorph( BW1, 'dilate', 3); % increase binary object size for visual purposes
                                                                L2(:,:, t-tStart+1 ) = BW1; 
                                                                S = 2^16 - 1;
                                                        end

                                        %------------------------- Create Plots -----------------------------------------------------------------------------------------------
                                                        if ~isgraphics(MH) || t == tStart
                                                            if exist('FH','var'); clear('MH');  end
                                                            MH = figure(7); clf;
                                                            MH.Color = 'w';
                                                            MH.Units = 'normalized';

                                                            if strcmp(app.DataFormat,'Nikon-CP')
                                                                MH.Position = [  0.15    0.15    0.7    0.7];
                                                                set(MH,'Color','k','Units','normalized');
                                                                TL = tiledlayout(1,2,"TileSpacing","tight","Padding","tight");
                                                                AX1 = nexttile(TL,1);
                                                            else
                                                                MH.Position = [0.15,0.2,0.7,0.65];
                                                                A1 = axes(MH,'Position',[0.05,0.22,0.90,0.75]);
                                                                A2 = axes(MH,'Position',[0.05,0.05,0.90,0.16]);
                                                            end

                                                            XLow = [];
                                                            XHigh = [];
                                                        end


                                                        if strcmp(app.DataFormat,'Nikon-CP')
                                                                MarkSize = 50;
                                                                LineWidth = 1;
                                                                % SF = 700/rows;
                                                                AX1 = nexttile(TL,1);     cla       
                                                                    %imagesc(AX1, imresize(I0_bright,SF) ); hold on; 
                                                                    imagesc(AX1,I0_bright); hold on; 
                                                                    if ~isempty(XY)
                                                                        scatter(AX1,XY(:,1),XY(:,2),MarkSize,'filled','o','MarkerEdgeColor','k','MarkerFaceColor','c','LineWidth',LineWidth);
                                                                    end
                                                                    colormap(AX1,flipud(gray)); hold off; axis square tight off  
                                                                    xticks([]);yticks([]); 
                                                                    try
                                                                        clim([0,max(I0_bright(:))])
                                                                    catch
                                                                        d = dbstack;
                                                                        display(['Autocell line# ',num2str(d(1).line)])
                                                                    end

                                                                    text(AX1,0.05,-0.01,['Position: ',num2str(index),'/',num2str(nSP),',   Time point: ',num2str(t-tStart+1),'     Cellpose model: ',SelectedModelName],...
                                                                                        'FontSize',18,'Units','normalized','Color','w',...
                                                                                        'BackgroundColor','none','HorizontalAlignment','left','VerticalAlignment','top')
                                                                    text(AX1,0.05,1.01,[Folders1{k}, ':    ', StageFiles{s}], 'FontSize',16,'Units','normalized','Color','w','Interpreter','none',...
                                                                                        'BackgroundColor','none','HorizontalAlignment','left','VerticalAlignment','bottom')

                                                                AX2 = nexttile(TL,2);    cla
                                                                    % imagesc(AX2, imresize(SegmentationImage,SF,"nearest") );% hold on;% C1 = prctile(PhaseFlat(:),[5,99.9]);
                                                                    imagesc(AX2, SegmentationImage);% hold on;% C1 = prctile(PhaseFlat(:),[5,99.9]);
                                                                    CM2 = DivergentColorMap03(app);
                                                                    colormap(AX2,[[0,0,0.3];CM2(1:end-40,:)]); hold off; axis square tight off  
                                                                    xticks([]);yticks([]); 
                                                                    try
                                                                        clim([0,max(SegmentationImage(:))])
                                                                    catch
                                                                        d = dbstack;
                                                                        display(['Autocell line# ',num2str(d(1).line)])
                                                                    end
                                                                    %text(0.02,0.97,['t = ',num2str(t)],'FontSize',18,'Units','normalized','Color','w','BackgroundColor','k')
                                                              

                                                        else
                                                                Line = uint16(S*ones(512,4));
                                                                ImCombined = cat(2,ImSat( imresize(Iraw,'OutputSize',[512,NaN],'method','bicubic','Antialiasing',true), 0.1,0.1), Line,...
                                                                                   ImSat( imresize(IM1B,'OutputSize',[512,NaN],'method','bicubic','Antialiasing',true), 0.1,0.1), Line,...
                                                                                   uint16( S*logical( imresize(  L1,'OutputSize',[512,NaN],'method','bicubic','Antialiasing',true) ) )  );
                                                            
    
                                                                imshow(ImCombined,'Parent',A1,'InitialMagnification',100); 
                                                                warning off
                                    
                                                                % Image Processing Display Title -----------------------------------------------
                                                                switch app.DataFormat
                                                                    case 'Nikon';      title(A1,[Folders1{k}, ':  ', StageFiles{s}, '        Timepoint: ', num2str(t,'%04.0f')], 'Interpreter', 'none', 'FontSize', 14)
                                                                    case 'Metamorph';  title(A1,['Image Name: ', FileName,            '        Timepoint: ', num2str(t,'%04.0f')], 'Interpreter', 'none', 'FontSize', 14)
                                                                end
                                                                %-------------------------------------------------------------------------------
                                                                text(A1, 1/6, -0.03, 'Raw Image',       'Units', 'normalized', 'HorizontalAlignment', 'center')
                                                                text(A1, 3/6, -0.03, 'Processed Image', 'Units', 'normalized', 'HorizontalAlignment', 'center')
                                                                text(A1, 5/6, -0.03, 'Binary Image',    'Units', 'normalized', 'HorizontalAlignment', 'center')                                                                
        
                                                                semilogy(A2, xHist, yHist, '.b', xFit, yFit, '-r', [Threshold_01; Threshold_01], [1; max(yHist)] , '--k',...
                                                                                                                   [Threshold_01+ObjectThreshold; Threshold_01+ObjectThreshold], [1; max(yHist)] , '--r')
                                    
                                                                ylim(A2, [1, max(yHist)])
                                                                text(A2, 3/6, -0.2, 'Pixel Value [vertical dashed line is adjusted background value, red dashed line is object threshold]',...
                                                                                    'Units', 'normalized', 'HorizontalAlignment', 'center')
                                                        end

                                                        drawnow

                                                       % End of: Create Plots ----------------------------------------------------------------------------------------
                            

                                                       if strcmp(app.DataFormat,'Nikon-CP')
                                                                %IRAW(:,:,t-tStart+1)  = imsaturate(app,IM1A,0.1);

                                                       else

                                                               % Create MovieInfo variable ---------------------------------------------------
                                                                CC = bwconncomp(L2(:,:,t-tStart+1));
                                                                Stats = regionprops(CC,'Centroid');
                                                                N = CC.NumObjects;
                                                                x = zeros(N,2);
                                                                y = zeros(N,2);
                                                                a = zeros(N,2);
                                                                for n = 1:N
                                                                    x(n,:) = [round(Stats(n).Centroid(1,1)), r]; % Grab X coordinate of cell mask centroid
                                                                    y(n,:) = [round(Stats(n).Centroid(1,2)), r]; % Grab Y coordinate of cell mask centroid
                                                                    ObjIndices = CC.PixelIdxList{n};  % Grab linear indices of cell mask region
                                                                    a(n,1) = mean(IM1A(ObjIndices));  % using mask, grab mean pixel value from processed image
                                                                end
                                                                movieInfo(t-tStart+1).xCoord = x;
                                                                movieInfo(t-tStart+1).yCoord = y;
                                                                movieInfo(t-tStart+1).amp    = a;
                                    
                                                                % Read in Channel 2 image =======================================================================================
                                                                switch app.DataFormat
                                                                    case 'Nikon'  % for Nikon format Read in all image names for this stage position
                                                                        %ImageFileName2 = fullfile(RD2, ImFilesCh2{t});
                                                                        try    
                                                                            %IM2A = imread(ImageFileName2); % Read in Widefield image
                                                                            WFCh = app.WideFieldChannel.Value;
                                                                            IM2A  = GetND2Image(app,reader,1,1,WFCh,t);   % Image = GetND2Image(app,reader,Series,z,c,t);
                                                                            if app.ResampleImagesTo1024Check.Value
                                                                                IM2A = imresize(IM2A,[1024,1024]);
                                                                            end
                                                                        
                                                                        catch
                                                                            %IM2A = imread(ImageFileName);  % Incase Widefield image isn't there, read in nuclear channel to take it's place
                                                                        end
        
                                                                    case 'Metamorph'
                                                                        FileName = FindMetamorphFileName(app, app.MetamorphData.FileInfo.CST_array,2,s,t,... % CST_array, channel, stageposition, timepoint
                                                                                                              app.MetamorphData.FileInfo.FileNames);
                                                                        ImageFileName2 = fullfile(app.ReadDirMain,FileName);
                                                                        try    IM2A = imread(ImageFileName2); % Read in Widefield image
                                                                        catch; IM2A = imread(ImageFileName);  % Incase Brightfield image isn't there, read in nuclear channel to take it's place
                                                                        end
                                                                end
                                                                % ================================================================================================================
                                                                if isa(IM2A,'uint8'); IM2A = im2uint16(IM2A); end
                                                                IM2A = ImSat(IM2A, 1, 0.1);
                                                                IM2D(:,:,t-tStart+1)  = IM2A; 
                                                                IMnuc(:,:,t-tStart+1) = ImSat(IM1B,0.1,0.1); 
                                                                IRAW(:,:,t-tStart+1)  = ImSat(Iraw,0.1,0.1);
                                                       end

                                                        
                                                       if strcmp(app.DataFormat,'Nikon') || strcmp(app.DataFormat,'Metamorph')
                                                            if isgraphics(wbh)
                                                                waitbar((t-tStart+1)/(tEnd-tStart+1),wbh,['Position: ',num2str(index),'/',num2str(nSP),', Time Point: ',num2str(t-tStart+1)]);
                                                            else
                                                                wbh = waitbar(0);
                                                                wbh.Units = 'normalized';
                                                                wbh.Position = [0.0083, 0.0519, 0.1875, 0.0694];
                                                                waitbar((t-tStart+1)/(tEnd-tStart+1),wbh,['Position: ',num2str(index),'/',num2str(nSP),', Time Point: ',num2str(t-tStart+1)]);
                                                            end
                                                       end
                                        end
                                    % END LOOPING THROUGH ALL THE TIME POINTS FOR THIS STAGE POSITION -----------------------------------------
                    
                                        try close(MH); end %#ok<TRYNC>
                    
                                    % STOP BUTTON |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                        if StopTrigger; break;
                                        elseif strcmp(app.StopButton.Enable,'off')
                                            button = questdlg('Are you sure you want to stop processing?','Processing Stop','Yes','No','Yes');
                                            if strcmp(button,'Yes')
                                                StopTrigger = true;
                                                app.StartButton.Enable = 'on';
                                                break
                                            else; app.StopButton.Enable = 'on';
                                            end
                                        end

                                    % |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                    % TRACK CELLS using u-TRACK or FASTRACK --------------------------------------------------------------------------------------------
                                        % [gCP, cM, kF, v, pD] = TrackParametersKRC(app);
                                        DPtext = sprintf('%1.3f',dp);
                                        iddx = strfind(DPtext, '.');
                                        DPtext(iddx) = 'd';
                    
                                        switch app.DataFormat
                                            case 'Nikon' % Calculate Cell tracks ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                    saveResults.dir = fullfile(AnalysisDir,Folders1{k});
                                                    mkdir(saveResults.dir)
                                                    sIdx = strfind(StageFiles{s},'.');
                                                    sIdx = sIdx(end);
                                                    %saveResults.filename = ['uTrackOutput_', 'ps', DPtext, '_',  StageFiles{s}(1:sIdx-1), '.mat']; % Name of file where input and output are saved
                                                    saveResults.filename = ['LAPLinkerOutput_', 'ps', DPtext, '_', StageFiles{s}(1:sIdx-1), '.mat']; % Name of file where input and output are saved

                                                    % trackCloseGapsKalmanSparse function is borrowed from https://github.com/DanuserLab/u-track
                                                    % [tracksFinal,~,~] = trackCloseGapsKalmanSparse(app, movieInfo, cM, gCP, kF, pD, saveResults, v);                                            

                                                    DLG = uiprogressdlg(app.figure1,'Title','Computing tracks.... this will take a minute.','Indeterminate','on');
                                                    drawnow 
                                                    StageName = StageFiles{s}(1:sIdx-1);
                                                    [tracksFinal,TrackXArray,TrackYArray,~] = Apply_LAPlinker_tracking(app, nI, dt, linker, TimePointData, nLocations, StageName);
                                                    close(DLG)

                                                    MinTrackLength = str2double(app.MinTrackLengthInput.Value); 
                                                    nMSD = str2double(app.nMSDPts.Value);
                                                    MSD_ImageFileName = fullfile(saveResults.dir,['MSD-ANALYSIS-PLOTS__',StageFiles{s}(1:sIdx-1),'.tif']);

                                            case 'Nikon-CP' % Calculate Cell tracks |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||| 
                                                    saveResults.dir = fullfile(AnalysisDir,Folders1{k});
                                                    sIdx = strfind(StageFiles{s},'.');
                                                    sIdx = sIdx(end);
                                                    saveResults.filename = ['LAPLinkerOutput_', 'ps', DPtext, '_', StageFiles{s}(1:sIdx-1), '.mat']; % Name of file where input and output are saved
                                                                                                   
                                                    DLG = uiprogressdlg(app.figure1,'Title','Computing tracks.... this will take a minute.','Indeterminate','on');
                                                    drawnow

                                                    StageName = StageFiles{s}(1:sIdx-1);
                                                    [tracksFinal,TrackXArray,TrackYArray,~] = Apply_LAPlinker_tracking(app, nI, dt, linker, TimePointData, nLocations, StageName);
                                                    close(DLG)

                                                    mkdir(saveResults.dir)
                                                    MinTrackLength = str2double(app.MinTrackLengthInput.Value); 
                                                    nMSD = str2double(app.nMSDPts.Value);
                                                    MSD_ImageFileName = fullfile(saveResults.dir,['MSD-ANALYSIS-PLOTS__',StageFiles{s}(1:sIdx-1),'.tif']);

                                            case 'Metamorph' % Calculate Cell tracks ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                                                                                                            
                                                    GroupName = app.MetamorphData.StageGroups{k}.Category;
                                                    saveResults.dir = fullfile(AnalysisDir,app.MetamorphData.StageGroups{k}.Category);
                                                    saveResults.filename = ['uTrackOutput_', GroupName, '_ps', DPtext, sprintf('_s%03d',s), '.mat']; % Name of file where input and output are saved
                                                    % trackCloseGapsKalmanSparse function is borrowed from https://github.com/DanuserLab/u-track
                                                    % [tracksFinal,~,~] = trackCloseGapsKalmanSparse(app, movieInfo, cM, gCP, kF, pD, saveResults, v);

                                                    MinTrackLength = str2double(app.MinTrackLengthInput.Value); 
                                                    nMSD = str2double(app.nMSDPts.Value);
                                                    MSD_ImageFileName = fullfile(saveResults.dir,['MSD-ANALYSIS-PLOTS__',StageFolders{s}(1:sIdx-1),'.tif']);
                                        end


                                        % MSD Analysis ---------------------------------------------------------------------------------------------------------
                                        % MinTrackLength = str2double(app.MinTrackLengthInput.Value); 
                                        % nMSD = str2double(app.nMSDPts.Value);
                                        % MSD_ImageFileName = fullfile(saveResults.dir,['MSD-ANALYSIS-PLOTS__',StageFiles{s}(1:sIdx-1),'.tif']);

                                        try
                                            [DATA, DR, tracksFinal] = CellTrack_MSD_Analysis(app, tracksFinal, nI, dt, dp, MinTrackLength, nMSD, MSD_ImageFileName);
                                            AnalysisGood = true;
                                        catch
                                            disp(['Stage Position:  ',StageFiles{s},'  FAILED,     k = ',num2str(k),', s = ',num2str(s)])
                                            AnalysisGood = false;
                                        end
                                        % ----------------------------------------------------------------------------------------------------------------------

                                        if AnalysisGood
                                                switch app.DataFormat
                                                    case 'Nikon'
                                                        GaussianSigma = app.GaussianSigma;
                                                        save(fullfile(saveResults.dir,['MSD-ANALYSIS_',StageFiles{s}(1:sIdx-1),'.mat']),'DATA','DR','tracksFinal','dt','dp','Irows','Icols','GaussianSigma')
                                                    case 'Nikon-CP'   
                                                        save(fullfile(saveResults.dir,['MSD-ANALYSIS_',StageFiles{s}(1:sIdx-1),'.mat']),'DATA','DR','tracksFinal','dt','dp','Irows','Icols')
                                                    case 'Metamorph'
                                                        save(fullfile(saveResults.dir,['MSD-ANALYSIS_',GroupName,sprintf('_s%03d',s),'.mat']),'DATA','DR','tracksFinal','dt','dp','Irows','Icols','GaussianSigma')
                                                end
                            
                                                % Plot cell tracks over segmented cells --------------------------------------------------------------------------------
                                                % Initialize Movie -----------------------------------------------------------------
                                                switch app.DataFormat
                                                    case 'Nikon'
                                                        movObj = VideoWriter( fullfile(saveResults.dir, [StageFiles{s}(1:sIdx-1),'.mp4']),'MPEG-4');
                                                    case 'Nikon-CP'   
                                                        movObj = VideoWriter( fullfile(saveResults.dir, [StageFiles{s}(1:sIdx-1),'.mp4']),'MPEG-4');
                                                    case 'Metamorph'
                                                        GroupName = app.MetamorphData.StageGroups{k}.Category;
                                                        movObj = VideoWriter( fullfile(saveResults.dir, [GroupName, sprintf('_s%03d',s),'.mp4']),'MPEG-4');
                                                end
                                                movObj.FrameRate = 5;
                                                open(movObj);
                                                %-----------------------------------------------------------------------------------------------------------------------
                                               
                                                MH = -1;                                   
                                                
                                                for t = tStart:tEnd
        
                                                        % STOP BUTTON ||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                        if StopTrigger; break;
                                                        elseif strcmp(app.StopButton.Enable,'off')
                                                            button = questdlg('Are you sure you want to stop processing?','Processing Stop','Yes','No','Yes');
                                                            if strcmp(button,'Yes')
                                                                StopTrigger = true;
                                                                app.StartButton.Enable = 'on';
                                                                break
                                                            else; app.StopButton.Enable = 'on';
                                                            end
                                                        end
                                                        % |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                
                                                        if strcmp(app.DataFormat,'Nikon-CP')
                                                                if t == tStart || ~isgraphics(MH)
                                                                     MH = figure(2); clf
                                                                     set(MH,'Color','k','Units','normalized')
                                                                     MH.Position = [0.27    0.0583    0.46    0.80];
                                                                     TL = tiledlayout(12,11,'TileSpacing','tight','Padding','compact');
                                                                     AH5 = nexttile(TL,12,[11,11]);
                                                                     AH5.Color = 'k';
                                                                     nTrax = size(TrackXArray,1);
                                                                end                                                        
                                                                
                                                                LCM = DivergentColorMap05(app);
                                                                ScaleMaxSpeed = 2.0; % microns/min
                                                                IM = adapthisteq(imsaturate(app,ImageData(:,:,t),0.1));
                                                                NormalizedImage = double(IM); 
                                                                NormalizedImage = ScaleMaxSpeed*( 0.9*NormalizedImage./max(NormalizedImage(:)) + 0.1);
                                                                imagesc(AH5,dp*(1:Icols),dp*(1:Irows),NormalizedImage); hold on
                                                                try
                                                                    clim([0,ScaleMaxSpeed])
                                                                end
                                                                colormap(AH5,flipud(gray))
                                                              
                                                                scatter(AH5,dp*TrackXArray(:,t),dp*TrackYArray(:,t),50,'filled','o','MarkerEdgeColor','k','MarkerFaceColor','g','LineWidth',1); hold on
                                                               
                                                                for T = 1:nTrax
                                                                     MSDVelocity = DATA(T,6);
                                                                     if ~isnan(MSDVelocity)
                                                                         PtIdx = ~isnan(TrackXArray(T,1:t));
                                                                         x = dp*smoothdata( TrackXArray(T,1:t),'gaussian',9);
                                                                         y = dp*smoothdata( TrackYArray(T,1:t),'gaussian',9);
                                                                         z = zeros(size(x));
                                                                         SpeedIndex = round(256*MSDVelocity/ScaleMaxSpeed) + 1;
                                                                         SpeedIndex(SpeedIndex > 256) = 256;
                                                                         plot(AH5,x,y,'-','Color',LCM(SpeedIndex,:),'LineWidth',1.0); 
                                                                     else
                                                                         x = dp*smoothdata( TrackXArray(T,1:t),'gaussian',9);
                                                                         y = dp*smoothdata( TrackYArray(T,1:t),'gaussian',9);
                                                                         plot(AH5,x,y,'-','Color','w','LineWidth',1.0); 
                                                                     end
                                                                end
                                                                hold off
                                                        
                                                              
                                                                text(AH5,0.00,1.10,['Time point = ',num2str(t),'  (',sprintf('%0.2f',(t-1)*dt/60),' hrs)      Cellpose model: ', SelectedModelName],...
                                                                            'FontSize',18,'FontWeight','normal','Interpreter','none', ...
                                                                            'Units','normalized','Color','w','BackgroundColor','none',...
                                                                            'HorizontalAlignment','left','VerticalAlignment','baseline')
                                                        
                                                                text(AH5,0.00,1.05,[Folders1{k}, ':    ', StageFiles{s}],...
                                                                            'FontSize',14,'FontWeight','normal','Interpreter','none', ...
                                                                            'Units','normalized','Color','w','BackgroundColor','none',...
                                                                            'HorizontalAlignment','left','VerticalAlignment','baseline')
                                                        
                                                                set(AH5,'YDir','reverse','FontSize',12,'FontWeight','normal','TickDir','out',...
                                                                            'XColor',[0.8,0.8,0.8],'YColor',[0.8,0.8,0.8])
        
                                                                xlabel('X (\mum)','FontSize',14,'FontWeight','normal') 
                                                                ylabel('Y (\mum)','FontSize',14,'FontWeight','normal')
                                                                axis square tight; 
                                                                    CH = colorbar;
                                                                    CH.FontSize = 12;
                                                                    CH.TickDirection = "out";
                                                                    CH.Ticks = 0:0.2:ScaleMaxSpeed;
                                                                    colormap(CH,LCM);
                                                                    CH.Limits = [0,ScaleMaxSpeed];
                                                                    CH.Label.String = 'MSD Velocity (\mum/min)';
                                                                    CH.Label.FontSize = 18;
                                                                    CH.Label.Color = 'w';
                                                                    CH.Color = 'w';
                                                                drawnow
                                                                pause(0.1)
        
                                                        else % For Nikon-FluorescentMarker and Metamorph
                                                                if t == tStart
                                                                    MH = figure(123); clf
                                                                    MH.Color = 'w';
                                                                    A3 = axes(MH);    %#ok<LAXES>
                                                                    %A3.Units = 'normalized';
                                                                end
                                                                % Resize image to fit on screen -------------------------------------
                                                                cWidth = 800;
                                                                Mimage1 = imresize(IMnuc(:,:,t-tStart+1),[NaN,cWidth]);
                                                                Mimage2 = imresize(IM2D(:,:,t-tStart+1) ,[NaN,cWidth]);
                                                                [~,imc] = size(IMnuc(:,:,t-tStart+1));
                                                                rf = cWidth/imc; % Calculate display image Resize Factor so the cell tracks are overlaid properly
                                    
                                                                switch class(Mimage2)
                                                                    case 'uint16';    mLine = uint16( (2^16-1)*ones( size(Mimage1,1), 5 ));
                                                                    case 'uint8';     mLine =  uint8(  (2^8-1)*ones( size(Mimage1,1), 5 ));
                                                                end
                                                                MovieImageFull = [ Mimage1, mLine , Mimage2 ];
                                        
                                                                %--------------------------------------------------------------------
                                                                imshow(MovieImageFull,'Parent',A3,'InitialMagnification',100);
                                        
                                                                % START Plot tracks on Movie Image (Non-MSD cells are white) -------------------------
                                                                for ii = 1:numel(tracksFinal)
                                                                    ALPHA = tracksFinal(ii,1).Alpha;
                                                                    X = rf.*tracksFinal(ii,1).X; % X coordinate of cell track
                                                                    Y = rf.*tracksFinal(ii,1).Y; % Y coordinate of cell track
                                    
                                                                    hold(A3,'on')
                                                                    if ALPHA > app.AlphaThreshold
                                                                        plot(A3,X,Y,'-' ,'LineWidth',1)
                                                                    else
                                                                        plot(A3,X,Y,'w-','LineWidth',1)
                                                                    end
                                                                    hold(A3,'off')
                                                                end
                                                        end
        
                                                        % Add text to bottom of movie image ------------------------------------------------------------------------------------
                                                        
                                                         switch app.DataFormat
                                                             case 'Nikon'
                                                                [movieR, movieC] = size(MovieImageFull);
                                                                text(A3, 0.0122*movieC, 0.957*movieR, [Folders1{k},':  ',StageFiles{s}(1:sIdx-1), ',     timepoint = ', num2str(t),'  (',sprintf('%0.2f',(t-1)*dt/60),' hrs)'],... 
                                                                                'Color',[1 1 1],'Interpreter','none','FontSize',12)
                
                                                             case 'Nikon-CP' 
                                                                 % text(A3, 0.02*MovieC, 0.957*MovieR,...
                                                                 %                [{[Folders1{k},':   ',StageFolders{s}]};{['Cellpose segmentation,   timepoint = ',num2str(t),' (',sprintf('%0.2f',(t-1)*dt/60),' hrs)']}],...
                                                                 %                'Color',[1 1 1],'Interpreter','none','FontSize',12,'FontWeight','bold')
                
                                                             case 'Metamorph'
                                                                [movieR, movieC] = size(MovieImageFull); 
                                                                GroupName = app.MetamorphData.StageGroups{k}.Category;
                                                                text(A3, 0.0122*movieC, 0.957*movieR, [GroupName,':  ',sprintf('s%03d',s), ',    timepoint = ', sprintf('%d',t)],...
                                                                'Color',[1 1 1],'Interpreter','none','FontSize',12)
                                                         end
                                                        %-----------------------------------------------------------------------------------------------------------------------
                                                        % END Plot tracks on Movie Image (Non-MSD cells are white) -------------------------
                                                        drawnow
                                                        % Write Movie images -----------------------------------------------------------------
                                                        % MP4 format ---------------
                                                        F = getframe(MH);
                                                        try writeVideo(movObj,F.cdata)
                                                        catch; disp('!!! Video is not being written. It is most likely the Directory + Filename exceed 256 max character limit.')
                                                        end
                                                        %-------------------------------------------------------------------------------------
                                                end
                                         %-------------------------------------------------------------------------------------
                                                 % Grab final frame of movie and save as Summary Image
                                                 switch app.DataFormat
                                                     case 'Nikon'
                                                        SummaryImage  = getframe(MH);
                                                        SummaryFileName1 = fullfile(saveResults.dir,'SummaryStack.tif');
                                                        SummaryFileName2 = fullfile(saveResults.dir,[Folders1{k},'_',StageFiles{s}(1:sIdx-1),'.tif']);
                                                        
                                                     case 'Nikon-CP'  
                                                         SummaryImage  = getframe(MH);
                                                         % SummaryFileName1 = fullfile(SummaryDir,'SummaryStack.tif');
                                                         % SummaryFileName2 = fullfile(SummaryDir,[Folders1{k},'_',StageFolders{s},'.tif']);
                                                         SummaryFileName1 = fullfile(saveResults.dir, ['SummaryImages_',Folders1{k},'.tif']);

                                                     case 'Metamorph'
                                                        SummaryImage  = getframe(MH);
                                                        SummaryFileName1 = fullfile(SummaryDir,'SummaryStack.tif');
                                                        SummaryFileName2 = fullfile(SummaryDir,[GroupName,'_',sprintf('s%03d',s),'.tif']);
                                                 end
                            
                                                 
                                                 if s == 1
                                                     imwrite(SummaryImage.cdata, SummaryFileName1,'tif','WriteMode','overwrite')
                                                 else 
                                                     imwrite(SummaryImage.cdata, SummaryFileName1,'tif','WriteMode','append')
                                                 end
                                                
                            
                                                %-------------------------------------------------------
                                                % try imwrite(SummaryImage.cdata, SummaryFileName2, 'tif', 'Compression', 'none', 'WriteMode', 'overwrite')
                                                % catch; disp('Error writing Summary Images2')
                                                % end
                                                %-------------------------------------------------------
                            
                                                try close(movObj); end %#ok<TRYNC>
                                                try close(MH);     end %#ok<TRYNC>
                            
                                                % STOP BUTTON |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                                    if StopTrigger; break;
                                                    elseif strcmp(app.StopButton.Enable,'off')
                                                        button = questdlg('Are you sure you want to stop processing?','Processing Stop','Yes','No','Yes');
                                                        if strcmp(button,'Yes')
                                                            StopTrigger = true;
                                                            app.StartButton.Enable = 'on';
                                                            break
                                                        else; app.StopButton.Enable = 'on';
                                                        end
                                                    end
                                                % |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||


                                        end % End for AnalysisGood If statement
                                % --------------------------------------------------------------------------------------------------------------
                                end
                                % STOP BUTTON |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||
                                    if StopTrigger; break;
                                    elseif strcmp(app.StopButton.Enable,'off')
                                        button = questdlg('Are you sure you want to stop processing?','Processing Stop','Yes','No','Yes');
                                        if strcmp(button,'Yes')
                                             StopTrigger = true;
                                             app.StartButton.Enable = 'on';
                                             break
                                        else; app.StopButton.Enable = 'on';
                                        end
                                    end
                                % |||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||||

                    end % END of loop for k = 1:nG
            
            
                    try close(wbh); end %#ok<TRYNC>
            
                    % Check to see if there is an ANALYSIS FOLDER --------------------------------------------
                    [~, Folders1] = FilesAndFolders(app,app.ReadDirMain);
                    ind1 = contains(Folders1,"ANALYSIS");
                    if any(ind1)
                        app.AnalysisFilesDirectory = fullfile(app.ReadDirMain,Folders1{ind1});
                        [~, Folders2] = FilesAndFolders(app,app.AnalysisFilesDirectory);
                        app.CellCategoryDisplay.Items = Folders2;
                        app.CellCategoryDisplay.ItemsData = (1:length(Folders2))';
                        app.AnalysisFolderOriginalOrderAssignment.Names = Folders2;
                        app.AnalysisFolderOriginalOrderAssignment.Order = (1:length(Folders2))';
            
                        app.CellCategoryDisplay.Enable = 'on';
                        app.UpButton.Enable = 'on';
                        app.DownButton.Enable = 'on';
                        app.CreateAnalysisPlots_Button.Enable = 'on';
                        app.AssignColorsButton.Enable = "on";
                    else
                        app.CellCategoryDisplay.Enable = 'off';
                        app.UpButton.Enable = 'off';
                        app.DownButton.Enable = 'off';
                        app.CreateAnalysisPlots_Button.Enable = 'off';
                        app.AssignColorsButton.Enable = "off";
                    end
                    %---------------------------------------------------------------------------------
                    app.StopButton.Enable = 'off';
                    app.StartButton.Enable = 'on';
                             
                    AutocellComplete(app) % Alert user
                    
        end

        % Button pushed function: StopButton
        function StopButton_Callback(app, event)
            
                  app.StopButton.Enable = 'off';
                  app.StartButton.Enable = 'on';
        end

        % Value changed function: WideFieldChannel
        function WideFieldChannel_Callback(app, event)
            
                app.WFChannel = app.WideFieldChannel.Value;
            
                if isequal(app.NuclearChannel, app.WFChannel)
                    switch app.WFChannel
                        case 1
                            app.NuclearChannel = 2;
                            app.NuclearMarkerChannel.Value = 2;
                        case 2
                            app.NuclearChannel = 1;
                            app.NuclearMarkerChannel.Value = 1;
                        case 3
                            app.NuclearChannel = 2;
                            app.NuclearMarkerChannel.Value = 2;
                    end
                end            
              
        end

        % Value changed function: nMSDPts
        function nMSDPts_Callback(app, event)
            
                 val = round(str2double(app.nMSDPts.Text));
        
                 if ~isnan(val)
                    if val < app.MinTrackLength
                        app.nMSDPts.Text = num2str(val);
                        app.nMSDPtsForFit = val;
                    else
                        app.nMSDPts.Text   = num2str(app.MinTrackLength);
                        app.nMSDPtsForFit  = num2str(app.MinTrackLength);
                        WH = warndlg([{'Number of points used in MSD fit must be'};...
                                      {'less than Min Consecutive TrackPoints'}]);
                        try set(WH.Children(3,1).Children,'HorizontalAlignment','Center','Position',[124,31,0]); end  %#ok<TRYNC>
                    end
                 end            
                    
        end

        % Value changed function: nPts_MSDFit
        function nPts_MSDFitValueChanged(app, event)
           
            app.nPts_MSDFit.Value   = 1;
            app.AllPts_MSDFit.Value = 0;
            app.nMSDPts.Enable      = 'on';
            
        end

        % Value changed function: AllPts_MSDFit
        function AllPts_MSDFitValueChanged(app, event)
            
            app.nPts_MSDFit.Value   = 0;
            app.AllPts_MSDFit.Value = 1;
            app.nMSDPts.Enable      = 'off';   
            
        end

        % Value changed function: StTimePtInput
        function StTimePtInputValueChanged(app, event)
            
            value = app.StTimePtInput.Value;
            
        end

        % Value changed function: EndTimePtInput
        function EndTimePtInputValueChanged(app, event)
            
            value = app.EndTimePtInput.Value;
            
        end

        % Button pushed function: Nikon_CP_nd2_format_button
        function Nikon_CP_nd2_format_buttonButtonPushed(app, event)
            

                set(app.Nikon_CP_nd2_format_button,    'FontColor',[0.29,1.00,0.29],'BackgroundColor',[0.00,0.37,0.59],'FontWeight','bold');
                set(app.Nikon_nd2_format_button,       'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal');
                set(app.Metamorph_nd_format_button,    'FontColor',[0.50,0.50,0.50],'BackgroundColor',[0.94,0.94,0.94],'FontWeight','normal');
            
                app.uipanel2.Title = 'STEP 1: Sort nd2 files into sub-folders, then select the main folder';
                set(app.uipanel1,'Title','STEP 2: Cell Tracking')
              
                app.DataFormatDisplay.Text = 'Nikon format: phase-contrast imaging for Cellpose (.nd2)';
            
                app.SelectReadFolderButton.Text = {'Select';'Main';'Folder'};
                app.DataFormat = 'Nikon-CP';

                app.CellposePanel.Enable = "on";
                app.WideFieldChannel.Value = 1;
                app.NuclearMarkerChannel.Value = 2;
                app.NuclearMarkerChannel.Enable    = "off";
                app.PercentThresholdInput.Enable   = "off";
            
            
        end

        % Value changed function: PixelSizeInput
        function PixelSizeInputValueChanged(app, event)
            
            
                app.PixelSize = app.PixelSizeInput.Value;
            
                if ~isempty(app.PixelSizeInput.Value) && ~isempty(app.TimeStepInput.Value) %&& ~isempty(app.ReadDirMain)
                    app.StartButton.Enable = 'on';
                    app.MinTrackLengthInput.Value = num2str(round(app.MinTrackHourLength*60/app.TimeStep));
                    app.nMSDPts.Value = num2str( round(app.MinTrackHourLength*60/app.TimeStep) - 1 );
                end            
    
                if app.AutoAdjustGaussianSigma.Value
                    SGFS = SuggestedGaussianFilterSize(app);
                    app.GaussianFilterSizeInput.Value = SGFS;
                    app.GaussianSigma = app.GaussianFilterSizeInput.Value;
                end

                CS = EstimateCellSizeFromPixelSize(app,app.PixelSize);
                app.CellSizeForCellposeInput.Value = CS;


        end

        % Value changed function: TimeStepInput
        function TimeStepInputValueChanged(app, event)
            
                app.TimeStep = app.TimeStepInput.Value;
            
                if ~isempty(app.PixelSizeInput.Value) && ~isempty(app.TimeStepInput.Value)% && ~isempty(app.ReadDirMain)
                    app.StartButton.Enable = 'on';
                    app.MinTrackLengthInput.Value = num2str(round(app.MinTrackHourLength*60/app.TimeStep));
                    app.nMSDPts.Value = num2str( round( app.MinTrackHourLength*60/app.TimeStep )-1 );
                end


        end

        % Value changed function: ResampleImagesTo1024Check
        function ResampleImagesTo1024CheckValueChanged(app, event)
            
                        
            if app.ResampleImagesTo1024Check.Value
                app.CellSizeForCellposeInput.Value = 60;
            end
            

        end

        % Value changed function: RosePlotsCheckBox
        function RosePlotsCheckBoxValueChanged(app, event)
                        
            %value = app.RosePlotsCheckBox.Value;
            
        end

        % Button pushed function: AssignColorsButton
        function AssignColorsButtonPushed(app, event)
                        
 
            SubApp = RosePlotColorSelector(app.CellCategoryDisplay.Items',app.RosePlotColors,app);

            % uiwait(SubApp.RoseplotcolorassignmentUIFigure)
            % app.RosePlotColors = SubApp.ColorAssignments;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'AUTOCELL';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'STEP 2: Cell Tracking';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = {'Pixel Size'; '(microns)'};
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = {'Time Step'; '(minutes)'};
            app.StartButton = uibutton(app.uipanel1, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButton_Callback, true);
            app.StartButton.Text = 'START';
            app.StopButton = uibutton(app.uipanel1, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButton_Callback, true);
            app.StopButton.Text = 'STOP';
            app.uipanel8 = uipanel(app.uipanel1);
            app.CropTimePtsCheck = uicheckbox(app.uipanel8);
            app.CropTimePtsCheck.ValueChangedFcn = createCallbackFcn(app, @CropTimePtsCheck_Callback, true);
            app.CropTimePtsCheck.Text = 'Crop Timepoints';
            app.StTimePtInput = uieditfield(app.uipanel8, 'text');
            app.StTimePtInput.ValueChangedFcn = createCallbackFcn(app, @StTimePtInputValueChanged, true);
            app.StTimePtInput.Value = '100';
            app.EndTimePtInput = uieditfield(app.uipanel8, 'text');
            app.EndTimePtInput.ValueChangedFcn = createCallbackFcn(app, @EndTimePtInputValueChanged, true);
            app.EndTimePtInput.Value = '500';
            app.StText = uilabel(app.uipanel8);
            app.StText.Enable = 'off';
            app.StText.Text = 'start:';
            app.EndText = uilabel(app.uipanel8);
            app.EndText.Text = 'end:';
            app.PixelSizeInput = uieditfield(app.uipanel1, 'numeric');
            app.PixelSizeInput.Limits = [0 999];
            app.PixelSizeInput.ValueDisplayFormat = '%.3f';
            app.PixelSizeInput.AllowEmpty = 'on';
            app.PixelSizeInput.ValueChangedFcn = createCallbackFcn(app, @PixelSizeInputValueChanged, true);
            app.TimeStepInput = uieditfield(app.uipanel1, 'numeric');
            app.TimeStepInput.Limits = [0 999];
            app.TimeStepInput.ValueDisplayFormat = '%.2f';
            app.TimeStepInput.AllowEmpty = 'on';
            app.TimeStepInput.ValueChangedFcn = createCallbackFcn(app, @TimeStepInputValueChanged, true);
            app.CellposePanel = uipanel(app.uipanel1);
            app.ApproxcellsizepixelscellposeparamLabel = uilabel(app.CellposePanel);
            app.ApproxcellsizepixelscellposeparamLabel.Text = {'Approx cell size:'; '(default is 60 for pixel = 0.650 um,'; ' & 120 for pixel = 0.325 um)'};
            app.CellSizeForCellposeInput = uieditfield(app.CellposePanel, 'numeric');
            app.CellSizeForCellposeInput.Limits = [10 1000];
            app.CellSizeForCellposeInput.RoundFractionalValues = 'on';
            app.CellSizeForCellposeInput.ValueDisplayFormat = '%.0f';
            app.CellSizeForCellposeInput.Placeholder = '70';
            app.CellSizeForCellposeInput.Value = 60;
            app.CellposeparametersLabel = uilabel(app.CellposePanel);
            app.CellposeparametersLabel.Text = {'Cellpose '; 'parameters'};
            app.ModelLabel = uilabel(app.CellposePanel);
            app.ModelLabel.Text = 'Model:';
            app.CellposeModelPulldown = uidropdown(app.CellposePanel);
            app.CellposeModelPulldown.Items = {'cyto3'};
            app.CellposeModelPulldown.Value = 'cyto3';
            app.ResampleImagesTo1024Check = uicheckbox(app.uipanel1);
            app.ResampleImagesTo1024Check.ValueChangedFcn = createCallbackFcn(app, @ResampleImagesTo1024CheckValueChanged, true);
            app.ResampleImagesTo1024Check.Text = 'Resample images to 1024x1024 before processing (if not already).';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'STEP 1: Sort nd2 files into folders, then select top folder';
            app.SelectReadFolderButton = uibutton(app.uipanel2, 'push');
            app.SelectReadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReadFolderButton_Callback, true);
            app.SelectReadFolderButton.Text = {'Select '; 'Folder'};
            app.ReadDirectoryDisplay = uitextarea(app.uipanel2);
            app.ReadDirectoryDisplay.Editable = 'off';
            app.ReadDirectoryDisplay.Value = {'X:\Mendoza Lab\MATLAB\Custom Keith Code\Sample Autocell Data\ND2 files for Cellpose tracking - size 2024x2024'};
            app.ImageStatsDisplay = uilabel(app.uipanel2);
            app.ImageStatsDisplay.Text = 'Image size = 1024 x 1024 pixels';
            app.uipanel4 = uipanel(app.figure1);
            app.text15 = uilabel(app.uipanel4);
            app.text15.Text = 'Parameter Adjustment (optional)';
            app.uipanel6 = uipanel(app.uipanel4);
            app.uipanel6.Title = 'Image Processing and Tracking';
            app.text9 = uilabel(app.uipanel6);
            app.text9.Text = 'Min Consecutive TrackPoints:';
            app.MinTrackLengthInput = uieditfield(app.uipanel6, 'text');
            app.MinTrackLengthInput.InputType = 'digits';
            app.MinTrackLengthInput.ValueChangedFcn = createCallbackFcn(app, @MinTrackLengthInput_Callback, true);
            app.MinTrackLengthInput.Value = '24';
            app.text11 = uilabel(app.uipanel6);
            app.text11.Text = '% Treshold Above BackGround:';
            app.text12 = uilabel(app.uipanel6);
            app.text12.Text = '(default = 2.0%)';
            app.PercentThresholdInput = uieditfield(app.uipanel6, 'text');
            app.PercentThresholdInput.ValueChangedFcn = createCallbackFcn(app, @PercentThresholdInput_Callback, true);
            app.PercentThresholdInput.Value = '2';
            app.uipanel10 = uipanel(app.uipanel6);
            app.text7 = uilabel(app.uipanel10);
            app.text7.Text = 'Gaussian Filter Sigma Size:';
            app.text8 = uilabel(app.uipanel10);
            app.text8.Text = '(default for dp = 0.65 microns is 6.0)';
            app.AutoAdjustGaussianSigma = uicheckbox(app.uipanel10);
            app.AutoAdjustGaussianSigma.ValueChangedFcn = createCallbackFcn(app, @AutoAdjustGaussianSigma_Callback, true);
            app.AutoAdjustGaussianSigma.Text = 'Auto Adjust';
            app.AutoAdjustGaussianSigma.Value = true;
            app.GaussianFilterSizeInput = uieditfield(app.uipanel10, 'numeric');
            app.GaussianFilterSizeInput.Limits = [0.5 20];
            app.GaussianFilterSizeInput.ValueDisplayFormat = '%.1f';
            app.GaussianFilterSizeInput.Value = 20;
            app.hourminimumlengthLabel = uilabel(app.uipanel6);
            app.hourminimumlengthLabel.Text = '(3 hour minimum length)';
            app.uipanel7 = uipanel(app.uipanel4);
            app.uipanel7.Title = 'MSD Criteria';
            app.Text20 = uilabel(app.uipanel7);
            app.Text20.Text = {'Number of '; 'points used '; 'in MSD fit **'};
            app.nMSDPts = uieditfield(app.uipanel7, 'text');
            app.nMSDPts.ValueChangedFcn = createCallbackFcn(app, @nMSDPts_Callback, true);
            app.nMSDPts.Value = '23';
            app.AllPts_MSDFit = uicheckbox(app.uipanel7);
            app.AllPts_MSDFit.ValueChangedFcn = createCallbackFcn(app, @AllPts_MSDFitValueChanged, true);
            app.AllPts_MSDFit.Text = ' All';
            app.nPts_MSDFit = uicheckbox(app.uipanel7);
            app.nPts_MSDFit.ValueChangedFcn = createCallbackFcn(app, @nPts_MSDFitValueChanged, true);
            app.nPts_MSDFit.Text = {' '; ''};
            app.uipanel5 = uipanel(app.uipanel4);
            app.uipanel5.Title = 'Channel Assignment';
            app.NuclearMarkerChannel = uidropdown(app.uipanel5);
            app.NuclearMarkerChannel.Items = {'1,2,3,4'};
            app.NuclearMarkerChannel.ItemsData = [1 2];
            app.NuclearMarkerChannel.ValueChangedFcn = createCallbackFcn(app, @NuclearMarkerChannel_Callback, true);
            app.NuclearMarkerChannel.Value = 1;
            app.text14 = uilabel(app.uipanel5);
            app.text14.Text = 'Nuclear:';
            app.WideFieldChannel = uidropdown(app.uipanel5);
            app.WideFieldChannel.Items = {'1', '2', '3', '4'};
            app.WideFieldChannel.ItemsData = [1 2];
            app.WideFieldChannel.ValueChangedFcn = createCallbackFcn(app, @WideFieldChannel_Callback, true);
            app.WideFieldChannel.Value = 1;
            app.text17 = uilabel(app.uipanel5);
            app.text17.Text = 'Widefield:';
            app.text25 = uilabel(app.uipanel4);
            app.text25.Text = '**For post-autocell changes, use VIEWCELL to recalculate velocities.';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'STEP 3: Select plotting order and create analysis plots';
            app.CellCategoryDisplay = uilistbox(app.uipanel3);
            app.CellCategoryDisplay.Items = {};
            app.CellCategoryDisplay.Value = {};
            app.UpButton = uibutton(app.uipanel3, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButton_Callback, true);
            app.UpButton.Text = 'U';
            app.DownButton = uibutton(app.uipanel3, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButton_Callback, true);
            app.DownButton.Text = 'D';
            app.SelectAnaylsisFolderButton = uibutton(app.uipanel3, 'push');
            app.SelectAnaylsisFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAnaylsisFolderButton_Callback, true);
            app.SelectAnaylsisFolderButton.Text = 'Select ANALYSIS folder';
            app.Panel = uipanel(app.uipanel3);
            app.RosePlotsCheckBox = uicheckbox(app.Panel);
            app.RosePlotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RosePlotsCheckBoxValueChanged, true);
            app.RosePlotsCheckBox.Text = 'Rose plots';
            app.nTracksEditFieldLabel = uilabel(app.Panel);
            app.nTracksEditFieldLabel.Text = 'nTracks:';
            app.nTracksPerRosePlotInput = uieditfield(app.Panel, 'numeric');
            app.nTracksPerRosePlotInput.Limits = [1 100];
            app.nTracksPerRosePlotInput.RoundFractionalValues = 'on';
            app.nTracksPerRosePlotInput.ValueDisplayFormat = '%.0f';
            app.nTracksPerRosePlotInput.Value = 20;
            app.AssignColorsButton = uibutton(app.Panel, 'push');
            app.AssignColorsButton.ButtonPushedFcn = createCallbackFcn(app, @AssignColorsButtonPushed, true);
            app.AssignColorsButton.Text = 'Assign colors';
            app.CreateAnalysisPlots_Button = uibutton(app.uipanel3, 'push');
            app.CreateAnalysisPlots_Button.ButtonPushedFcn = createCallbackFcn(app, @CreateAnalysisPlots_Button_Callback, true);
            app.CreateAnalysisPlots_Button.Text = {'Create'; 'plots'};
            app.uipanel9 = uipanel(app.figure1);
            app.Nikon_nd2_format_button = uibutton(app.uipanel9, 'push');
            app.Nikon_nd2_format_button.ButtonPushedFcn = createCallbackFcn(app, @Nikon_nd2_format_button_Callback, true);
            app.Nikon_nd2_format_button.Text = 'Nikon: Fluor (.nd2)';
            app.Metamorph_nd_format_button = uibutton(app.uipanel9, 'push');
            app.Metamorph_nd_format_button.ButtonPushedFcn = createCallbackFcn(app, @Metamorph_nd_format_button_Callback, true);
            app.Metamorph_nd_format_button.Text = 'Metamorph (.nd)';
            app.DataFormatDisplay = uilabel(app.uipanel9);
            app.DataFormatDisplay.Text = 'Select Data Format:';
            app.Nikon_CP_nd2_format_button = uibutton(app.uipanel9, 'push');
            app.Nikon_CP_nd2_format_button.ButtonPushedFcn = createCallbackFcn(app, @Nikon_CP_nd2_format_buttonButtonPushed, true);
            app.Nikon_CP_nd2_format_button.Text = 'Nikon: Cellpose (.nd2)';
            app.FluorescentmarkednucleiLabel = uilabel(app.uipanel9);
            app.FluorescentmarkednucleiLabel.Text = 'Fluorescent marked nuclei';
            app.PhasecontrastwidefieldimageonlyLabel = uilabel(app.uipanel9);
            app.PhasecontrastwidefieldimageonlyLabel.Text = 'Phase contrast widefield image only';
            app.FluorescentmarkednucleiLabel_2 = uilabel(app.uipanel9);
            app.FluorescentmarkednucleiLabel_2.Text = 'Fluorescent marked nuclei';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autocell

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @autocell_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end